classdef enorm_dev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ENORMUIFigure                   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        CurrentdatasetMenu              matlab.ui.container.Menu
        enormMenu                       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        RegistrationTab                 matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ApplyTransformButton            matlab.ui.control.Button
        ReadFileButton                  matlab.ui.control.Button
        ApplyButton_ApplyTransf         matlab.ui.control.Button
        ReadImagesOnlyCheckBox          matlab.ui.control.CheckBox
        AutoFillButton_Transf           matlab.ui.control.Button
        SurfaceSpaceDropDown            matlab.ui.control.DropDown
        SurfaceSpaceDropDownLabel       matlab.ui.control.Label
        ElectrodeSpaceDropDown          matlab.ui.control.DropDown
        ElectrodeSpaceDropDownLabel     matlab.ui.control.Label
        SurfaceLabel                    matlab.ui.control.Label
        SurfaceTextArea                 matlab.ui.control.TextArea
        MNISurfaceEditField             matlab.ui.control.EditField
        MNISurfaceEditFieldLabel        matlab.ui.control.Label
        FSOrigEditField                 matlab.ui.control.EditField
        FSOrigEditFieldLabel            matlab.ui.control.Label
        FSRawOrigEditField              matlab.ui.control.EditField
        FSRawOrigLabel                  matlab.ui.control.Label
        CTMNIEditField                  matlab.ui.control.EditField
        CTMNIEditFieldLabel             matlab.ui.control.Label
        T1MNIEditField                  matlab.ui.control.EditField
        T1MNIEditFieldLabel             matlab.ui.control.Label
        CTT1EditField                   matlab.ui.control.EditField
        CTT1EditFieldLabel              matlab.ui.control.Label
        T1ImageEditField                matlab.ui.control.EditField
        T1ImageEditFieldLabel           matlab.ui.control.Label
        CTImageEditField                matlab.ui.control.EditField
        CTImageEditFieldLabel           matlab.ui.control.Label
        MNIImageEditField               matlab.ui.control.EditField
        MNIImageEditFieldLabel          matlab.ui.control.Label
        SelectButton_MNISurf            matlab.ui.control.Button
        SelectButton_Surface            matlab.ui.control.Button
        SelectButton_Orig               matlab.ui.control.Button
        SelectButton_Raw2Orig           matlab.ui.control.Button
        SelectButton_CT2MNI             matlab.ui.control.Button
        SelectButton_T12MNI             matlab.ui.control.Button
        SelectButton_CT2T1              matlab.ui.control.Button
        SelectButton_MNI                matlab.ui.control.Button
        SelectButton_T1                 matlab.ui.control.Button
        SelectButton_CT                 matlab.ui.control.Button
        ProjectionTab                   matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        ElectrodeProjectionPanel        matlab.ui.container.Panel
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        PorjMethodWangButton            matlab.ui.control.RadioButton
        PorjMethodLineFitButton         matlab.ui.control.RadioButton
        PorjMethodHemesButton           matlab.ui.control.RadioButton
        PorjMethodGroppeButton          matlab.ui.control.RadioButton
        PorjMethodDykstraButton         matlab.ui.control.RadioButton
        ClearProjButton                 matlab.ui.control.Button
        ApplyProjButton                 matlab.ui.control.Button
        PreviewProjButton               matlab.ui.control.Button
        GridSizeEditField               matlab.ui.control.EditField
        GridSizeLabel                   matlab.ui.control.Label
        EqualSpaceLineFit               matlab.ui.control.CheckBox
        AtlasofselectedchannelPanel     matlab.ui.container.Panel
        AtlasListbox                    matlab.ui.control.ListBox
        SelectedLabelListbox            matlab.ui.control.ListBox
        SelectedchannelsLabel           matlab.ui.control.Label
        ElecLabelGroupListbox           matlab.ui.control.ListBox
        ChanelgroupLabel                matlab.ui.control.Label
        ElecLabelGroupInfo              matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        ElecLabelListbox                matlab.ui.control.ListBox
        AtlasOptionsPanel               matlab.ui.container.Panel
        ShowAtlasButton                 matlab.ui.control.Button
        QueryButton                     matlab.ui.control.Button
        AtlasGroupListbox               matlab.ui.control.ListBox
        SurfaceOpacitySlider            matlab.ui.control.Slider
        SurfaceOpacitySliderLabel       matlab.ui.control.Label
        OpacityEdit                     matlab.ui.control.NumericEditField
        SurfaceOptionsPanel             matlab.ui.container.Panel
        OpacityLabel                    matlab.ui.control.Label
        FSsurfRhCheckBox                matlab.ui.control.CheckBox
        FSsurfLhCheckBox                matlab.ui.control.CheckBox
        FSsurfOpacitySlider             matlab.ui.control.Slider
        FSParcellationListBox           matlab.ui.control.ListBox
        FreesurferParcellationListBoxLabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        DisplayoptionsPanel             matlab.ui.container.Panel
        FigurebBkColorButton            matlab.ui.control.Button
        MNIbrainintensityPanel          matlab.ui.container.Panel
        DefaultStdClim                  matlab.ui.control.Button
        HighLabel_2                     matlab.ui.control.Label
        MNIIntensityHi                  matlab.ui.control.NumericEditField
        MNIintensityLo                  matlab.ui.control.NumericEditField
        LowLabel_2                      matlab.ui.control.Label
        T1brainintensityPanel           matlab.ui.container.Panel
        HighLabel                       matlab.ui.control.Label
        DefaultT1Clim                   matlab.ui.control.Button
        T1IntensityHi                   matlab.ui.control.NumericEditField
        T1IntensityLo                   matlab.ui.control.NumericEditField
        LowLabel                        matlab.ui.control.Label
        ProjectedelectrodesizeEditField  matlab.ui.control.NumericEditField
        ProjectedelectrodesizeEditFieldLabel  matlab.ui.control.Label
        SelectedelectrodesizeEditField  matlab.ui.control.NumericEditField
        SelectedelectrodesizeEditFieldLabel  matlab.ui.control.Label
        ElectrodessizeEditField         matlab.ui.control.NumericEditField
        ElectrodessizeEditFieldLabel    matlab.ui.control.Label
        ChangeprojectedelectrodescolorButton  matlab.ui.control.Button
        ChangeselectedelectrodescolorButton  matlab.ui.control.Button
        ChangeelectrodescolorButton     matlab.ui.control.Button
        ShowprojectedelectrodesCheckBox  matlab.ui.control.CheckBox
        ElectrodestoshowButtonGroup     matlab.ui.container.ButtonGroup
        ShowallwithouthighlightingselectedelectrodeButton  matlab.ui.control.RadioButton
        ShowselectedelectrodesonlyButton  matlab.ui.control.RadioButton
        ShowallelectrodesButton         matlab.ui.control.RadioButton
        StatusTextArea                  matlab.ui.control.Label
    end

    properties (Access = private)
        userdata % user data
        fighandle % handle to dependent figures
    end

    methods (Access = private)

        function Eviewer_InitializeUserdata(app)
            % whether data has been saved or not. 'yes' | 'no'
            app.userdata.data_saved = 'no';

            % whether electrode projection was performed and the
            % coordinates were updated
            app.userdata.elec_projected = 'no';

            % names of the figures
            app.userdata.surf_yoke_name = 'eviewer FS Surface';
            app.userdata.mni_surf_yoke_name = 'eviewer MNI Surface';
            app.userdata.misc.t13d_figname = 'eviewer T1';
            app.userdata.misc.std3d_figname = 'eviewer MNI brain';

            % figure background color
            app.userdata.bkcolor = 'k';

            % marker size for electrodes overlay on brain surfaces
            app.userdata.markersize_3d = 5;

            % marker size for brain slices view
            app.userdata.elec_markersize = 6;
            app.userdata.cur_elec_markersize = 6;

            % electrode color
            app.userdata.elec_color = 'b';
            app.userdata.cur_elec_color = 'r';
            app.userdata.proj_elec_markersize = 6;
            app.userdata.proj_elec_color = 'k';
            app.userdata.show_proj = 'yes';
            app.ShowprojectedelectrodesCheckBox.Value = 1;
            app.userdata.show_all_elec = 'yes';
            app.userdata.show_cur_elec = 'yes';

            app.ElectrodessizeEditField.Value = app.userdata.elec_markersize;
            app.SelectedelectrodesizeEditField.Value = app.userdata.cur_elec_markersize;
            app.ProjectedelectrodesizeEditField.Value = app.userdata.proj_elec_markersize;


            % data resulted from EPLACE
            % directory the electrode location file
            app.userdata.srcdir = '';
            app.userdata.filename = '';
            app.userdata.elec = [];

            % brain image that was used for electrode placement
            app.userdata.reg.srcvol = '';
            % 'unknown', 'ct', 't1', 'mni'
            app.userdata.reg.img_space = 'unknown';
            % 'unknown', 't1', 'fs', 'mni'
            app.userdata.reg.surf_space = 'unknown';

            % full-path-to CT or post-operative MRI
            app.userdata.reg.ctimg = '';
            % full path to Pre-operative MRI
            app.userdata.reg.t1img = '';
            % full path to MNI brain
            app.userdata.reg.stdimg = '';
            % *.pial from Freesurfer
            app.userdata.reg.surffile = '';
            % volume in Freesurfer space, orig.nii.gz
            app.userdata.reg.surfvol = '';
            % a data structure created using CreateSurf.m
            % or Freesurfer MNI152 *.pial
            app.userdata.reg.mnisurf = '';


            % Read T1, MNI, surface etc
            app.userdata.srcdata = [];
            app.userdata.srcdata.t1 = []; % read by MRIread
            app.userdata.srcdata.std = []; % read by MRIread
            app.userdata.srcdata.fssurf = [];
            app.userdata.srcdata.mnisurf = [];
            app.userdata.srcdata.t1_clim = [];
            app.userdata.default_t1_clim = [];
            app.userdata.srcdata.std_clim = [];
            app.userdata.default_std_clim = [];

            % Freesurfer's orig.nii.gz
            app.userdata.srcdata.surfmri = [];

            % MNI surface file
            app.userdata.srcdata.mnisurf = [];

            % individual freesurfer cortical
            app.userdata.srcdata.fssurf = [];

            app.userdata.srcdata.fsannot = [];

            % T1 brain surface created using CreateSurf
            app.userdata.srcdata.t1surf = [];

            % Freesurfer's cvs_avg35_inMNI152 annotation data
            app.userdata.srcdata.mniannot = [];
            app.userdata.srcdata.mniannot.parc = 'aparc.a2009s';
            app.userdata.srcdata.mniannot.lh = [];
            app.userdata.srcdata.mniannot.rh = [];

            % Freesurfer annotation data of individual T1
            app.userdata.srcdata.fsannot = [];
            app.userdata.srcdata.fsannot.parc = 'aparc.a2009s';
            app.userdata.srcdata.fsannot.lh = [];
            app.userdata.srcdata.fsannot.rh = [];
            app.userdata.srcdata.fsannot.filename.lh  = '';
            app.userdata.srcdata.fsannot.filename.rh = '';

            % transformation matrix resulted from FSL's flirt
            app.userdata.reg.ct2t1 = '';
            app.userdata.reg.ct2std = '';
            app.userdata.reg.t12std = '';

            % Freesurfer's raw.nii.gz -> orig.nii.gz transformation matrix
            % which was obtained using FSL's flirt
            app.userdata.reg.t12surf = '';

            % coordinates in T1 space
            app.userdata.reg.coord2t1 = [];
            % coordinates in MNI space
            app.userdata.reg.coord2std = [];
            % coordinates in cortical surface space
            app.userdata.reg.coord2surf = [];

            % atlas in individual space
            % the atlas has a volume file and a look up table
            app.userdata.atlas.t1.names = '';
            % full-path-to-atalas volumes
            app.userdata.atlas.t1.srcfile = '';
            % brain volumes read by MRIread
            app.userdata.atlas.t1.vols = '';
            % Nx2 [index, atlas name]look-up table for each atlas
            app.userdata.atlas.t1.lut = '';

            % MNI atlas
            app.userdata.atlas.std.names = '';
            app.userdata.atlas.std.srcfile = '';
            app.userdata.atlas.std.vols = '';
            app.userdata.atlas.std.lut = '';
            app.userdata.atlas.radius = 2;

            % atlas query results
            app.userdata.atlas.query = [];
            % atlas query result with Freesurfer segmentation
            app.userdata.atlas.query.fsaseg = {};
            app.userdata.atlas.query.fsl = {};

            % electrode projection
            app.userdata.proj = [];
            % projected coordinates in source space
            app.userdata.proj.coord = [];
            app.userdata.proj.coord2t1 = [];
            app.userdata.proj.coord2std = [];
            % projected coordinates in Freesurfer's surface space
            app.userdata.proj.coord2surf = [];
            % 'yes' | 'no', whether the electrode has been projected
            app.userdata.proj.status = [];
            % current grid size
            app.userdata.proj.gridsize = [];
            % projection history
            app.userdata.proj.hist.label = {};
            app.userdata.proj.hist.gridsize = [];
            % projection method
            app.userdata.proj.hist.method = {};
            app.userdata.proj.label = {};

            % figure app
            app.fighandle.fs_yoke = [];
            % handle to surface patch
            app.fighandle.fssurf.lh = [];
            app.fighandle.fssurf.rh = [];

            app.fighandle.fs_all_elec = [];
            app.fighandle.fs_cur_elec = [];
            app.fighandle.fs_proj_elc = [];

            app.fighandle.mnisurf_yoke = [];
            app.fighandle.mnisurf.lh = [];
            app.fighandle.mnisurf.rh = [];

            app.fighandle.mni_all_elec = [];
            app.fighandle.mni_cur_elec = [];
            app.fighandle.mni_proj_elec = [];


            app.fighandle.t13d_fig = [];
            app.fighandle.std3d_fig = [];
            app.fighandle.fs_proj_elec = [];
            app.fighandle.std_proj_elec = [];

            % individual surface handles
            app.fighandle.fsannot = [];
            app.fighandle.fsannot.lh = [];
            app.fighandle.fsannot.rh = [];

            % Freesurfer MNI surface handles
            app.fighandle.mniannot = [];
            app.fighandle.mniannot.lh = [];
            app.fighandle.mniannot.rh = [];

            % initilize listboxes
            objs = {app.ElecLabelListbox,...
                app.SelectedLabelListbox,...
                app.ElecLabelGroupListbox,...
                app.AtlasGroupListbox,...
                app.AtlasListbox};

            for k = 1 : length( objs)
                set( objs{ k}, 'Items', {});
            end

            app.StatusTextArea.Text = '';
            app.ElecLabelGroupInfo.Text = '';
        end % function Initialize User Data


        function ChangeVisibility( app, status)
            app.SaveMenu.Enable = status;
            app.CloseMenu.Enable = status;
            app.CurrentdatasetMenu.Enable = status;
            for k = 1 : length( app.ProjectionTab.Children)
                if isprop( app.ProjectionTab.Children( k), 'Enable')
                    app.ProjectionTab.Children( k).Enable = status;
                end
            end
        end


        function Eviewer_InitializeTransform(app)
            if ~isempty( app.userdata.elec)
                % electrode label list
                app.ElecLabelListbox.Items = app.userdata.elec.label;

                % group labels
                if exist( 'ECoG_GroupLabels', 'file')
                    lbl_grp = ECoG_GroupLabels( app.userdata.elec.label);

                    app.ElecLabelGroupListbox.Items = lbl_grp( :, 1);
                    app.ElecLabelGroupListbox.Value = lbl_grp{ 1, 1};
                    app.ElecLabelGroupInfo.Text = sprintf( '%s: %d labels', ...
                        lbl_grp{ 1, 1}, numel( lbl_grp{ 1, 2}));
                    app.userdata.label_grp = lbl_grp;
                end
            end

            if ~isempty( app.userdata.reg)
                % the reg must be in eviewer format
                switch app.userdata.reg.img_space
                    case 'ct'
                        app.ElectrodeSpaceDropDown.Value = 'CT';
                    case 't1'
                        app.ElectrodeSpaceDropDown.Value = 'T1';
                    case 'mni'
                        app.ElectrodeSpaceDropDown.Value = 'MNI';
                    otherwise
                        app.ElectrodeSpaceDropDown.Value = 'Unknown';
                end

                switch app.userdata.reg.surf_space
                    case 'fs'
                        app.SurfaceSpaceDropDown.Value = 'Freesurfer';
                    case 't1'
                        app.SurfaceSpaceDropDown.Value = 'T1';
                    case 'mni'
                        app.SurfaceSpaceDropDown.Value = 'MNI';
                    otherwise
                        app.SurfaceSpaceDropDown.Value = 'Unknown';
                end

                app.MNIImageEditField.Value = app.userdata.reg.stdimg;
                app.CTImageEditField.Value = app.userdata.reg.ctimg;
                app.T1ImageEditField.Value = app.userdata.reg.t1img;
                app.CTT1EditField.Value = app.userdata.reg.ct2t1;
                app.T1MNIEditField.Value = app.userdata.reg.t12std;
                app.CTMNIEditField.Value = app.userdata.reg.ct2std;
                app.SurfaceTextArea.Value = app.userdata.reg.surffile;
                app.FSOrigEditField.Value = app.userdata.reg.surfvol;
                app.FSRawOrigEditField.Value = app.userdata.reg.t12surf;
                app.MNISurfaceEditField.Value = app.userdata.reg.mnisurf;
            end % transformation setup
        end % function Eviewer_InitializeTransform


        function coord2surf = Eviewer_Coord2Surf( app, coord_t1)
            % coordinates in T1 space to Freesufer surface space
            coord_surfvol = Img2ImgCoords( app, coord_t1,...
                app.userdata.reg.t12surf,...
                app.userdata.reg.t1img,...
                app.userdata.reg.surfvol);

            if isempty( coord_surfvol)
                coord2surf = [];
                return;
            end

            % t1 space -> freesurfer volume space
            surfvol = MRIread( app.userdata.reg.surfvol);
            N = size( coord_surfvol, 1);
            orig_ras = surfvol.vox2ras \ [coord_surfvol, ones( N, 1)]';
            % Freesurfer_Space (MM) -> Freesurface_Surface_Space
            % CRS (Voxel) (orig.mgz) -> surface transformation matrix
            Torig = [-1 0 0 128;
                0 0 1 -128;
                0 -1 0 128;
                0 0 0 1];

            tkrRAS = Torig * orig_ras;
            coord2surf = tkrRAS( 1:3, :)';
        end % function Eviewer_Coord2Surf


        function InitializeYoke(app)
            % handles to individual surface and slice plots
            f = findobj( 'name', app.userdata.surf_yoke_name);
            for k = 1 : length( f)
                delete( f(k));
            end

            f = figure( 'units', 'normalized',...
                'position', [0.01 0.6 0.15 0.25],...
                'name', app.userdata.surf_yoke_name,...
                'numbertitle', 'off',...
                'Color', app.userdata.bkcolor);

            app.fighandle.fs_yoke = axes( f);
            axis( app.fighandle.fs_yoke, 'off');
            axis( app.fighandle.fs_yoke, 'equal');
            axis( app.fighandle.fs_yoke, 'vis3d');

            % axial, sagittal, and cronal slices
            tmp_f = findobj( 'name', app.userdata.misc.t13d_figname);
            for k = 1 : length( tmp_f)
                delete( tmp_f(k));
            end

            app.fighandle.t13d_fig = figure( 'units', 'normalized',...
                'position', [0.2 0.6 0.15 0.25],...
                'name', app.userdata.misc.t13d_figname,...
                'numbertitle', 'off',...
                'color', app.userdata.bkcolor);
        end % function InitializeYoke


        function InitializeMNISurfYoke( app)
            % 3d brain surface figure
            f = findobj( 'name', app.userdata.mni_surf_yoke_name);
            for k = 1 : length( f)
                delete( f(k));
            end

            f = figure( 'units', 'normalized',...
                'numbertitle', 'off',...
                'position', [0.01 0.1 0.15 0.25],...
                'name', app.userdata.mni_surf_yoke_name,...                
                'Color', app.userdata.bkcolor);

            app.fighandle.mnisurf_yoke = axes( f);
            axis( app.fighandle.mnisurf_yoke, 'off');
            axis( app.fighandle.mnisurf_yoke, 'equal');
            axis( app.fighandle.mnisurf_yoke, 'vis3d');

            % axial, sagittal, and cronal slices
            tmp_f = findobj( 'name', app.userdata.misc.std3d_figname);
            for k = 1 : length( tmp_f)
                delete( tmp_f(k));
            end

            app.fighandle.std3d_fig = figure( 'units', 'normalized',...
                'position', [0.2 0.1 0.15 0.25],...
                'name', app.userdata.misc.std3d_figname,...
                'numbertitle', 'off', ...
                'color', app.userdata.bkcolor);
        end % function InitializeMNISurfYoke


        function Clear3dElecPlot( ~, h)
            if ~isempty( h) && isvalid( h)
                set( h, 'XData', [],...
                    'YData', [],...
                    'Zdata', []);
            end
        end


        function Update3dElec( app, elec_nam, fig_nam, c, ck, mark_sz)
            % elec_nam, name the handle to 3d points  app.fighandle.( elec_nam)
            % fig_nam, name of the handle to figure  app.fighandle.( fig_nam)
            % coord, Nx3 coordinates
            if isempty( app.fighandle.( elec_nam)) || ~isvalid( app.fighandle.( elec_nam))
                app.fighandle.( elec_nam) = line( app.fighandle.( fig_nam),...
                    c( :, 1), c( :, 2), c( :, 3), ...
                    'linestyle', 'none', 'color', ck,...
                    'marker', 'o', 'markersize', mark_sz, 'markerfacecolor', ck);

            else
                set( app.fighandle.( elec_nam),...
                    'XData', c( :, 1), 'YData', c( :, 2), 'ZData', c( :, 3),...
                    'Color', ck, 'MarkerSize', mark_sz, 'MarkerFaceColor', ck);
            end

            % % add wire label
            %  Update3dElec( app, 'fs_all_elec', 'fs_yoke',...
            %             app.userdata.reg.coord2surf,...
            %             app.userdata.elec_color,...
            %             app.userdata.elec_markersize);
            if ~strcmpi( elec_nam, 'fs_all_elec') || ~isfield( app.userdata, 'label_grp')
                return;
            end

            % app.userdata.label_grp
            txt_h = findall( app.fighandle.( fig_nam), 'type', 'text');
            delete( txt_h);
            nb_grp = size( app.userdata.label_grp);
            for k = 1 : nb_grp
                loc = app.userdata.label_grp{ k, 2};
                prefix = app.userdata.label_grp{ k, 1};
                c = app.userdata.reg.coord2surf( loc, :);
                [~, idx] = max( abs( c( :, 1)));
                xrng = abs( max( c( :, 1)) - min( c(:, 1)));
                c = c( idx, :);
                c(1) = sign( c(1))*0.1*xrng + c(1);
                text( app.fighandle.( fig_nam), c(1), c(2), c(3), ...
                    prefix, 'fontsize', 16, 'fontname', 'Arial', ...
                    'fontweight', 'bold', 'color', rand( 1, 3));
            end            
        end


        function UpdateYoke( app)
            % currently selected labels
            val = app.ElecLabelListbox.Value;
            if isempty( app.userdata.elec) || isempty( app.userdata.elec.label)
                return;
            end

            cur_elec_loc = find( ismember( app.userdata.elec.label, val));
            if ~isempty( app.fighandle.fs_yoke) ...
                    && isvalid( app.fighandle.fs_yoke)...
                    && ~isempty( app.userdata.reg.coord2surf)

                hold( app.fighandle.fs_yoke, 'on');

                if ~strcmpi( app.userdata.show_all_elec, 'yes')
                    Clear3dElecPlot( app, app.fighandle.fs_all_elec);
                else
                    Update3dElec( app, 'fs_all_elec', 'fs_yoke',...
                        app.userdata.reg.coord2surf,...
                        app.userdata.elec_color,...
                        app.userdata.elec_markersize);
                end

                if ~strcmpi( app.userdata.show_cur_elec, 'yes')
                    Clear3dElecPlot( app, app.fighandle.fs_cur_elec);
                else
                    Update3dElec( app, 'fs_cur_elec', 'fs_yoke',...
                        app.userdata.reg.coord2surf( cur_elec_loc, :),...
                        app.userdata.cur_elec_color,...
                        app.userdata.cur_elec_markersize);
                end

                % projected electrodes
                if ~strcmpi( app.userdata.show_proj, 'yes')
                    Clear3dElecPlot( app, app.fighandle.fs_proj_elec);
                else
                    loc = find( ismember( app.userdata.proj.label, val));
                    coord2plot = [];
                    for k = 1 : length( loc)
                        if app.userdata.proj.status( loc(k)) == 0
                            continue;
                        end

                        if size( app.userdata.proj.coord2surf, 1) >= loc(k)
                            coord2plot = cat( 1, coord2plot, ...
                                app.userdata.proj.coord2surf( loc(k), :));
                        end
                    end

                    if ~isempty( app.fighandle.fs_proj_elec)
                        delete( app.fighandle.fs_proj_elec);
                        app.fighandle.fs_proj_elec = [];
                    end

                    if ~isempty( coord2plot)
                        Update3dElec( app, 'fs_proj_elec', 'fs_yoke',...
                            coord2plot,...
                            app.userdata.proj_elec_color,...
                            app.userdata.proj_elec_markersize);
                    end
                end % plot projected electrodes

                hold( app.fighandle.fs_yoke, 'off');
            end % surface plot in individual space

            if ~isempty( app.fighandle.mnisurf_yoke) ...
                    && isvalid( app.fighandle.mnisurf_yoke) ...
                    && ~isempty( app.userdata.reg.coord2std)

                hold( app.fighandle.mnisurf_yoke, 'on');

                if ~strcmpi( app.userdata.show_all_elec, 'yes')
                    Clear3dElecPlot( app, app.fighandle.mni_all_elec);
                else
                    Update3dElec( app, 'mni_all_elec', 'mnisurf_yoke',...
                        app.userdata.reg.coord2std,...
                        app.userdata.elec_color,...
                        app.userdata.elec_markersize);
                end

                if ~strcmpi( app.userdata.show_cur_elec, 'yes')
                    Clear3dElecPlot( app, app.fighandle.mni_cur_elec);
                else
                    Update3dElec( app, 'mni_cur_elec', 'mnisurf_yoke',...
                        app.userdata.reg.coord2std( cur_elec_loc, :),...
                        app.userdata.cur_elec_color,...
                        app.userdata.cur_elec_markersize);
                end

                % plot projected electrodes
                if ~strcmpi( app.userdata.show_proj, 'yes')
                    Clear3dElecPlot( app, app.fighandle.std_proj_elec);
                else
                    if ~isempty( app.fighandle.std_proj_elec)
                        delete( app.fighandle.std_proj_elec);
                    end

                    loc = find( ismember( app.userdata.proj.label, val));
                    coord2plot = [];
                    for k = 1 : length( loc)
                        if app.userdata.proj.status( loc(k)) == 0
                            continue;
                        end
                        if size( app.userdata.proj.coord2std, 1) >= loc(k)
                            coord2plot = cat( 1, coord2plot, ...
                                app.userdata.proj.coord2std( loc(k), :));
                        end
                    end

                    if ~isempty( coord2plot)
                        app.fighandle.std_proj_elec = line( app.fighandle.mnisurf_yoke,...
                            coord2plot( :, 1),...
                            coord2plot( :, 2),...
                            coord2plot( :, 3),...
                            'marker', 'o',...
                            'color', app.userdata.proj_elec_color,...
                            'markerfacecolor', app.userdata.proj_elec_color,...
                            'markersize', app.userdata.proj_elec_markersize);
                    end
                end % projected electroded in MNI space

                hold( app.fighandle.mnisurf_yoke, 'off');
            end % MNI surface plot

            set( app.StatusTextArea, 'Text', 'Ready.', 'FontColor', 'b');
        end % function UpdateYoke


        function ChangeSurfOpacity(app)
            % brain surface transparency
            val = app.SurfaceOpacitySlider.Value/100;
            if ~isempty( app.fighandle.fs_yoke)...
                    && isvalid( app.fighandle.fs_yoke) ...
                    && ~isempty( app.fighandle.fssurf.lh)

                alpha( app.fighandle.fssurf.lh, val);
                alpha( app.fighandle.fssurf.rh, val);
            end

            if ~isempty( app.fighandle.mnisurf_yoke)...
                    && isvalid( app.fighandle.mnisurf_yoke)...
                    && ~isempty( app.fighandle.mnisurf.lh)

                alpha( app.fighandle.mnisurf.lh, val);
                alpha( app.fighandle.mnisurf.rh, val);
            end
        end


        function Eviewer_AtlasQuery( app)
            % look for Freesufer segmentation
            is_fs_performed = 'yes';
            app.userdata.atlas.query.fsaseg = Eviewer_FS_Atlas( app);
            if isempty( app.userdata.atlas.query.fsaseg)
                is_fs_performed = 'no';
            end

            % Harvard-Oxford atlas
            is_mni_performed = 'yes';
            if ~isempty( app.userdata.reg.coord2std)
                app.StatusTextArea.Text = 'Atlas query using FSL ...';
                app.StatusTextArea.FontColor = 'b';
                drawnow;

                app.userdata.atlas.query.fsl = {};
                coord = app.userdata.reg.coord2std;
                loc = find( app.userdata.proj.status == 1);
                coord( loc, :) = app.userdata.proj.coord2std( loc, :);

                coord_label = AtlasQuery_HarvardOxford( coord);
                app.StatusTextArea.Text = 'Atlas query using FSL     Done.';
                drawnow;

                app.userdata.atlas.query.fsl( :, 2) = repmat( {'no'}, size( coord, 1), 1);

                if isempty( coord_label)
                    is_mni_performed = 'no';
                else
                    app.userdata.atlas.query.fsl( :, 1) = coord_label;
                    app.userdata.atlas.query.fsl( loc, 2) = repmat( {'yes'}, length( loc), 1);
                end
            end

            txt = '';
            if strcmpi( is_fs_performed, 'no')
                txt = 'Freesurfer atlas query failed.';
            end

            if strcmpi( is_mni_performed, 'no')
                if ~isempty( txt)
                    txt = 'Both Freesurfer and FSL atlas query failed.';
                else
                    txt = 'FSL atlas query failed.';
                end
            end

            if isempty( txt)
                txt = 'Atlas query succeeded.';
            end

            app.StatusTextArea.Text = txt;
            app.StatusTextArea.FontColor = 'r';
        end % function Eviewer_AtlasQuery


        function fs_atlas_result = Eviewer_FS_Atlas( app)
            % atlas label in Freesurfer aseg file, which has been
            % transformed into original T1 space
            fs_atlas_result = [];
            app.StatusTextArea.FontColor = 'r';
            if isempty( app.userdata.reg.surfvol)
                app.StatusTextArea.Text = 'Surface volume not found.';                
                return;
            end

            subj_mridir = fileparts( app.userdata.reg.surfvol);
            file = fullfile( subj_mridir, 'aparc+aseg-in-rawavg.nii.gz');
            tmp = fileparts( mfilename( 'fullpath'));
            lut_file = fullfile( tmp, 'Freesurfer_LUT.txt');

            if ~isfile( file)
                app.StatusTextArea.Text = 'aparc+aseg-in-rawavg.nii.gz was not found.';
                return;
            end

            if ~isfile( lut_file)
                app.StatusTextArea.Text = 'Freesurfer_LUT.txt was not found in script folder.';
                return;
            end

            if isempty( app.userdata.srcdata.t1)
                app.StatusTextArea.Text = 'No T1 image was set.';
                return;
            end

            app.userdata.atlas.fs_aseg.mri = MRIread( file);
            app.userdata.atlas.fs_aseg.mri.vol = permute( app.userdata.atlas.fs_aseg.mri.vol, [2, 1, 3]);

            % freesurfer lookup table
            app.userdata.atlas.fs_aseg.lut = '';
            t1_sz = size( app.userdata.srcdata.t1.vol);
            fs_aseg_sz = size( app.userdata.atlas.fs_aseg.mri.vol);
            if ~isequal( t1_sz, fs_aseg_sz)
                app.StatusTextArea.Text = 'T1 and Freesurfer atlas sizes are not matched.';
                drawnow;
                return;
            end
            
            lut = importdata( lut_file);
            lut_num = cellfun( @(x) num2str( x), lut.textdata(:, 1), 'UniformOutput', false);

            nb_labels = length( app.userdata.elec.label);
            fs_atlas_result = cell( nb_labels, 2);
            app.StatusTextArea.FontColor = 'b';
            for elec_idx = 1 : nb_labels
                app.StatusTextArea.Text = sprintf( ...
                    'Freesurfer atlas query %.0f%%', 100 * elec_idx/nb_labels);                
                drawnow;

                if app.userdata.proj.status( elec_idx) == 1
                    % projected electrodes
                    stdcoord = app.userdata.proj.coord2t1( elec_idx, :)';
                    proj_used = 'yes';

                else
                    stdcoord = app.userdata.reg.coord2t1( elec_idx, :)';
                    proj_used = 'no';
                end

                img_c = round( app.userdata.srcdata.t1.vox2ras1 \ [stdcoord; 1]);
                img_x = img_c( 1);
                img_y = img_c( 2);
                img_z = img_c( 3);

                img_dims = size( app.userdata.srcdata.t1.vol);
                [xx, yy, zz] = ndgrid( 1:img_dims(1), 1:img_dims(2), 1:img_dims(3));

                resol = app.userdata.srcdata.t1.volres( [2 1 3]);
                res_x = resol( 1);
                res_y = resol( 2);
                res_z = resol( 3);
                ss = sqrt( (res_x*(xx - img_x)).^2 ...
                    + (res_y*(yy - img_y)).^2 ...
                    + (res_z*(zz - img_z)).^2) <= app.userdata.atlas.radius + 2*eps;     

                lut_val = app.userdata.atlas.fs_aseg.mri.vol( ss == 1);
                [lut_val, ~, lut_val_ind] = unique( lut_val(:));
                lut_val = cat( 1, app.userdata.atlas.fs_aseg.mri.vol( img_x, img_y, img_z), lut_val);

                label_lookup = cell( length( lut_val), 1);
                for k = 1 : length( lut_val)
                    loc = find( cell2mat( cellfun( @(x) str2num(x) == lut_val( k), lut_num, 'un', 0)));

                    prefix = '';
                    if k > 1
                        N = sum( lut_val_ind == (k-1)) / length( lut_val_ind);
                        N = num2str( round( N * 10000)/100);
                        prefix = [N, '%: '];
                    end

                    if ~isempty( loc)
                        label_lookup{ k, 1} = strcat( prefix, lut.textdata{ loc, 2});
                    else
                        label_lookup{ k, 1} = strcat( prefix, 'Not found');
                    end
                end

                label_lookup{ k + 1, 1} = sprintf( 'Radius: %g mm; %d voxels',...
                    app.userdata.atlas.radius, length( lut_val_ind));

                fs_atlas_result( elec_idx, :) = {label_lookup, proj_used};
            end % all electrodes
            
            app.StatusTextArea.Text = 'Freesurfer atlas query completed successfully.';
            drawnow;
        end % freesurfer atlas query


        function status = Eviewer_ElecProj( app, event)
            % FREESURFER subject directory
            % .pial-outer-smoothed
            status = 1;
            if isempty( app.userdata.elec)
                %errordlg( 'No electrodes were selected for correction.\n', '');
                set( app.StatusTextArea, ...
                    'Text', 'No electrodes were selected for correction.', 'FontColor', 'r');
                drawnow;
                return;
            end

            % Selected labels for projection
            labels = app.ElecLabelListbox.Value;
            loc = find( ismember( app.userdata.elec.label, labels));

            % Grid size
            grid_size = str2num( app.GridSizeEditField.Value);
            if (length( grid_size) == 2 || length( grid_size) == 1)...
                    && all( abs(grid_size - round( grid_size)) < 2*eps) ...
                    && abs( prod( grid_size) - length( loc)) < eps ...
                    && all( grid_size > 0)

                app.userdata.proj.gridsize = grid_size;

            else
                % errordlg( 'Grid size must be the same to the number of selected electrodes.', '');
                txt = 'Grid size must be the same to the number of selected electrodes.';
                set( app.StatusTextArea, 'Text', txt, 'FontColor', 'r');
                drawnow;
                return;
            end

            status = 0;

            % projected coordinates in different spaces
            proj_fs_surf = [];
            proj_ct_mm = [];
            proj_t1_mm = [];
            % proj_std_mm = [];
            %h = waitbar( 0, 'Please wait ...');

            % It's typical that the registration between CT and T1 is
            % performed using linear normalization method. Applying the
            % transformation matrix to the coordinates seems to be fast.
            % On the other hand, the registeration between T1 and MNI could
            % be done using non-linear transformation. Applying the
            % transformation matrix to the coordinates could be slow. This
            % should be done after all projections has been done.
            proj_method = '';
            if app.PorjMethodWangButton.Value == 1
                % Project to brain surface uing Wang et al.
                proj_method = 'Wang';
                if ~isempty( app.userdata.reg.surffile)...
                        && ~isempty( app.userdata.reg.surfvol) ...
                        && ~isempty( app.userdata.srcdata.surfmri)

                    txt = 'Running electrode projection ... please wait...';
                    set( app.StatusTextArea, 'Text', txt, 'FontColor', 'b');
                    drawnow;

                    subj_dir = fileparts( app.userdata.reg.surffile{1});
                    subj_dir = fileparts( subj_dir);

                    % smoothed surface is needed for projection
                    sm_lh_pial = fullfile( subj_dir, 'surf', 'lh.pial-outer-smoothed');
                    sm_rh_pial = fullfile( subj_dir, 'surf', 'rh.pial-outer-smoothed');
                    if ~exist( sm_lh_pial, 'file') || ~exist( sm_rh_pial, 'file')
                        set( app.StatusTextArea, ...
                            'Text', '*.pial-outer-smoothed files were not found.', 'r');
                        return;
                    end

                    % projection method
                    coord2proj = app.userdata.reg.coord2surf( loc, :);
                    if any( coord2proj(:, 1) < 0)
                        hemi = 'lh';
                    else
                        hemi = 'rh';
                    end

                    proj_elec = ntools_elec_projection( [], [],...
                        grid_size(1), grid_size(2),...
                        hemi, subj_dir, [ones( length( loc), 1), coord2proj]);

                    proj_fs_surf = proj_elec{ 5};

                    Torig = [-1, 0, 0, 128;
                        0, 0, 1, -128;
                        0, -1, 0, 128;
                        0, 0, 0, 1];

                    tmp = [proj_fs_surf, ones( size( proj_fs_surf, 1), 1)];
                    proj_coord_FS_MM = app.userdata.srcdata.surfmri.vox2ras * (Torig \ tmp');

                    if ~isfield( app.userdata.reg, 'surf2t1') || isempty( app.userdata.reg.surf2t1)
                        app.userdata.reg.surf2t1 = Eviewer_InverseXfm( app, app.userdata.reg.t12surf);
                    end

                    fs_rawavg = fullfile( subj_dir, 'mri', 'rawavg.nii.gz');
                    if ~exist( fs_rawavg, 'file')
                        set( app.StatusTextArea, 'Text',...
                            sprintf( 'rawavg.nii.gz was not found in %s.', fileparts( fs_rawavg)), 'r');
                    end

                    proj_t1_mm = Img2ImgCoords( app, proj_coord_FS_MM( 1:3, :)',...
                        app.userdata.reg.surf2t1, app.userdata.reg.surfvol, fs_rawavg);

                    if ~isfield( app.userdata.reg, 't12ct')
                        app.userdata.reg.t12ct = Eviewer_InverseXfm( app, app.userdata.reg.ct2t1);
                    end

                    set( app.StatusTextArea, 'Text', 'Register projected coordinates to T1 ...');
                    drawnow;
                    proj_ct_mm = Img2ImgCoords( app, proj_t1_mm,...
                        app.userdata.reg.t12ct,...
                        app.userdata.reg.t1img,...
                        app.userdata.reg.ctimg);

                    % set( app.StatusTextArea, 'Text', 'Register projected coordinates to MNI ...');
                    % drawnow;
                    % proj_std_mm = Img2ImgCoords( app, proj_t1_mm,...
                    %     app.userdata.reg.t12std,...
                    %     app.userdata.reg.t1img,...
                    %     app.userdata.reg.stdimg);

                else
                    txt = ['Error in running electrode projection ... ' ...
                        'check command window for details.'];
                    set( app.StatusTextArea, 'Text', txt, 'FontColor', 'r');
                    drawnow;
                end

            elseif app.PorjMethodLineFitButton.Value == 1
                proj_method = 'Line Fit';
                coord2proj = app.userdata.elec.elecpos.coord( loc, :);
                proj_ct_mm = Eviewer_FitLine3d( app, coord2proj);

                nb_elecs = size( proj_ct_mm, 1);
                if app.EqualSpaceLineFit.Value == 1 && nb_elecs > 2                    
                    orig_dist = sqrt( sum( abs( diff( proj_ct_mm, [], 1) ).^2, 2 ));

                    % equal spacing of the contacts
                    xlo = proj_ct_mm( 1, 1);
                    xhi = proj_ct_mm( end, 1);
                    ylo = proj_ct_mm( 1, 2);
                    yhi = proj_ct_mm( end, 2);
                    zlo = proj_ct_mm( 1, 3);
                    zhi = proj_ct_mm( end, 3);
                    proj_ct_mm = [linspace( xlo, xhi, nb_elecs)',...
                        linspace( ylo, yhi, nb_elecs)',...
                        linspace( zlo, zhi, nb_elecs)'];

                    new_dist = mean( sqrt( sum( abs( diff( proj_ct_mm, [], 1) ).^2, 2 )));

                    txt = sprintf( ['Electrode projection done. ' ...
                        'Origial distance: %.1f-%.1f mm. ' ...
                        'New distance: %.1f-%.1f mm\n'],...
                        min( orig_dist), max( orig_dist), min( new_dist), max( new_dist));
                else
                    txt = 'No equal distance was enforced.';
                end

                set( app.StatusTextArea, 'Text', 'Register projected coordinates to T1 ...');
                drawnow;
                % Assuming electrode placement was done in CT space
                % app.userdata.reg.img_space
                switch lower( app.userdata.reg.img_space)
                    case 'ct'
                        proj_t1_mm = Img2ImgCoords( app, proj_ct_mm,...
                            app.userdata.reg.ct2t1,...
                            app.userdata.reg.ctimg,...
                            app.userdata.reg.t1img);

                        % set( app.StatusTextArea, 'Text', 'Register projected coordinates to MNI ...');
                        % drawnow;
                        % proj_std_mm = Img2ImgCoords( app, proj_ct_mm,...
                        %     app.userdata.reg.ct2std,...
                        %     app.userdata.reg.ctimg,...
                        %     app.userdata.reg.stdimg);

                    case 't1'
                        proj_t1_mm = proj_ct_mm;
                        % set( app.StatusTextArea, 'Text', 'Register projected coordinates to MNI ...');
                        % drawnow;
                        % proj_std_mm = Img2ImgCoords( app, proj_t1_mm,...
                        %     app.userdata.reg.t12std,...
                        %     app.userdata.reg.t1img,...
                        %     app.userdata.reg.stdimg);

                    case 'std'
                        proj_std_mm = proj_ct_mm;
                        proj_t1_mm = Img2ImgCoords( app, proj_std_mm,...
                            app.userdata.reg.std2t1,...
                            app.userdata.reg.stdimg,...
                            app.userdata.reg.t1img);
                end

                proj_fs_surf = Eviewer_Coord2Surf( app, proj_t1_mm);
                set( app.StatusTextArea, 'Text', txt, 'FontColor', 'b');
                drawnow;
                fprintf( txt);

            else
                % To implement: other correction methods
            end


            % % Normalize CT/T1 coordinates to MNI space
            % If it's nonlinear transformation, it will much faster to do
            % this after all projects are done.
            proj_std_mm = [];


            if event.Source == app.ApplyProjButton
                % save porjection results
                txt = 'Applying electrode projection ...';
                set( app.StatusTextArea, 'Text', txt, 'FontColor', 'b');
                drawnow;

                if ~isempty( proj_ct_mm) && ~isempty( proj_t1_mm)
                    app.userdata.elec_projected = 'yes';

                    if isempty( app.userdata.proj.status)
                        app.userdata.proj.status = zeros( length( app.userdata.elec.label), 1);
                    end

                    % 1 - projected, 0 - original coordinates
                    app.userdata.proj.status( loc) = ones( length( loc), 1);
                    app.userdata.proj.status = app.userdata.proj.status(:);
                    app.userdata.proj.label = app.userdata.elec.label;
                    % tracking projection history
                    if ~isfield( app.userdata.proj.hist, 'method')
                        % bug
                        app.userdata.proj.hist.method = {};
                    end

                    app.userdata.proj.hist.method{ end+1} = proj_method;
                    app.userdata.proj.hist.label{ end+1, 1} = labels;
                    app.userdata.proj.hist.gridsize{ end+1} = grid_size;

                    % projected coordinates in CT space
                    app.userdata.proj.coord( loc, 1:3) = proj_ct_mm;
                    app.userdata.proj.coord2t1( loc, 1:3) = proj_t1_mm;

                    if ~isempty( proj_std_mm)
                        app.userdata.proj.coord2std( loc, 1:3) = proj_std_mm;
                    end

                    txt = 'Electrodes were projected successfully ...';
                    set( app.StatusTextArea, 'Text', txt, 'FontColor', 'b');
                    drawnow;

                else
                    txt = 'Transformatin project to';
                    if isempty( proj_ct_mm)
                        txt = sprintf( '%s %s', txt, 'CT');
                    end

                    if isempty( proj_t1_mm)
                        txt = sprintf( '%s %s', txt, 'T1');
                    end

                    if isempty( proj_std_mm)
                        txt = sprintf( '%s %s', txt, 'MNI');
                    end

                    txt = sprintf( '%s failed.', txt);
                    set( app.StatusTextArea, 'Text', txt, 'FontColor', 'b');
                    drawnow;
                end
            end % save project results

            % Plot projected electrodes on surface
            if isempty( proj_fs_surf)
                return;
            end

            app.userdata.proj.coord2surf( loc, 1:3) = proj_fs_surf;
            if ~isempty( app.fighandle.fs_yoke) && isvalid( app.fighandle.fs_yoke)
                hold( app.fighandle.fs_yoke, 'on');
                app.fighandle.fs_proj_elec  = line( app.fighandle.fs_yoke,...
                    proj_fs_surf( :, 1),...
                    proj_fs_surf( :, 2),...
                    proj_fs_surf( :, 3),...
                    'marker', 'o', 'linestyle', 'none', ...
                    'color', app.userdata.proj_elec_color,...
                    'MarkerFaceColor', app.userdata.proj_elec_color,...
                    'markersize', app.userdata.proj_elec_markersize);
                hold( app.fighandle.fs_yoke, 'off');
            end
        end % shift correction


        function fitted_data = Eviewer_FitLine3d( ~, data, varargin)
            % fitting a line to 3-d data using pca
            %
            % See https://www.mathworks.com/help/stats/examples/
            % fitting-an-orthogonal-regression-using-principal-components-analysis.html
            %
            % Input
            %   data, M x N matrix, rows - observations, columns - variables.
            %   varagin{1}, group vector, fit line for each group separately.
            %

            % if ~ismatrix( data)
            %     error( 'data must be a 2d matrix.');
            % end
            sz = size( data);
            g = ones( sz(1), 1);
            if ~isempty( varargin)
                g = varargin{1};
                if ~isvector( g) || length( g) ~= sz(1)
                    error( 'Invalid group index.');
                end

                g = g(:);
            end

            grp = unique( g);
            nb_grps = length( grp);
            fitted_data = data;
            pca_result.coeff = [];
            pca_result.score = [];
            pca_result.roots = [];
            pca_result.avg = [];
            pca_result = repmat( pca_result, [nb_grps, 1]);
            for gidx = 1 : nb_grps
                grp_loc = g == grp( gidx);
                mat = data( grp_loc, :);
                [pca_result( gidx).coeff, pca_result( gidx).score, pca_result( gidx).roots] = pca( mat);
                pca_result( gidx).avg = mean( mat, 1);
                fitted_data( grp_loc, :) = bsxfun( @plus, pca_result( gidx).avg,...
                    pca_result( gidx).score( :, 1) * pca_result( gidx).coeff( :, 1)');
            end
        end % function Eviewer_FitLine3d


        function Eviewer_CloseFigs(app)
            % close all other figures
            figs2close = {app.userdata.surf_yoke_name,...
                app.userdata.mni_surf_yoke_name,...
                app.userdata.misc.t13d_figname,...
                app.userdata.misc.std3d_figname};

            for k = 1 : length( figs2close)
                f = findobj( 'name', figs2close{k});
                if ~isempty( f)
                    delete( f);
                end
            end

            app.fighandle.fs_yoke = [];
            % handle to surface patch
            app.fighandle.fssurf.lh = [];
            app.fighandle.fssurf.rh = [];

            app.fighandle.fs_all_elec = [];
            app.fighandle.fs_cur_elec = [];
            app.fighandle.fs_proj_elc = [];

            app.fighandle.mnisurf_yoke = [];
            app.fighandle.mnisurf.lh = [];
            app.fighandle.mnisurf.rh = [];

            app.fighandle.mni_all_elec = [];
            app.fighandle.mni_cur_elec = [];
            app.fighandle.mni_proj_elec = [];

            app.fighandle.t13d_fig = [];
            app.fighandle.std3d_fig = [];
            app.fighandle.fs_proj_elec = [];
            app.fighandle.std_proj_elec = [];

            % individual surface handles
            app.fighandle.fsannot = [];
            app.fighandle.fsannot.lh = [];
            app.fighandle.fsannot.rh = [];

            % Freesurfer MNI surface handles
            app.fighandle.mniannot = [];
            app.fighandle.mniannot.lh = [];
            app.fighandle.mniannot.rh = [];

        end % function Eviewer_CloseFigs


        function inv_reg =  Eviewer_InverseXfm( ~, f)
            % Invert FSL's transformation matrix file
            %
            % inv_reg, '' or full-path-to-inverse transform matrix
            inv_reg = '';
            if isempty( f)
                return;
            end

            fsl_installed = false;
            if ~isempty( getenv( 'FSLDIR'))
                fsl_installed = true;
            else
                if isfolder( '/usr/local/fsl')
                    setenv( 'FSLDIR', '/usr/local/fsl');
                    fsl_installed = true;
                end
            end

            if fsl_installed
                [p, fname] = fileparts( f);
                inv_f  = fullfile( p, ['inv_', fname, '.mat']);
                cmd = ['source ${FSLDIR}/etc/fslconf/fsl.sh && ' ...
                    'export PATH=$PATH:${FSLDIR}/bin && convert_xfm -omat ',...
                    inv_f, ' -inverse ', f];
                status = system( cmd);
                if status == 0
                    inv_reg = inv_f;
                end
            end
        end % function Eviewer_InverseXfm


        function to_quit = Eviewer_SaveData(app)
            to_quit = 'yes';
            if isempty( app.userdata.elec) || strcmpi( app.userdata.data_saved, 'yes')
                return;
            end

            answer = questdlg( ...
                sprintf( 'Results have not been saved.\nAre you sure to quit?'),...
                'Exit', 'Yes', 'No', 'Cancel', 'No');
            
            if ~strcmpi( answer, 'yes')
                to_quit = 'no';
            end
        end


        function InPlot( app)
            set( app.StatusTextArea, 'Text', 'Updating plots ...', 'Fontcolor', 'b');
            drawnow;

            InitializeYoke( app);

            try
                fns = fieldnames( app.userdata.srcdata.fssurf);
                for k = 1 : length( fns)
                    set( app.StatusTextArea, 'Fontcolor', 'b', 'Text', ...
                        'Plotting individual cortical surface ... please wait');
                    drawnow;
                    fname = fns{ k};
                    if k == 1
                        app.fighandle.fssurf.( fname) = SurfPlot( app.fighandle.fs_yoke, ...
                            app.userdata.srcdata.fssurf.( fname).vert, app.userdata.srcdata.fssurf.( fname).tri);
                    else
                        app.fighandle.fssurf.( fname) = SurfPlot( app.fighandle.fs_yoke,...
                            app.userdata.srcdata.fssurf.( fname).vert, app.userdata.srcdata.fssurf.( fname).tri, 'hold', 'on');
                    end

                    app.fighandle.fsannot.( fname) = SurfPlot( app.fighandle.fs_yoke,...
                        app.userdata.srcdata.fssurf.( fname).vert, app.userdata.srcdata.fssurf.( fname).tri,...
                        'fc', app.userdata.srcdata.fssurf.( fname).label_color/255, ...
                        'vert_alpha', app.userdata.srcdata.fssurf.( fname).label_alpha, 'hold', 'on');
                end

                %alpha( app.fighandle.fs_yoke, app.SurfaceOpacitySlider.Value/100);
                if ~isempty( app.fighandle.fssurf.( fname))
                    alpha( app.fighandle.fssurf.( fname), app.SurfaceOpacitySlider.Value/100);
                    %alpha( app.fighandle.fssurf.rh, app.SurfaceOpacitySlider.Value/100);
                end

            catch
                % failed to read surface
            end


            % MNI brain surface
            set( app.StatusTextArea, 'Text', 'Plotting MNI brain surface ... please wait', 'Fontcolor', 'b');
            drawnow;

            InitializeMNISurfYoke( app);

            % created using CreateSurf
            if ~isempty( app.userdata.srcdata.mnisurf) && isfield( app.userdata.srcdata.mnisurf, 'vert')
                SurfPlot( app.fighandle.mnisurf_yoke, app.userdata.srcdata.mnisurf.vert, app.userdata.srcdata.mnisurf.tri);
                alpha( app.fighandle.mnisurf_yoke, app.SurfaceOpacitySlider.Value/100);

            elseif ~isempty( app.userdata.srcdata.mnisurf) && isfield( app.userdata.srcdata.mnisurf, 'lh')
                % freesurfer MNI surface
                for hemi = {'lh', 'rh'}
                    h = hemi{1};
                    if ~isfield( app.userdata.srcdata.mnisurf, h) || ~isfield( app.userdata.srcdata.mnisurf.( h), 'vert')
                        continue;
                    end

                    if strcmpi( h, 'lh')
                        app.fighandle.mnisurf.(h) = SurfPlot( app.fighandle.mnisurf_yoke,...
                            app.userdata.srcdata.mnisurf.( h).vert, app.userdata.srcdata.mnisurf.( h).tri);
                    else
                        app.fighandle.mnisurf.(h) = SurfPlot( app.fighandle.mnisurf_yoke,...
                            app.userdata.srcdata.mnisurf.( h).vert, app.userdata.srcdata.mnisurf.( h).tri, 'hold', 'on');
                    end

                    % paracellation
                    if ~isempty( app.userdata.srcdata.mniannot.(h))
                        [c, ~, ic] = unique( app.userdata.srcdata.mniannot.(h).label);
                        [~, locb] = ismember(c, app.userdata.srcdata.mniannot.(h).colortable.table( :, 5));
                        label_color = app.userdata.srcdata.mniannot.(h).colortable.table( locb, 1:3);
                        label_color = label_color( ic, :);

                        label_alpha = app.userdata.srcdata.mniannot.(h).colortable.table( :, 6);
                        label_alpha = label_alpha( ic);

                        app.fighandle.mniannot.(h) = SurfPlot( app.fighandle.mnisurf_yoke,...
                            app.userdata.srcdata.mnisurf.( h).vert, app.userdata.srcdata.mnisurf.( h).tri,...
                            'fc', label_color/255, 'vert_alpha', label_alpha, 'hold', 'on');
                    end
                end

                if ~isempty( app.fighandle.mnisurf.lh)
                    alpha( app.fighandle.mnisurf.lh, app.SurfaceOpacitySlider.Value/100);
                    alpha( app.fighandle.mnisurf.rh, app.SurfaceOpacitySlider.Value/100);
                end

            else
                % do nothing
            end

            UpdateYoke( app);

            if ~isempty( app.ElecLabelListbox.Items) > 0
                app.ElecLabelListbox.Value = app.ElecLabelListbox.Items(1);
                ElecLabelListboxValueChanged( app, []);
            end

            set( app.StatusTextArea, 'Text', 'Ready.', 'FontColor', 'b');
            drawnow;
        end % Plot


        function UpdateT1Clim( app)
            h = findall( app.fighandle.t13d_fig, 'type', 'axes');
            for k = 1 : length( h)
                caxis( h(k), app.userdata.srcdata.t1_clim);
            end
        end


        function UpdateStdClim( app)
            h = findall( app.fighandle.std3d_fig, 'type', 'axes');
            for k = 1 : length( h)
                caxis( h(k), app.userdata.srcdata.std_clim);
            end
        end


        function s = OrientLabel(~, vox2ras0)
            s = cell( 1, 6);
            s(:) = {''};
            labels = { {'P', 'A'}, {'L', 'R'}, {'I', 'S'}};
            ars = vox2ras0( 1:3, [2, 1, 3]);
            [~, midx] = max( abs( ars), [], 2);
            for k = 1 : 3
                ind = 2*(k-1) + 1 : 2*k;
                if ars( k, midx(k)) > 0
                    s( ind) = labels{ midx(k)}( [1, 2]);
                else
                    s( ind) = labels{ midx(k)}( [2, 1]);
                end
            end
        end

        
        function [cmdout] = Img2ImgCoords( ~, coord_file, regmat, src_img, targ_img, varargin)
            % Coordinates transformation using FSL's img2imgcoord
            %
            % Usage
            %   [cmdout] = Img2ImgCoords( coord_file, regmat, src_img, targ_img, varargin)
            %
            % Input
            %   coord_file, coordinates file N (rows) x 3 or Nx3 matrix, both input and
            %               output are in mm.
            %   reg, registration matrix, i.e. src_img -> targ_img registration matrix
            %   src_img, source image
            %   targ_img, targ_img
            %   varargin{1}, full-path-to-file2save
            %
            % Output
            %   cmdout, coordinates in target image space
            %
            % Example
            %   coord_file = '~/Tmp/TestSubj/BrainImage/RegCT2MR/Coords/ct2mri_affine2MNI152_elec_clean.txt';
            %   targ_img = '~/Desktop/Tmp/TestSubj/BrainImage/MR_pre.nii.gz';
            %   src_img = '/usr/local/fsl/data/standard/MNI152_T1_1mm_brain.nii.gz';
            %   regmat = '~/Desktop/Tmp/TestSubj/BrainImage/reg/std2MR_pre.mat';
            %   coords = Img2ImgCoords( coord_file, regmat, src_img, targ_img, varargin);
            %
            % make sure you are using the latest FSL version, see FSL's img2imgcoord help

            %

            if nargin < 4
                error( 'Not enough input arguments.');
            end

            if ~isempty( varargin)
                output_file = varargin{1};
            else
                output_file = '';
            end

            cmdout = [];
            if ~exist( regmat, 'file')
                % fprintf( ['Img2ImgCoords: Transformation matrix does not exist.\n']);
                return;
            end

            if ~exist( src_img, 'file')
                % fprintf( ['Img2ImgCoords: Source image does not exist.\n']);
                return;
            end

            if ~exist( targ_img, 'file')
                % fprintf( [ 'Img2ImgCoords: Target image does not exist.\n']);
                return;
            end

            % make sure fsl's img2imgcoord command was available
            if isempty( getenv( 'FSLDIR'))
                if exist( '/usr/local/fsl', 'dir')
                    setenv( 'FSLDIR', '/usr/local/fsl');
                else
                    return;
                end
            end

            % % Interactive mode was no longer available since 6.0.2

            % make sure all files exist
            delete_file = 'no';
            if isnumeric( coord_file)
                if isempty( coord_file)
                    return;
                end

                sz = size( coord_file);
                if length( sz) > 2 || sz(2) ~= 3
                    error( 'coordinates must be a Nx3 matrix.');
                end

                p = fileparts( mfilename( 'fullpath'));
                tmp_coord_file = fullfile( p, ['tmp_coord-', date, '.txt']);
                delete_file = 'yes';

                fid = fopen( tmp_coord_file, 'w+');
                for k =1 : size( coord_file, 1)
                    fprintf( fid, '%f %f %f', coord_file(k, 1), coord_file( k, 2), coord_file( k, 3));
                    if k < size( coord_file, 1)
                        fprintf( fid, '\n');
                    end
                end
                fclose( fid);

                coord_file = tmp_coord_file;

            else
                if ~exist( coord_file, 'file')
                    error( [coord_file, ' does not exist']);
                end
            end

            if endsWith( regmat, '.nii.gz')
                cmd = ['img2imgcoord -mm -warp ', regmat, ' -src  ', src_img, ' -dest ', targ_img, ' ', coord_file];

            elseif endsWith( regmat, '.mat')
                cmd = ['img2imgcoord -mm -xfm ', regmat, ' -src  ', src_img, ' -dest ', targ_img, ' ', coord_file];

            else
                error( 'Unsupproted transformatioin matrix.');
            end

            cmd = ['source ${FSLDIR}/etc/fslconf/fsl.sh && export PATH=$PATH:${FSLDIR}/bin && ', cmd];

            % save to file
            if ~isempty( output_file)
                tmp_cmd = [cmd,  ' > ' output_file];
                system( tmp_cmd)
            end

            % assign to outout variable
            [~, cmdout] =  system( cmd);
            [~, cmdout] = strtok( cmdout, ')');
            cmdout = str2num( cmdout( 2:end));

            if strcmpi( delete_file, 'yes')
                if exist( coord_file, 'file')
                    delete( coord_file);
                end
            end
        end
    end % Methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize userdata structure
            Eviewer_InitializeUserdata( app);

            % close all dependent figures
            Eviewer_CloseFigs( app);

            warning off MATLAB:subscripting:noSubscriptsSpecified

            app.TabGroup.Visible = 'off';

            app.StatusTextArea.FontSize = 14;
        end

        % Menu selected function: LoadMenu
        function LoadDataButtonPushed(app, event)
            [file, p] = uigetfile( fullfile( app.userdata.srcdir, '*.mat'));

            orig_file = fullfile( p, file);
            if isequal( file, 0)
                % file selection failure
                return;
            end

            Eviewer_InitializeUserdata( app);
            Eviewer_CloseFigs( app);

            try
                file = fullfile( p, file);
                app.userdata.srcdir = p;
                elec = load( file);
                elec = elec.elec;
                app.userdata.elec = elec;
                app.userdata.filename = file;

                % initialize transformation fields
                if isfield( elec, 'reg') && isstruct( elec.reg)
                    orig_fname = fieldnames( elec.reg);
                    reg_fname = fieldnames( app.userdata.reg);
                    for k = 1 : length( reg_fname)
                        nam = reg_fname{ k};
                        if ismember( nam, orig_fname)
                            app.userdata.reg.( nam) = elec.reg.( nam);
                        end
                    end
                end

                if isfield( elec, 'atlas') && isstruct( elec.atlas) ...
                        && isequal( fieldnames( elec.atlas), fieldnames( app.userdata.atlas.query))
                    app.userdata.atlas.query = elec.atlas;
                end

                if isfield( elec, 'surf') && isfolder( elec.surf.dir)
                    f1 = fullfile( elec.surf.dir, 'label', ['lh.', elec.surf.parc, '.annot']);
                    f2 = fullfile( elec.surf.dir, 'label', ['rh.', elec.surf.parc, '.annot']);
                    if isfile( f1) && isfile( f2)
                        app.userdata.srcdata.fsannot.filename.lh = f1;
                        app.userdata.srcdata.fsannot.filename.rh = f2;
                        app.userdata.srcdata.fsannot.parc = elec.surf.parc;
                    end
                end

                % initialize electrode projection
                if isfield( elec, 'proj') && isstruct( elec.proj)
                    app.userdata.proj = elec.proj;
                end

                if length( elec.label) ~= length( app.userdata.proj.status)
                    app.userdata.proj.status = zeros( length( elec.label), 1);
                end

                app.TabGroup.Visible = 'on';

                % set GUI options
                Eviewer_InitializeTransform( app);


                % load previously saved brain and surface images
                if isfield( elec, 'srcdata')
                    app.userdata.srcdata = elec.srcdata;

                    % read t1 and standard brain
                    if ~isempty( app.userdata.srcdata.t1)
                        app.T1IntensityLo.Value = app.userdata.srcdata.t1_clim(1);
                        app.T1IntensityHi.Value = app.userdata.srcdata.t1_clim(2);
                    end

                    % read MNI512 standard brain
                    if ~isempty( app.userdata.srcdata.std)
                        app.MNIintensityLo.Value = app.userdata.srcdata.std_clim(1);
                        app.MNIIntensityHi.Value = app.userdata.srcdata.std_clim(2);
                    end

                    % app.userdata.srcdata.surfmri
                    if ~isempty( app.userdata.srcdata.mniannot)
                        app.FSParcellationListBox.Items = app.userdata.srcdata.mniannot.rh.colortable.struct_names;
                    end
                end

                ChangeVisibility( app, 'on');

                app.StatusTextArea.Text = ['Succeeded in loading ', file];
                app.StatusTextArea.FontColor = 'b';

                if isfield( elec, 'matdata')
                    InPlot( app);
                end

            catch
                %warndlg( 'Failed to load the electrode location data.', 'Unsupported file');
                app.StatusTextArea.Text = ['Failed to load ', orig_file];
                app.StatusTextArea.FontColor = 'r';

                ChangeVisibility( app, 'off');
                app.TabGroup.Visible = 'off';
            end

        end

        % Button pushed function: SelectButton_MNI
        function SelectButton_MNIPushed(app, event)
            fsldir = getenv( 'FSLDIR');
            if ~isempty( fsldir)
                [file, path] = uigetfile( fullfile( fsldir, 'data', 'standard', '*.nii.gz'));
            else
                [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.nii.gz'));
            end

            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.stdimg = s;
                app.MNIImageEditField.Value = s;
                app.SelectButton_MNI.FontColor = 'k';
            end
        end

        % Value changed function: MNIImageEditField
        function MNIImageEditFieldValueChanged(app, event)
            value = app.MNIImageEditField.Value;
            if exist( value, 'file')
                app.userdata.reg.stdimg = value;
                txt = 'MNI image was set.';
                txt_ck = 'b';

                app.SelectButton_MNI.FontColor = 'k';
                app.MNIImageEditField.FontColor = 'k';

            else
                % app.MNIImageEditField.Value = app.userdata.reg.stdimg;
                app.userdata.reg.stdimg = '';
                txt = 'MNI image was not set successfully.';
                txt_ck = 'r';
                app.SelectButton_MNI.FontColor = 'r';
                app.MNIImageEditField.FontColor = 'r';
            end

            app.StatusTextArea.Text = txt;
            app.StatusTextArea.FontColor = txt_ck;
        end

        % Button pushed function: SelectButton_CT
        function SelectButton_CTPushed(app, event)
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.nii.gz'));
            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.ctimg = s;
                app.CTImageEditField.Value = s;
                txt = 'CT image was set.';
                txt_ck = 'b';

                app.SelectButton_CT.FontColor = 'k';
                app.CTImageEditField.FontColor = 'k';

            else
                txt = 'CT image was not set successfully.';
                txt_ck = 'r';
                app.SelectButton_CT.FontColor = 'r';
                app.CTImageEditField.FontColor = 'r';
            end

            app.StatusTextArea.Text = txt;
            app.StatusTextArea.FontColor = txt_ck;
        end

        % Value changed function: CTImageEditField
        function CTImageEditFieldValueChanged(app, event)
            value = app.CTImageEditField.Value;
            if exist( value, 'file')
                app.userdata.reg.ctimg = value;
                txt = 'CT image was set.';
                txt_ck = 'b';
                app.SelectButton_CT.FontColor = 'k';
                app.CTImageEditField.FontColor = 'k';

            else
                % app.CTImageEditField.Value = app.userdata.reg.ctimg;
                app.userdata.reg.ctimg = '';
                txt = 'CT image does not exist, restored to previous value.';
                txt_ck = 'r';
                app.SelectButton_CT.FontColor = 'r';
                app.CTImageEditField.FontColor = 'r';
            end

            app.StatusTextArea.Text = txt;
            app.StatusTextArea.FontColor = txt_ck;
        end

        % Button pushed function: SelectButton_T1
        function SelectButton_T1Pushed(app, event)
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.nii.gz'));
            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.t1img = s;
                app.T1ImageEditField.Value = s;
                txt = 'T1 image was set.';
                txt_ck = 'b';
                app.SelectButton_T1.FontColor = 'k';
                app.T1ImageEditField.FontColor = 'k';

            else
                txt = 'T1 image was not set successfully.';
                txt_ck = 'r';
                app.SelectButton_T1.FontColor = 'r';
                app.T1ImageEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Value changed function: T1ImageEditField
        function T1ImageEditFieldValueChanged(app, event)
            value = app.T1ImageEditField.Value;
            if exist( value, 'file')
                app.userdata.reg.t1img = value;
                txt = 'T1 image was set.';
                txt_ck = 'b';
                app.SelectButton_T1.FontColor = 'k';
                app.T1ImageEditField.FontColor = 'k';

            else
                app.userdata.reg.t1img = '';
                txt = 'T1 image does not exist, restored to previous value.';
                txt_ck = 'r';
                app.SelectButton_T1.FontColor = 'r';
                app.T1ImageEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: SelectButton_CT2T1
        function SelectButton_CT2T1Pushed(app, event)
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.mat'));
            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.ct2t1 = s;
                app.CTT1EditField.Value = s;
                txt = 'CT to T1 image registration transform was set.';
                txt_ck = 'b';
                app.SelectButton_CT2T1.FontColor = 'k';
                app.CTT1EditField.FontColor = 'k';
            else
                txt = 'Failed to set CT to T1 image registration transform.';
                txt_ck = 'r';
                app.SelectButton_CT2T1.FontColor = 'r';
                app.CTT1EditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Value changed function: CTT1EditField
        function CTT1EditFieldValueChanged(app, event)
            value = app.CTT1EditField.Value;
            if exist( value, 'file')
                app.userdata.reg.ct2std = value;
                txt = 'CT to T1 image registration transform was set.';
                txt_ck = 'b';
                app.SelectButton_CT2T1.FontColor = 'k';
                app.CTT1EditField.FontColor = 'k';
            else
                app.CTT1EditField.Value = app.userdata.reg.ct2t1;
                txt = 'Failed to set CT to T1 image registration transform.';
                txt_ck = 'r';
                app.SelectButton_CT2T1.FontColor = 'r';
                app.CTT1EditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: SelectButton_T12MNI
        function SelectButton_T12MNIPushed(app, event)
            % [file, path] = uigetfile( {fullfile( app.userdata.srcdir, '*.mat'), ...
            %     fullfile( app.userdata.srcdir, '*.nii.gz')});
            % [file, path] = uigetfile( {'*.mat'; '*.gz'; '*.nii'});
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.*'));

            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.t12std = s;
                app.T1MNIEditField.Value = s;
                txt = 'T1 to MNI image registration transform was set.';
                txt_ck = 'b';
                app.SelectButton_T12MNI.FontColor = 'k';
                app.T1MNIEditField.FontColor = 'k';

            else
                txt = 'Failed to set T1 to MNI image registration transform.';
                txt_ck = 'r';
                app.SelectButton_T12MNI.FontColor = 'r';
                app.T1MNIEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Value changed function: T1MNIEditField
        function T1MNIEditFieldValueChanged(app, event)
            value = app.T1MNIEditField.Value;
            if exist( value, 'file')
                app.userdata.reg.t12std = value;
                txt = 'T1 to MNI image registration transform was set.';
                txt_ck = 'b';
                app.SelectButton_T12MNI.FontColor = 'k';
                app.T1MNIEditField.FontColor = 'k';
            else
                app.T1MNIEditField.Value = app.userdata.reg.t12std;
                txt = 'Failed to set T1 to MNI image registration transform.';
                txt_ck = 'r';
                app.SelectButton_T12MNI.FontColor = 'r';
                app.T1MNIEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: SelectButton_CT2MNI
        function SelectButton_CT2MNIPushed(app, event)
            % [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.mat'));
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.*'));
            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.ct2std = s;
                app.CTMNIEditField.Value = s;
                txt = 'CT to MNI image registration transform was set.';
                txt_ck = 'b';
                app.SelectButton_CT2MNI.FontColor = 'k';
                app.CTMNIEditField.FontColor = 'k';

            else
                txt = 'Failed to set CT to MNI image registration transform.';
                txt_ck = 'r';
                app.SelectButton_CT2MNI.FontColor = 'r';
                app.CTMNIEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Value changed function: CTMNIEditField
        function CTMNIEditFieldValueChanged(app, event)
            value = app.CTMNIEditField.Value;
            if exist( value, 'file')
                app.userdata.reg.ct2std = value;
                txt = 'CT to MNI image registration transform was set.';
                txt_ck = 'b';
                app.SelectButton_CT2MNI.FontColor = 'k';
                app.CTMNIEditField.FontColor = 'k';

            else
                app.CTMNIEditField.Value = app.userdata.reg.ct2std;
                txt = 'Failed to set CT to MNI image registration transform.';
                txt_ck = 'r';
                app.SelectButton_CT2MNI.FontColor = 'r';
                app.CTMNIEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: SelectButton_Raw2Orig
        function SelectButton_Raw2OrigPushed(app, event)
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.mat'));
            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.t12surf = s;
                app.FSRawOrigEditField.Value = s;
                txt = ' Raw to Orig image registration transform was set.';
                txt_ck = 'b';

                app.SelectButton_Raw2Orig.FontColor = 'k';
                app.FSRawOrigEditField.FontColor = 'k';

            else
                txt = 'Failed to set Raw to Orig image registration transform.';
                txt_ck = 'r';
                app.SelectButton_Raw2Orig.FontColor = 'r';
                app.FSRawOrigEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Value changed function: FSRawOrigEditField
        function FSRawOrigEditFieldValueChanged(app, event)
            value = app.FSRawOrigEditField.Value;
            if exist( value, 'file')
                app.userdata.reg.t12surf = value;
                txt = ' Raw to Orig image registration transform was set.';
                txt_ck = 'b';
                app.SelectButton_Raw2Orig.FontColor = 'k';
                app.FSRawOrigEditField.FontColor = 'k';

            else
                app.FSRawOrigEditField.Value = app.userdata.reg.t12surf;
                txt = 'Failed to set Raw to Orig image registration transform.';
                txt_ck = 'r';
                app.SelectButton_Raw2Orig.FontColor = 'r';
                app.FSRawOrigEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: SelectButton_Orig
        function SelectButton_OrigPushed(app, event)
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.nii.gz'));
            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.surfvol = s;
                app.FSOrigEditField.Value = s;
                txt = 'Freesurfer Orig image was set.';
                txt_ck = 'b';
                app.SelectButton_Orig.FontColor = 'k';
                app.FSOrigEditField.FontColor = 'k';

            else
                txt = 'Failed to set Freesurfer Orig image.';
                txt_ck = 'r';
                app.SelectButton_Orig.FontColor = 'r';
                app.FSOrigEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Value changed function: FSOrigEditField
        function FSOrigEditFieldValueChanged(app, event)
            value = app.FSOrigEditField.Value;
            if exist( value, 'file')
                app.userdata.reg.surfvol = value;
                txt = 'Freesurfer Orig image was set.';
                txt_ck = 'b';
                app.SelectButton_Orig.FontColor = 'k';
                app.FSOrigEditField.FontColor = 'k';

            else
                app.FSOrigEditField.Value = app.userdata.reg.surfvol;
                txt = 'Failed to set Freesurfer Orig image.';
                txt_ck = 'r';
                app.SelectButton_Orig.FontColor = 'r';
                app.FSOrigEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: SelectButton_Surface
        function SelectButton_SurfacePushed(app, event)
            [file, path] = uigetfile( {'*.mat;*.pial'}, 'MultiSelect', 'on');
            if ~isequal( file, 0)
                if iscell( file)
                    [~, ~, ext] = fileparts( file{1});
                else
                    [~, ~, ext] = fileparts( file);
                    file = {file};
                end

                s = fullfile( path, file);
                app.userdata.reg.surffile = s;
                app.SurfaceTextArea.Value = s;

                txt = 'Freesurfer surface files was set.';
                txt_ck = 'b';

                if strcmpi( ext, '.pial')
                    subjdir = strrep( path, 'surf', 'label');
                    parc_nam = app.userdata.srcdata.fsannot.parc;
                    file = fullfile( subjdir, sprintf( 'lh.%s.annot', parc_nam));
                    if exist( file, 'file')
                        app.userdata.srcdata.fsannot.filename.lh = file;
                    end

                    file = fullfile( subjdir, sprintf( 'rh.%s.annot', parc_nam));
                    if exist( file, 'file')
                        app.userdata.srcdata.fsannot.filename.rh = file;
                    end
                end

                app.SelectButton_Surface.FontColor = 'k';
                app.SurfaceTextArea.FontColor = 'k';

            else
                txt = 'Failed to set Freesurfer surface files.';
                txt_ck = 'r';
                app.SelectButton_Surface.FontColor = 'r';
                app.SurfaceTextArea.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: SelectButton_MNISurf
        function SelectButton_MNISurfPushed(app, event)
            [file, path] = uigetfile( fullfile( app.userdata.srcdir, '*.mat'));
            if ~isequal( file, 0)
                s = fullfile( path, file);
                app.userdata.reg.mnisurf = s;
                app.MNISurfaceEditField.Value = s;
                txt = ' MNI surface file was set.';
                txt_ck = 'b';
                app.SelectButton_MNISurf.FontColor = 'k';
                app.MNISurfaceEditField.FontColor = 'k';

            else
                txt = 'Failed to set MNI surface file.';
                txt_ck = 'r';
                app.SelectButton_MNISurf.FontColor = 'r';
                app.MNISurfaceEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Value changed function: MNISurfaceEditField
        function MNISurfaceEditFieldValueChanged(app, event)
            value = app.MNISurfaceEditField.Value;
            if exist( value, 'file') || isfolder( value)
                app.userdata.reg.mnisurf = value;
                txt = ' MNI surface file was set.';
                txt_ck = 'b';
                app.SelectButton_MNISurf.FontColor = 'k';
                app.MNISurfaceEditField.FontColor = 'k';
            else
                app.MNISurfaceEditField.Value = app.userdata.reg.mnisurf;
                txt = 'Failed to set MNI surface file.';
                txt_ck = 'r';
                app.SelectButton_MNISurf.FontColor = 'r';
                app.MNISurfaceEditField.FontColor = 'r';
            end

            set( app.StatusTextArea, 'Text', txt, 'FontColor', txt_ck);
        end

        % Button pushed function: AutoFillButton_Transf
        function AutoFillButton_TransfPushed(app, event)
            % fill transformation setup automatically
            if isempty( app.userdata.elec)
                app.StatusTextArea.Text = 'No auo fill was done.';
                app.StatusTextArea.FontColor = 'r';
                return;
            end

            try
                orig_subjdir = fileparts( app.userdata.elec.coord.src_img);
            catch
                orig_subjdir = fileparts( app.userdata.elec.elecpos.sourcefile);
            end

            if strcmpi( orig_subjdir, '.')
                orig_subjdir = pwd;
            end

            % retrieve work path from the elctrode file
            cur_subjdir = fileparts( app.userdata.filename);
            home_d = getenv( 'HOME');
            if ~isempty( home_d) && ~startsWith( cur_subjdir, '~')
                usrnam = sprintf( '^%s', home_d);
                cur_subjdir = regexprep( cur_subjdir, usrnam, '~');
                orig_subjdir = regexprep( orig_subjdir, usrnam, '~');
            end           

            if ~strcmp( orig_subjdir, cur_subjdir)
                % warning of directory change
                warning( 'Subject directory %s is different from original %s.', ...
                    cur_subjdir, orig_subjdir);
            end

            app.userdata.reg.srcvol = '';
            app.userdata.reg.img_space = 'ct';
            app.userdata.reg.surf_space = 'fs';

            % default file names
            % registeration matrix folder
            reg_folder = 'reg';
            % freesurfer subject ID
            fs_subjid = 'FS';
            ct_name = 'CT.nii.gz';
            t1_name = 'MR_pre.nii.gz';
            % FSL's flirt transformation matrix name; CT->T1
            ct_to_t1_regname = 'ct2MR_pre_affine.mat';
            % FSL's flirt transformation matrix name; CT->STD
            ct_to_std_regname = 'ct2MR_pre2std.mat';            
            % FSL's flirt transformation matrix name; T1->STD
            t1_to_std_regname = 'MR_pre2std.mat';

            % default to nonlinear now
            ct_to_std_regname = 'ct2MR_pre2std_nonlinear_transf.nii.gz';
            t1_to_std_regname = 'MR_pre2std_nonlinear_transf.nii.gz';

            % full-path-to MNI standard brain
            mni_brain = '/usr/local/fsl/data/standard/MNI152_T1_1mm_brain.nii.gz';

            subjdir = fileparts( app.userdata.filename);
            file = fullfile( subjdir, ct_name);
            if exist( file, 'file')
                app.userdata.reg.ctimg = file;
                app.SelectButton_CT.FontColor = 'k';
                app.CTImageEditField.FontColor = 'k';
            end

            file = fullfile( subjdir, t1_name);
            if exist( file, 'file')
                app.userdata.reg.t1img = file;
                app.SelectButton_T1.FontColor = 'k';
                app.T1ImageEditField.FontColor = 'k';
            end

            if exist( mni_brain, 'file')
                app.userdata.reg.stdimg = mni_brain;
                app.SelectButton_MNI.FontColor = 'k';
                app.MNIImageEditField.FontColor = 'k';
            end

            % transformation matrix resulted from FSL's flirt
            file = fullfile( subjdir, reg_folder, ct_to_t1_regname);
            if exist( file, 'file')
                app.userdata.reg.ct2t1 = file;
                app.SelectButton_CT2T1.FontColor = 'k';
                app.CTT1EditField.FontColor = 'k';
            end

            file = fullfile( subjdir, reg_folder, ct_to_std_regname);
            if exist( file, 'file')
                app.userdata.reg.ct2std = file;
                app.SelectButton_CT2MNI.FontColor = 'k';
                app.CTMNIEditField.FontColor = 'k';
            end

            file = fullfile( subjdir, reg_folder, t1_to_std_regname);
            if exist( file, 'file')
                app.userdata.reg.t12std = file;
                app.SelectButton_T12MNI.FontColor = 'k';
                app.T1MNIEditField.FontColor= 'k';
            end

            % Freesurfer's raw.nii.gz -> orig.nii.gz
            fsdir = fullfile( subjdir, fs_subjid);
            file = fullfile( fsdir, 'mri', 'raw2orig.mat');
            if exist( file, 'file')
                app.userdata.reg.t12surf = file;
                app.SelectButton_Raw2Orig.FontColor = 'k';
                app.FSRawOrigEditField.FontColor = 'k';
            end

            file = fullfile( fsdir, 'surf', 'lh.pial');
            if exist( file, 'file')
                app.userdata.reg.surffile = {file,...
                    fullfile( subjdir, fs_subjid, 'surf', 'rh.pial')};
                app.SelectButton_Surface.FontColor = 'k';
                app.SurfaceTextArea.FontColor = 'k';
            end

            % volume in Freesurfer space, orig.nii.gz
            file = fullfile( fsdir, 'mri', 'orig.nii.gz');
            if exist( file, 'file')
                app.userdata.reg.surfvol = file;
                app.SelectButton_Orig.FontColor = 'k';
                app.FSOrigEditField.FontColor = 'k';
            end

            % Freesurfer annotation files
            parc_nam = app.userdata.srcdata.fsannot.parc;
            file = fullfile( fsdir, 'label', sprintf( 'lh.%s.annot', parc_nam));

            if exist( file, 'file')
                app.userdata.srcdata.fsannot.filename.lh = file;
            end

            file = fullfile( fsdir, 'label', sprintf( 'rh.%s.annot', parc_nam));
            if exist( file, 'file')
                app.userdata.srcdata.fsannot.filename.rh = file;
            end

            fs_home = getenv( 'FREESURFER_HOME');
            d = fullfile( fs_home, 'subjects', 'cvs_avg35_inMNI152');
            if isfolder( d)
                app.userdata.reg.mnisurf = d;
                app.SelectButton_MNISurf.FontColor = 'k';
                app.MNISurfaceEditField.FontColor = 'k';
            else
                app.userdata.reg.mnisurf = '';
            end

            Eviewer_InitializeTransform( app);

            app.StatusTextArea.Text = 'Auto Fill done.';
            app.StatusTextArea.FontColor = 'b';


        end

        % Button pushed function: ApplyButton_ApplyTransf
        function ApplyButton_ApplyTransfPushed(app, event)
            if isempty( app.userdata.elec)
                return;
            end

            ready_to_plot = 'yes';

            % Read T1 image
            app.userdata.srcdata.t1 = [];
            app.userdata.srcdata.t1_clim = [];
            try
                set( app.StatusTextArea, 'Text', 'Reading T1 image ...', 'FontColor', 'b');

                app.userdata.srcdata.t1 = MRIread( app.userdata.reg.t1img);
                app.userdata.srcdata.t1.vol = permute( app.userdata.srcdata.t1.vol, [2, 1, 3]);
                app.userdata.srcdata.t1_clim = prctile( app.userdata.srcdata.t1.vol(:), [2, 95]);
                app.userdata.default_t1_clim = app.userdata.srcdata.t1_clim;

                app.T1IntensityLo.Value = app.userdata.srcdata.t1_clim(1);
                app.T1IntensityHi.Value = app.userdata.srcdata.t1_clim(2);

            catch
                app.SelectButton_T1.FontColor = 'r';
            end

            drawnow;

            % read MNI512 standard brain
            app.userdata.srcdata.std = [];
            app.userdata.srcdata.std_clim = [];
            try
                set( app.StatusTextArea, 'Text', 'Reading MNI image ...', 'FontColor', 'b');
                app.userdata.srcdata.std = MRIread( app.userdata.reg.stdimg);
                app.userdata.srcdata.std.vol = permute( app.userdata.srcdata.std.vol, [2, 1, 3]);

                if ~isempty( strfind( app.userdata.reg.stdimg, 'MNI152'))
                    app.userdata.srcdata.std_clim = [3, 8] * 1000;
                else
                    app.userdata.srcdata.std_clim = prctile( app.userdata.srcdata.std.vol(:), [2, 95]);
                end

                app.userdata.default_std_clim = app.userdata.srcdata.std_clim;
                app.MNIintensityLo.Value = app.userdata.srcdata.std_clim(1);
                app.MNIIntensityHi.Value = app.userdata.srcdata.std_clim(2);

            catch
                app.SelectButton_MNI.FontColor = 'r';
            end

            drawnow;

            % Freesurfer orig.nii.gz (orig.mgz)
            app.userdata.srcdata.surfmri = [];
            try
                set( app.StatusTextArea, 'Text', 'Reading Surface volume ...', 'FontColor', 'b');
                app.userdata.srcdata.surfmri = MRIread( app.userdata.reg.surfvol);
                app.userdata.srcdata.surfmri.vol = permute( app.userdata.srcdata.surfmri.vol, [2, 1, 3]);

            catch
                app.SelectButton_Orig.FontColor = 'r';
            end

            drawnow;

            % individual annot
            app.userdata.srcdata.fsannot.lh = [];
            app.userdata.srcdata.fsannot.rh = [];
            if ~isempty( app.userdata.srcdata.fsannot.filename.lh)
                set( app.StatusTextArea, 'FontColor', 'b', ...
                    'Text', 'Reading individual Freesurfer annoat ...');
                drawnow;

                [app.userdata.srcdata.fsannot.lh.vert, app.userdata.srcdata.fsannot.lh.label, colortable] = ...
                    read_annotation( app.userdata.srcdata.fsannot.filename.lh);

                app.userdata.srcdata.fsannot.lh.colortable = colortable;
                app.userdata.srcdata.fsannot.lh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);
                app.userdata.srcdata.fsannot.lh.colortable.table = app.userdata.srcdata.fsannot.lh.colortable.table;
            end

            if ~isempty( app.userdata.srcdata.fsannot.filename.rh)
                set( app.StatusTextArea, 'FontColor', 'b', ...
                    'Text', 'Reading individual Freesurfer annoat ...');
                drawnow;

                [app.userdata.srcdata.fsannot.rh.vert, app.userdata.srcdata.fsannot.rh.label, colortable] = ...
                    read_annotation( app.userdata.srcdata.fsannot.filename.rh);

                app.userdata.srcdata.fsannot.rh.colortable = colortable;
                app.userdata.srcdata.fsannot.rh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);
                app.userdata.srcdata.fsannot.rh.colortable.table = app.userdata.srcdata.fsannot.rh.colortable.table;
                app.FSParcellationListBox.Items = colortable.struct_names;
            end


            % Freesurfer fsaverageMNI parcellation
            app.userdata.srcdata.mniannot.lh = [];
            app.userdata.srcdata.mniannot.rh = [];
            if isfolder( app.userdata.reg.mnisurf)
                set( app.StatusTextArea, 'FontColor', 'b', ...
                    'Text', 'Reading MNI brain surface ...');
                drawnow;

                nam = [app.userdata.srcdata.mniannot.parc, '.annot'];
                [app.userdata.srcdata.mniannot.lh.vert, ...
                    app.userdata.srcdata.mniannot.lh.label, colortable] = ...
                    read_annotation( fullfile( app.userdata.reg.mnisurf, 'label', ['lh.', nam]));

                app.userdata.srcdata.mniannot.lh.colortable = colortable;
                app.userdata.srcdata.mniannot.lh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);

                app.userdata.srcdata.mniannot.lh.colortable.table = app.userdata.srcdata.mniannot.lh.colortable.table;

                [app.userdata.srcdata.mniannot.rh.vert, ...
                    app.userdata.srcdata.mniannot.rh.label, colortable] = ...
                    read_annotation( fullfile( app.userdata.reg.mnisurf, 'label', ['rh.', nam]));


                app.userdata.srcdata.mniannot.rh.colortable = colortable;
                app.userdata.srcdata.mniannot.rh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);

                % why the struct_names of Freesurfer is different from individual brain?
                app.FSParcellationListBox.Items = colortable.struct_names;
            end

            % Freesurfer surface
            app.userdata.srcdata.fssurf = [];
            try
                for k = 1 : length( app.userdata.reg.surffile)
                    [v, f] = read_surf( app.userdata.reg.surffile{ k});
                    f = f + 1;

                    % plot parcellation and return
                    [~, fname] = fileparts( app.userdata.reg.surffile{ k});

                    app.userdata.srcdata.fssurf.( fname).vert =  v;
                    app.userdata.srcdata.fssurf.( fname).tri =  f;

                    [c, ~, ic] = unique( app.userdata.srcdata.fsannot.( fname).label);
                    [~, locb] = ismember(c, app.userdata.srcdata.fsannot.( fname).colortable.table( :, 5));
                    label_color = app.userdata.srcdata.fsannot.( fname).colortable.table( locb, 1:3);
                    app.userdata.srcdata.fssurf.( fname).label_color = label_color( ic, :);

                    label_alpha = app.userdata.srcdata.fsannot.( fname).colortable.table( :, 6);
                    app.userdata.srcdata.fssurf.( fname).label_alpha = label_alpha( ic);
                end

            catch
                % failed to read surface
                app.userdata.srcdata.fssurf = [];
            end

            % MNI surface
            app.userdata.srcdata.mnisurf = [];
            if isfile( app.userdata.reg.mnisurf)
                % created using CreateSurf
                s = load( app.userdata.reg.mnisurf);
                fn = fieldnames( s);
                app.userdata.srcdata.mnisurf.vert =  s.(fn{1}).vert;
                app.userdata.srcdata.mnisurf.tri =  s.(fn{1}).tri;

            elseif isfolder( app.userdata.reg.mnisurf)
                % freesurfer MNI surface
                [app.userdata.srcdata.mnisurf.lh.vert, app.userdata.srcdata.mnisurf.lh.tri] = ...
                    read_surf( fullfile( app.userdata.reg.mnisurf, 'surf', 'lh.pial' ));

                app.userdata.srcdata.mnisurf.lh.tri = app.userdata.srcdata.mnisurf.lh.tri + 1;

                [app.userdata.srcdata.mnisurf.rh.vert, app.userdata.srcdata.mnisurf.rh.tri] = ...
                    read_surf( fullfile( app.userdata.reg.mnisurf, 'surf', 'rh.pial' ));

                app.userdata.srcdata.mnisurf.rh.tri = app.userdata.srcdata.mnisurf.rh.tri + 1;

            else
                % do nothing
            end % if freesurfer or createsurf


            % apply transformation matrix to the orginal coordinates
            if app.ReadImagesOnlyCheckBox.Value == 1
                set( app.StatusTextArea, 'Text', 'Ready.', 'FontColor', 'b');
                return;
            end

            set( app.StatusTextArea, 'FontColor', 'b', ...
                'Text', 'Applying transformation matrix ...');
            drawnow;

            elec_space = app.ElectrodeSpaceDropDown.Value;
            surf_space = app.SurfaceSpaceDropDown.Value;
            elec_pos = app.userdata.elec.elecpos.coord;
            if strcmpi( elec_space, 'unknown')
                app.userdata.reg.img_space = 'unknown';
                app.userdata.reg.coord2surf = elec_pos;

                if strcmpi( surf_space, 'unknown')
                    app.userdata.reg.surf_space = 'unknown';
                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                else
                    app.userdata.reg.surf_space = 'std';
                end

            elseif strcmpi( elec_space, 'ct')
                app.userdata.reg.img_space = 'ct';
                if strcmpi( surf_space, 'Unknown')
                    app.userdata.reg.surf_space = 'unknown';
                    app.userdata.reg.coord2surf = elec_pos;

                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                    coord_in_t1 = Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.ct2t1,...
                        app.userdata.reg.ctimg,...
                        app.userdata.reg.t1img);
                    
                    app.userdata.reg.coord2surf = Eviewer_Coord2Surf( app, coord_in_t1);

                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                    app.userdata.reg.coord2surf = Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.ct2t1,...
                        app.userdata.reg.ctimg,...
                        app.userdata.reg.t1img);
                else
                    % CT -> MNI space
                    app.userdata.reg.surf_space = 'std';
                    app.userdata.reg.coord2surf = Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.ct2std,...
                        app.userdata.reg.ctimg,...
                        app.userdata.reg.stdimg);
                end

                % coordinates ct -> t1
                app.userdata.reg.coord2t1 = Img2ImgCoords( app, elec_pos,...
                    app.userdata.reg.ct2t1,...
                    app.userdata.reg.ctimg,...
                    app.userdata.reg.t1img);

                app.userdata.reg.coord2std = Img2ImgCoords( app, elec_pos,...
                    app.userdata.reg.ct2std,...
                    app.userdata.reg.ctimg,...
                    app.userdata.reg.stdimg);

            elseif strcmpi( elec_space, 't1')
                app.userdata.reg.img_space = 't1';
                if strcmpi( surf_space, 'Uknown')
                    app.userdata.reg.surf_space = 'unknown';
                    app.userdata.reg.coord2surf = elec_pos;

                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                    app.userdata.reg.coord2surf = Eviewer_Coord2Surf( app, elec_pos);

                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                    app.userdata.reg.coord2surf = elec_pos;

                else
                    app.userdata.reg.surf_space = 'std';
                    app.userdata.reg.coord2surf = ...
                        Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.t12std,...
                        app.userdata.reg.t1img,...
                        app.userdata.reg.stdimg);
                end

                % coordinates t1 -> t1
                app.userdata.reg.coord2t1 = elec_pos;
                app.userdata.reg.coord2std = Img2ImgCoords( app, elec_pos,...
                    app.userdata.reg.t12std,...
                    app.userdata.reg.t1img,...
                    app.userdata.reg.stdimg);

            else
                app.userdata.reg.img_space = 'std';

                reg_std2t1 = Eviewer_InverseXfm( app, app.userdata.reg.t12std);
                if strcmpi( surf_space, 'Uknown')
                    app.userdata.reg.surf_space = 'unknown';

                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                    coord_in_t1 = Img2ImgCoords( app, elec_pos,...
                        reg_std2t1, app.userdata.reg.stdimg, app.userdata.reg.t1img);
                    app.userdata.reg.coord2surf = Eviewer_Coord2Surf( app, coord_in_t1);

                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                    app.userdata.reg.coord2surf = Img2ImgCoords( app, elec_pos,...
                        reg_std2t1, ...
                        app.userdata.reg.stdimg, ...
                        app.userdata.reg.t1img);

                else
                    app.userdata.reg.surf_space = 'std';
                    app.userdata.reg.coord2surf = elec_pos;
                end

                % coordinates std -> t1
                app.userdata.reg.coord2t1 = Img2ImgCoords( app, elec_pos, ...
                    reg_std2t1, ...
                    app.userdata.reg.stdimg, ...
                    app.userdata.reg.t1img);

                app.userdata.reg.coord2std = elec_pos;
            end % transformation of coordinates between different spaces

            % plot results
            if strcmpi( ready_to_plot, 'yes')
                InPlot( app);
            else
                set( app.StatusTextArea, 'Text', 'Nothing plotted.', 'FontColor', 'b');
                drawnow;
            end
        end

        % Value changed function: ElecLabelGroupListbox
        function ElecLabelGroupListboxValueChanged(app, event)
            if ~isfield( app.userdata, 'label_grp')
                return;
            end

            value = app.ElecLabelGroupListbox.Value;
            loc = ismember( app.userdata.label_grp( :, 1), value);
            sel_lbl = app.userdata.label_grp( loc, 3);
            sel_lbl = cat( 1, sel_lbl{:});
            app.ElecLabelListbox.Value = sel_lbl;
            if iscell( value)
                value = strjoin( value, ', ');
            end

            nb_lbl = numel( sel_lbl);
            app.ElecLabelGroupInfo.Text = [value, ' ', num2str( nb_lbl), ' labels'];
            ElecLabelListboxValueChanged(app);

            % set projection 
            if app.PorjMethodLineFitButton.Value == 1
                app.GridSizeEditField.Value = char( strjoin( string( [1, nb_lbl]), ' '));
            end
        end

        % Value changed function: ElecLabelListbox
        function ElecLabelListboxValueChanged(app, event)
            labels = app.ElecLabelListbox.Value;
            if isempty( labels)
                return;
            end

            grp = ECoG_GroupLabels( cellstr( labels));
            nb_grp = size( grp, 1);
            s = cell( nb_grp, 1);
            for k = 1 : nb_grp
                loc = ismember( app.userdata.label_grp( :, 1), grp{ k, 1});
                s{ k, 1} = [grp{ k, 1}, ': ',...
                    num2str( length( grp{ k, 2})), '/',...
                    num2str( length( app.userdata.label_grp{ loc, 2}))];
            end
            s( end+1, 1) = {['Total: ', num2str( length( labels)), ' labels.']};
            app.SelectedLabelListbox.Items = s;

            % axial, sagittal and cronal views
            if length( labels) == 1
                elec_loc = find( ismember( app.userdata.elec.label, labels));
                % plot electrode on individual anatomical iamge
                if ~isempty( app.fighandle.t13d_fig)...
                        && isvalid(app.fighandle.t13d_fig)...
                        && ~isempty(app.userdata.srcdata.t1) ...
                        && ~isempty( app.userdata.reg.coord2t1)

                    if size( app.userdata.proj.coord2t1, 1) >=  elec_loc ...
                            && app.userdata.proj.status( elec_loc) == 1
                        % use projected coordinates
                        stdcoord = app.userdata.proj.coord2t1( elec_loc, :)';
                    else
                        stdcoord = app.userdata.reg.coord2t1( elec_loc, :)';
                    end
                    % image -> voxel
                    imgcoord = round( app.userdata.srcdata.t1.vox2ras1 \ [stdcoord; 1]);
                    cx = imgcoord(1);
                    cy = imgcoord(2);
                    cz = imgcoord(3);

                    set( 0, 'CurrentFigure', app.fighandle.t13d_fig);
                    clf( app.fighandle.t13d_fig);

                    s = OrientLabel( app, app.userdata.srcdata.t1.vox2ras0);
                    subplot( 222);
                    imagesc( gca, squeeze( app.userdata.srcdata.t1.vol( :, cy, :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.t1.volres( [3, 2, 1]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');

                    sz = size( app.userdata.srcdata.t1.vol);
                    text( 0, sz(3)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(3)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);

                    clim( gca, app.userdata.srcdata.t1_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    line( gca, cx, cz,...
                        'color', 'r', 'marker', 'o', ...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    axis off

                    subplot( 221);
                    imagesc( gca, squeeze( app.userdata.srcdata.t1.vol( cx, :, :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.t1.volres( [3, 1, 2]),...
                        'YDir', 'normal', ...
                        'XTick', [], ...
                        'ytick', [], ...
                        'box', 'off');

                    clim( gca, app.userdata.srcdata.t1_clim);
                    disableDefaultInteractivity( gca);

                    hold on;
                    line( gca, cy, cz,...
                        'color', 'r', 'marker', 'o', 'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    axis off

                    subplot( 223);
                    imagesc( gca, squeeze( app.userdata.srcdata.t1.vol( :, :, cz))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.t1.volres( [1, 2, 3]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    clim( gca, app.userdata.srcdata.t1_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    line( gca, cx, cy,...
                        'color', 'r', 'marker', 'o', 'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    colormap( gray);
                    axis off

                    text( 0, sz(2)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(2)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);
                end

                % plot electrode on standard MNI brain
                if ~isempty( app.fighandle.std3d_fig)...
                        && isvalid(app.fighandle.std3d_fig)...
                        && ~isempty( app.userdata.srcdata.std) ...
                        && ~isempty( app.userdata.reg.coord2std)

                    if size( app.userdata.proj.coord2std, 1) >= elec_loc ...
                            && app.userdata.proj.status( elec_loc) == 1
                        stdcoord = app.userdata.proj.coord2std( elec_loc, :)';
                    else
                        stdcoord = app.userdata.reg.coord2std( elec_loc, :)';
                    end

                    imgcoord = round( app.userdata.srcdata.std.vox2ras1 \ [stdcoord; 1]);
                    cx = imgcoord(1);
                    cy = imgcoord(2);
                    dz = imgcoord(3);

                    set( 0, 'CurrentFigure', app.fighandle.std3d_fig);
                    clf( app.fighandle.std3d_fig);

                    s = OrientLabel( app, app.userdata.srcdata.std.vox2ras0);

                    sz = size( app.userdata.srcdata.std.vol);

                    subplot( 222);
                    imagesc( gca, squeeze( app.userdata.srcdata.std.vol( :, cy, :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.std.volres( [3, 2, 1]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    clim( gca, app.userdata.srcdata.std_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    line( gca, cx, cz,...
                        'color', 'r', 'marker', 'o',...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    axis equal
                    axis off

                    text( 0, sz(3)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(3)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);

                    subplot( 221);
                    imagesc( gca, squeeze( app.userdata.srcdata.std.vol( cx, :, :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.std.volres( [3, 1, 2]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    clim( gca, app.userdata.srcdata.std_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    line( gca, cy, cz,...
                        'color', 'r', 'marker', 'o',...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    axis equal
                    axis off

                    subplot( 223);
                    imagesc( gca, squeeze( app.userdata.srcdata.std.vol( :, :, cz))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.std.volres( [1, 2, 3]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');

                    clim( gca, app.userdata.srcdata.std_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    line( gca, cx, cy,...
                        'color', 'r', 'marker', 'o',...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    colormap( gray);
                    axis equal
                    axis off

                    text( 0, sz(2)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(2)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);

                    subplot( 224);
                    cla( gca);
                    fontsize = 12;
                    fontcolor = 'w';
                    t = text( 0, 4.1, 'MNI coordinates (mm):');
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    t = text( 0.1, 3.5, sprintf( 'x = %.0f', stdcoord(1)));
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    t = text( 0.1, 3, sprintf( 'y = %.0f', stdcoord(2)));
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    t = text( 0.1, 2.5, sprintf( 'z = %.0f', stdcoord(3)));
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    set( gca, 'box', 'off', 'YLim', [0, 5]);
                    axis off
                end

                % ATLAS
                atlas_str = {};
                if ~isempty( app.userdata.atlas.query.fsaseg)
                    atlas_str = cat( 1, atlas_str, ...
                        {'Freesurfer:'},...
                        app.userdata.atlas.query.fsaseg{ elec_loc, 1},...
                        ['Projected: ', upper( app.userdata.atlas.query.fsaseg{ elec_loc, 2})]);
                end

                if ~isempty( app.userdata.atlas.query.fsl)
                    atlas_str = cat( 1, atlas_str, {''},...
                        {'FSL:'}, app.userdata.atlas.query.fsl{ elec_loc, 1});
                end

                atlas_str( cellfun(@isempty, atlas_str)) = {' '};
                app.AtlasListbox.Items = atlas_str;
                if ~isempty( atlas_str)
                    app.AtlasListbox.Value = atlas_str{1};
                else
                    app.AtlasListbox.Value = {};
                end

                % title of atlab listbox
                if numel( labels) == 1
                    s = sprintf( 'Atlas of %s', labels{1});
                else
                    s = 'Atlas of selected labels';
                end

                app.AtlasofselectedchannelPanel.Title = s;

            end % if only one electrode is selected

            % update yoke figures
            UpdateYoke( app);
        end

        % Value changed function: SurfaceOpacitySlider
        function SurfaceOpacitySliderValueChanged(app, event)
            value = app.SurfaceOpacitySlider.Value;
            app.OpacityEdit.Value = value / 100;
            ChangeSurfOpacity(app);
        end

        % Value changed function: OpacityEdit
        function OpacityEditValueChanged(app, event)
            value = app.OpacityEdit.Value;
            if value >= 0 && value <= 1
                app.SurfaceOpacitySlider.Value = 100 * value;
                ChangeSurfOpacity(app);
            else
                app.OpacityEdit.Value = app.SurfaceOpacitySlider.Value / 100;
            end
        end

        % Close request function: ENORMUIFigure
        function ENORMUIFigureCloseRequest(app, event)
            to_quit = Eviewer_SaveData( app);
            if strcmpi( to_quit, 'no')
                return;
            end

            Eviewer_CloseFigs( app);
            delete(app);
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            % Update MNI coordinates of projected electrodes
            switch lower( app.userdata.reg.img_space)
                case 'ct'
                    set( app.StatusTextArea, 'Text', 'Register projected coordinates to MNI ...');
                    drawnow;
                    app.userdata.proj.coord2std = Img2ImgCoords( app, app.userdata.proj.coord,...
                        app.userdata.reg.ct2std,...
                        app.userdata.reg.ctimg,...
                        app.userdata.reg.stdimg);

                case 't1'
                    set( app.StatusTextArea, 'Text', 'Register projected coordinates to MNI ...');
                    drawnow;
                    app.userdata.proj.coord2std = Img2ImgCoords( app, app.userdata.proj.coord2t1,...
                        app.userdata.reg.t12std,...
                        app.userdata.reg.t1img,...
                        app.userdata.reg.stdimg);

                case 'std'
                    set( app.StatusTextArea, 'Text', 'Register projected coordinates to T1 ...');
                    drawnow;
                    app.userdata.proj.coord2t1 = Img2ImgCoords( app, app.userdata.proj.coord,...
                        app.userdata.reg.std2t1,...
                        app.userdata.reg.stdimg,...
                        app.userdata.reg.t1img);
            end

            Eviewer_AtlasQuery(app);            
        end

        % Button pushed function: ShowAtlasButton
        function ShowAtlasButtonPushed(app, event)
            if ~isempty( app.userdata.atlas.query.fsaseg)
                labels = app.userdata.atlas.query.fsaseg( :, 1);
                labels = cellfun( @(x) x{1}, labels, 'UniformOutput', false);
                labels = unique( labels);
                app.AtlasGroupListbox.Items = labels;
            end
        end

        % Value changed function: GridSizeEditField
        function GridSizeEditFieldValueChanged2(app, event)
            val = str2num( app.GridSizeEditField.Value);
            if length( val) == 2 && all( abs(val - round( val))) < 2*eps
                app.userdata.proj.gridsize = val;
            else
                app.GridSizeEditField.Value = '';
            end
        end

        % Value changed function: AtlasGroupListbox
        function AtlasGroupListboxValueChanged(app, event)
            value = app.AtlasGroupListbox.Value;
            if isfield( app.userdata, 'label_grp')
                labels = app.userdata.atlas.query.fsaseg( :, 1);
                labels = cellfun( @(x) x{1}, labels, 'UniformOutput', false);

                loc = ismember( labels( :, 1), value);
                app.ElecLabelListbox.Value = app.userdata.elec.label( loc);
                ElecLabelListboxValueChanged( app);
            end
        end

        % Button pushed function: ApplyProjButton
        function ApplyProjButtonPushed(app, event)
            [status] = Eviewer_ElecProj( app, event);
            if status == 0
                %app.StatusTextArea.Text = 'Updating atlas query results.';
                %app.StatusTextArea.FontColor = 'r';
                %Eviewer_AtlasQuery( app);
                %app.StatusTextArea.Text = 'Atlas query results were updated.';
                %app.StatusTextArea.FontColor = 'b';

                app.userdata.data_saved = 'no';
                app.StatusTextArea.Text = 'Please remember to update atlas query results.';
                app.StatusTextArea.FontColor = 'r';
                drawnow;
                %warndlg( 'Remember to update atlas query results.', 'Coordinates corrected');
            end
        end

        % Button pushed function: PreviewProjButton
        function PreviewProjButtonPushed(app, event)
            Eviewer_ElecProj( app, event);
        end

        % Button pushed function: ClearProjButton
        function ClearProjButtonPushed(app, event)
            answer = questdlg( 'Are you sure to clear projection?',...
                'Warning', 'Yes', 'No', 'Cancel', 'No');
            if strcmpi( answer, 'yes')
                app.userdata.proj = [];
                app.userdata.proj.coord = [];
                app.userdata.proj.coord2t1 = [];
                app.userdata.proj.coord2std = [];
                app.userdata.proj.coord2surf = [];
                app.userdata.proj.label = {};
                app.userdata.proj.status = [];
                app.userdata.proj.hist.label = {};
                app.userdata.proj.hist.gridsize = [];
                app.userdata.proj.hist.method = {};

                app.StatusTextArea.Text = 'Please remember to update atlas query results.';
                app.StatusTextArea.FontColor = 'r';
                % warndlg( 'Remember to update Atlas Query results.');
            end
        end

        % Menu selected function: SaveMenu
        function SaveResultButtonPushed(app, event)
            if isempty( app.userdata.elec)
                app.StatusTextArea.Text = 'Nothing to save.';
                app.StatusTextArea.FontColor = 'r';
                return;
            end

            elec = app.userdata.elec;
            % keep all transformation information
            if ~isempty( app.userdata.reg)
                elec.reg = app.userdata.reg;
            end

            if ~isempty( app.userdata.proj)
                elec.proj = app.userdata.proj;
            end

            elec.atlas = app.userdata.atlas.query;
            elec.srcdata = app.userdata.srcdata;
            uisave( 'elec', fullfile( app.userdata.srcdir, 'eplace_reg'));
            app.userdata.data_saved = 'yes';
        end

        % Value changed function: FSsurfOpacitySlider
        function FSsurfOpacitySliderValueChanged(app, event)
            set( app.StatusTextArea, 'Text', 'Updating annotations ...', 'FontColor', 'b');
            drawnow;

            value = app.FSsurfOpacitySlider.Value / 100;
            for annot = {'fsannot', 'mniannot'}
                a = annot{1};
                tmp = app.userdata.srcdata.(a).lh;
                if isfield( tmp, 'colortable') && isfield( tmp, 'FaceVertexAlphaData')
                    app.fighandle.(a).lh.FaceVertexAlphaData = zeros( length( tmp.lh.label), 1);

                    if app.FSsurfLhCheckBox.Value == 1
                        % struct name index
                        loc = ismember( tmp.colortable.struct_names, app.FSParcellationListBox.Value);
                        % vertex index
                        vert_ind = ismember( tmp.lh.label, tmp.lh.colortable.table( loc, 5));
                        app.fighandle.srcdata.(a).lh.FaceAlpha = 'interp';
                        app.fighandle.srcdata.(a).lh.FaceVertexAlphaData( vert_ind) = value * ones( sum( vert_ind), 1);
                    end
                end

                tmp = app.userdata.srcdata.(a).rh;
                if isfield( tmp, 'colortable') && isfield( tmp, 'FaceVertexAlphaData')
                    app.fighandle.(a).rh.FaceVertexAlphaData = zeros( length( tmp.rh.label), 1);

                    if app.FSsurfRhCheckBox.Value == 1
                        % struct name index
                        loc = ismember( tmp.rh.colortable.struct_names, app.FSParcellationListBox.Value);
                        % vertex index
                        vert_ind = ismember( tmp.rh.label, tmp.rh.colortable.table( loc, 5));
                        app.fighandle.srcdata.(a).rh.FaceAlpha = 'interp';
                        app.fighandle.srcdata.(a).rh.FaceVertexAlphaData( vert_ind) = value * ones( sum( vert_ind), 1);
                    end
                end
            end

            set( app.StatusTextArea, 'Text', 'Ready.', 'FontColor', 'b');
            drawnow;
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            help_doc = {'Step 1. Load data.',...
                'Step 2. Setup transformation matrix.'};

            app.StatusTextArea.Text = help_doc;
            app.StatusTextArea.FontColor = 'k';
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            to_quit = Eviewer_SaveData( app);
            if strcmpi( to_quit, 'no')
                return;
            end

            Eviewer_CloseFigs( app);
            delete( app);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            % close all dependent figures
            Eviewer_CloseFigs( app);

            % initlize userdata structure
            Eviewer_InitializeUserdata( app);

            % initlize userdata structure objects
            Eviewer_InitializeTransform( app);

            app.StatusTextArea.Text = '';
            app.FSParcellationListBox.Items = {};

            ChangeVisibility( app, 'off');
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)

        end

        % Menu selected function: CurrentdatasetMenu
        function CurrentdatasetMenuSelected(app, event)
            if ~isempty( app.userdata.filename)
                app.StatusTextArea.Text = ['Dataset: ', app.userdata.filename];
            else
                app.StatusTextArea.Text = 'No dataset loaded';
            end
            app.StatusTextArea.FontColor = 'k';
        end

        % Menu selected function: enormMenu
        function enormMenuSelected(app, event)
            app.StatusTextArea.Text = 'This is a GUI for visulizing eplace results.';
            app.StatusTextArea.FontColor = 'k';
        end

        % Selection changed function: ElectrodestoshowButtonGroup
        function ElectrodestoshowButtonGroupSelectionChanged(app, event)
            selectedButton = app.ElectrodestoshowButtonGroup.SelectedObject;

            if strcmpi( selectedButton.Text, 'Show all electrodes')
                app.userdata.show_all_elec = 'yes';
                app.userdata.show_cur_elec = 'yes';
            elseif strcmpi( selectedButton.Text, 'Show selected electrodes only')
                app.userdata.show_all_elec = 'no';
                app.userdata.show_cur_elec = 'yes';
            else
                app.userdata.show_all_elec = 'yes';
                app.userdata.show_cur_elec = 'no';
            end
            UpdateYoke( app);
        end

        % Value changed function: ShowprojectedelectrodesCheckBox
        function ShowprojectedelectrodesCheckBoxValueChanged(app, event)
            value = app.ShowprojectedelectrodesCheckBox.Value;
            if value == 1
                app.userdata.show_proj = 'yes';
            else
                app.userdata.show_proj = 'no';
            end
            UpdateYoke( app);
        end

        % Button pushed function: ChangeelectrodescolorButton
        function ChangeelectrodescolorButtonPushed(app, event)
            c = uisetcolor;
            if length( c) == 1
                % do nothing
            else
                app.userdata.elec_color = c;
                UpdateYoke( app);
            end
        end

        % Button pushed function: ChangeselectedelectrodescolorButton
        function ChangeselectedelectrodescolorButtonPushed(app, event)
            c = uisetcolor;
            if length( c) == 1
                % do nothing
            else
                app.userdata.cur_elec_color = c;
                UpdateYoke( app);
            end
        end

        % Button pushed function: ChangeprojectedelectrodescolorButton
        function ChangeprojectedelectrodescolorButtonPushed(app, event)
            c = uisetcolor;
            if length( c) == 1
                % do nothing
            else
                app.userdata.proj_elec_color = c;
                UpdateYoke( app);
            end
        end

        % Value changed function: ElectrodessizeEditField
        function ElectrodessizeEditFieldValueChanged(app, event)
            value = app.ElectrodessizeEditField.Value;

            app.userdata.elec_markersize = value;
            UpdateYoke( app);
        end

        % Value changed function: SelectedelectrodesizeEditField
        function SelectedelectrodesizeEditFieldValueChanged(app, event)
            value = app.SelectedelectrodesizeEditField.Value;

            app.userdata.cur_elec_markersize = value;
            UpdateYoke( app);
        end

        % Value changed function: ProjectedelectrodesizeEditField
        function ProjectedelectrodesizeEditFieldValueChanged(app, event)
            value = app.ProjectedelectrodesizeEditField.Value;

            app.userdata.proj_elec_markersize = value;
            UpdateYoke( app);
        end

        % Value changed function: T1IntensityLo
        function T1IntensityLoValueChanged(app, event)
            app.userdata.srcdata.t1_clim(1) = app.T1IntensityLo.Value;
            UpdateT1Clim( app);
        end

        % Value changed function: T1IntensityHi
        function T1IntensityHiValueChanged(app, event)
            app.userdata.srcdata.t1_clim(2) = app.T1IntensityHi.Value;
            UpdateT1Clim( app);
        end

        % Value changed function: MNIintensityLo
        function MNIintensityLoValueChanged(app, event)
            app.userdata.srcdata.std_clim(1) = app.MNIintensityLo.Value;
            UpdateStdClim( app);
        end

        % Value changed function: MNIIntensityHi
        function MNIIntensityHiValueChanged(app, event)
            app.userdata.srcdata.std_clim(2) = app.MNIIntensityHi.Value;
            UpdateStdClim( app);
        end

        % Button pushed function: DefaultT1Clim
        function DefaultT1ClimButtonPushed(app, event)
            if ~isempty( app.userdata.default_t1_clim)
                app.T1IntensityLo.Value = app.userdata.default_t1_clim(1);
                app.T1IntensityHi.Value = app.userdata.default_t1_clim(2);
                app.userdata.srcdata.t1_clim = app.userdata.default_t1_clim;
                UpdateT1Clim( app);
            end
        end

        % Button pushed function: DefaultStdClim
        function DefaultStdClimButtonPushed(app, event)
            if ~isempty( app.userdata.default_std_clim)
                app.MNIintensityLo.Value = app.userdata.default_std_clim(1);
                app.MNIIntensityHi.Value = app.userdata.default_std_clim(2);
                app.userdata.srcdata.std_clim = app.userdata.default_std_clim;
                UpdateStdClim( app);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            InPlot( app);
        end

        % Value changed function: FSParcellationListBox
        function FSParcellationListBoxValueChanged(app, event)
            FSsurfOpacitySliderValueChanged( app, []);
        end

        % Value changed function: FSsurfLhCheckBox
        function FSsurfLhCheckBoxValueChanged(app, event)
            FSsurfOpacitySliderValueChanged( app, []);
        end

        % Value changed function: FSsurfRhCheckBox
        function FSsurfRhCheckBoxValueChanged(app, event)
            FSsurfOpacitySliderValueChanged( app, []);
        end

        % Value changed function: SurfaceTextArea
        function SurfaceTextAreaValueChanged(app, event)
            value = app.SurfaceTextArea.Value;

        end

        % Button pushed function: FigurebBkColorButton
        function FigurebBkColorButtonPushed(app, event)
            c = uisetcolor( app.userdata.bkcolor);
            if length( c) == 1
                % do nothing
            else
                app.userdata.bkcolor = c;
                UpdateYoke( app);
            end
        end

        % Value changed function: ElectrodeSpaceDropDown
        function ElectrodeSpaceDropDownValueChanged(app, event)
            value = app.ElectrodeSpaceDropDown.Value;
            
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            % Load all files in.
            if isempty( app.userdata.elec)
                return;
            end

            % Read T1 image
            app.userdata.srcdata.t1 = [];
            app.userdata.srcdata.t1_clim = [];
            try
                set( app.StatusTextArea, 'Text', 'Reading T1 image ...', 'FontColor', 'b');

                app.userdata.srcdata.t1 = MRIread( app.userdata.reg.t1img);
                app.userdata.srcdata.t1.vol = permute( app.userdata.srcdata.t1.vol, [2, 1, 3]);
                app.userdata.srcdata.t1_clim = prctile( app.userdata.srcdata.t1.vol(:), [2, 95]);
                app.userdata.default_t1_clim = app.userdata.srcdata.t1_clim;

                app.T1IntensityLo.Value = app.userdata.srcdata.t1_clim(1);
                app.T1IntensityHi.Value = app.userdata.srcdata.t1_clim(2);

            catch
                app.SelectButton_T1.FontColor = 'r';
            end

            drawnow;

            % read MNI512 standard brain
            app.userdata.srcdata.std = [];
            app.userdata.srcdata.std_clim = [];
            try
                set( app.StatusTextArea, 'Text', 'Reading MNI image ...', 'FontColor', 'b');
                app.userdata.srcdata.std = MRIread( app.userdata.reg.stdimg);
                app.userdata.srcdata.std.vol = permute( app.userdata.srcdata.std.vol, [2, 1, 3]);

                if ~isempty( strfind( app.userdata.reg.stdimg, 'MNI152'))
                    app.userdata.srcdata.std_clim = [3, 8] * 1000;
                else
                    app.userdata.srcdata.std_clim = prctile( app.userdata.srcdata.std.vol(:), [2, 95]);
                end

                app.userdata.default_std_clim = app.userdata.srcdata.std_clim;
                app.MNIintensityLo.Value = app.userdata.srcdata.std_clim(1);
                app.MNIIntensityHi.Value = app.userdata.srcdata.std_clim(2);

            catch
                app.SelectButton_MNI.FontColor = 'r';
            end

            drawnow;

            % Freesurfer orig.nii.gz (orig.mgz)
            app.userdata.srcdata.surfmri = [];
            try
                set( app.StatusTextArea, 'Text', 'Reading Surface volume ...', 'FontColor', 'b');
                app.userdata.srcdata.surfmri = MRIread( app.userdata.reg.surfvol);
                app.userdata.srcdata.surfmri.vol = permute( app.userdata.srcdata.surfmri.vol, [2, 1, 3]);

            catch
                app.SelectButton_Orig.FontColor = 'r';
            end

            drawnow;

            % individual annot
            app.userdata.srcdata.fsannot.lh = [];
            app.userdata.srcdata.fsannot.rh = [];
            if ~isempty( app.userdata.srcdata.fsannot.filename.lh)
                set( app.StatusTextArea, 'FontColor', 'b', ...
                    'Text', 'Reading individual Freesurfer annoat ...');
                drawnow;

                [app.userdata.srcdata.fsannot.lh.vert, app.userdata.srcdata.fsannot.lh.label, colortable] = ...
                    read_annotation( app.userdata.srcdata.fsannot.filename.lh);

                app.userdata.srcdata.fsannot.lh.colortable = colortable;
                app.userdata.srcdata.fsannot.lh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);
                app.userdata.srcdata.fsannot.lh.colortable.table = app.userdata.srcdata.fsannot.lh.colortable.table;
            end

            if ~isempty( app.userdata.srcdata.fsannot.filename.rh)
                set( app.StatusTextArea, 'FontColor', 'b', ...
                    'Text', 'Reading individual Freesurfer annoat ...');
                drawnow;

                [app.userdata.srcdata.fsannot.rh.vert, app.userdata.srcdata.fsannot.rh.label, colortable] = ...
                    read_annotation( app.userdata.srcdata.fsannot.filename.rh);

                app.userdata.srcdata.fsannot.rh.colortable = colortable;
                app.userdata.srcdata.fsannot.rh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);
                app.userdata.srcdata.fsannot.rh.colortable.table = app.userdata.srcdata.fsannot.rh.colortable.table;
                app.FSParcellationListBox.Items = colortable.struct_names;
            end


            % Freesurfer fsaverageMNI parcellation
            app.userdata.srcdata.mniannot.lh = [];
            app.userdata.srcdata.mniannot.rh = [];
            if isfolder( app.userdata.reg.mnisurf)
                set( app.StatusTextArea, 'FontColor', 'b', ...
                    'Text', 'Reading MNI brain surface ...');
                drawnow;

                nam = [app.userdata.srcdata.mniannot.parc, '.annot'];
                [app.userdata.srcdata.mniannot.lh.vert, ...
                    app.userdata.srcdata.mniannot.lh.label, colortable] = ...
                    read_annotation( fullfile( app.userdata.reg.mnisurf, 'label', ['lh.', nam]));

                app.userdata.srcdata.mniannot.lh.colortable = colortable;
                app.userdata.srcdata.mniannot.lh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);

                app.userdata.srcdata.mniannot.lh.colortable.table = app.userdata.srcdata.mniannot.lh.colortable.table;

                [app.userdata.srcdata.mniannot.rh.vert, ...
                    app.userdata.srcdata.mniannot.rh.label, colortable] = ...
                    read_annotation( fullfile( app.userdata.reg.mnisurf, 'label', ['rh.', nam]));


                app.userdata.srcdata.mniannot.rh.colortable = colortable;
                app.userdata.srcdata.mniannot.rh.colortable.table( :, 6) = zeros( size( colortable.table, 1), 1);

                % why the struct_names of Freesurfer is different from individual brain?
                app.FSParcellationListBox.Items = colortable.struct_names;
            end

            % Freesurfer surface
            app.userdata.srcdata.fssurf = [];
            try
                for k = 1 : length( app.userdata.reg.surffile)
                    [v, f] = read_surf( app.userdata.reg.surffile{ k});
                    f = f + 1;

                    % plot parcellation and return
                    [~, fname] = fileparts( app.userdata.reg.surffile{ k});

                    app.userdata.srcdata.fssurf.( fname).vert =  v;
                    app.userdata.srcdata.fssurf.( fname).tri =  f;

                    [c, ~, ic] = unique( app.userdata.srcdata.fsannot.( fname).label);
                    [~, locb] = ismember(c, app.userdata.srcdata.fsannot.( fname).colortable.table( :, 5));
                    label_color = app.userdata.srcdata.fsannot.( fname).colortable.table( locb, 1:3);
                    app.userdata.srcdata.fssurf.( fname).label_color = label_color( ic, :);

                    label_alpha = app.userdata.srcdata.fsannot.( fname).colortable.table( :, 6);
                    app.userdata.srcdata.fssurf.( fname).label_alpha = label_alpha( ic);
                end

            catch
                % failed to read surface
                app.userdata.srcdata.fssurf = [];
            end

            % MNI surface
            app.userdata.srcdata.mnisurf = [];
            if isfile( app.userdata.reg.mnisurf)
                % created using CreateSurf
                s = load( app.userdata.reg.mnisurf);
                fn = fieldnames( s);
                app.userdata.srcdata.mnisurf.vert =  s.(fn{1}).vert;
                app.userdata.srcdata.mnisurf.tri =  s.(fn{1}).tri;

            elseif isfolder( app.userdata.reg.mnisurf)
                % freesurfer MNI surface
                [app.userdata.srcdata.mnisurf.lh.vert, app.userdata.srcdata.mnisurf.lh.tri] = ...
                    read_surf( fullfile( app.userdata.reg.mnisurf, 'surf', 'lh.pial' ));

                app.userdata.srcdata.mnisurf.lh.tri = app.userdata.srcdata.mnisurf.lh.tri + 1;

                [app.userdata.srcdata.mnisurf.rh.vert, app.userdata.srcdata.mnisurf.rh.tri] = ...
                    read_surf( fullfile( app.userdata.reg.mnisurf, 'surf', 'rh.pial' ));

                app.userdata.srcdata.mnisurf.rh.tri = app.userdata.srcdata.mnisurf.rh.tri + 1;

            else
                % do nothing
            end % if freesurfer or createsurf

            % InPlot( app);
        end

        % Button pushed function: ApplyTransformButton
        function ApplyTransformButtonPushed(app, event)
            % Apply the transformation matrix from electrode space to all
            % other spaces
            % This is different from applying the transformation matrix to
            % the projected electrodes.
            
            set( app.StatusTextArea, 'FontColor', 'b', ...
                'Text', 'Applying transformation matrix ...');
            drawnow;

            elec_space = app.ElectrodeSpaceDropDown.Value;
            surf_space = app.SurfaceSpaceDropDown.Value;
            elec_pos = app.userdata.elec.elecpos.coord;
            if strcmpi( elec_space, 'unknown')
                app.userdata.reg.img_space = 'unknown';
                app.userdata.reg.coord2surf = elec_pos;

                if strcmpi( surf_space, 'unknown')
                    app.userdata.reg.surf_space = 'unknown';
                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                else
                    app.userdata.reg.surf_space = 'std';
                end

            elseif strcmpi( elec_space, 'ct')
                app.userdata.reg.img_space = 'ct';
                if strcmpi( surf_space, 'Unknown')
                    app.userdata.reg.surf_space = 'unknown';
                    app.userdata.reg.coord2surf = elec_pos;

                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                    coord_in_t1 = Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.ct2t1,...
                        app.userdata.reg.ctimg,...
                        app.userdata.reg.t1img);
                    
                    app.userdata.reg.coord2surf = Eviewer_Coord2Surf( app, coord_in_t1);

                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                    app.userdata.reg.coord2surf = Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.ct2t1,...
                        app.userdata.reg.ctimg,...
                        app.userdata.reg.t1img);
                else
                    % CT -> MNI space
                    app.userdata.reg.surf_space = 'std';
                    app.userdata.reg.coord2surf = Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.ct2std,...
                        app.userdata.reg.ctimg,...
                        app.userdata.reg.stdimg);
                end

                % coordinates ct -> t1
                app.userdata.reg.coord2t1 = Img2ImgCoords( app, elec_pos,...
                    app.userdata.reg.ct2t1,...
                    app.userdata.reg.ctimg,...
                    app.userdata.reg.t1img);

                app.userdata.reg.coord2std = Img2ImgCoords( app, elec_pos,...
                    app.userdata.reg.ct2std,...
                    app.userdata.reg.ctimg,...
                    app.userdata.reg.stdimg);

            elseif strcmpi( elec_space, 't1')
                app.userdata.reg.img_space = 't1';
                if strcmpi( surf_space, 'Uknown')
                    app.userdata.reg.surf_space = 'unknown';
                    app.userdata.reg.coord2surf = elec_pos;

                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                    app.userdata.reg.coord2surf = Eviewer_Coord2Surf( app, elec_pos);

                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                    app.userdata.reg.coord2surf = elec_pos;

                else
                    app.userdata.reg.surf_space = 'std';
                    app.userdata.reg.coord2surf = ...
                        Img2ImgCoords( app, elec_pos,...
                        app.userdata.reg.t12std,...
                        app.userdata.reg.t1img,...
                        app.userdata.reg.stdimg);
                end

                % coordinates t1 -> t1
                app.userdata.reg.coord2t1 = elec_pos;
                app.userdata.reg.coord2std = Img2ImgCoords( app, elec_pos,...
                    app.userdata.reg.t12std,...
                    app.userdata.reg.t1img,...
                    app.userdata.reg.stdimg);

            else
                app.userdata.reg.img_space = 'std';

                reg_std2t1 = Eviewer_InverseXfm( app, app.userdata.reg.t12std);
                if strcmpi( surf_space, 'Uknown')
                    app.userdata.reg.surf_space = 'unknown';

                elseif strcmpi( surf_space, 'Freesurfer')
                    app.userdata.reg.surf_space = 'fs';
                    coord_in_t1 = Img2ImgCoords( app, elec_pos,...
                        reg_std2t1, app.userdata.reg.stdimg, app.userdata.reg.t1img);
                    app.userdata.reg.coord2surf = Eviewer_Coord2Surf( app, coord_in_t1);

                elseif strcmpi( surf_space, 'T1')
                    app.userdata.reg.surf_space = 't1';
                    app.userdata.reg.coord2surf = Img2ImgCoords( app, elec_pos,...
                        reg_std2t1, ...
                        app.userdata.reg.stdimg, ...
                        app.userdata.reg.t1img);

                else
                    app.userdata.reg.surf_space = 'std';
                    app.userdata.reg.coord2surf = elec_pos;
                end

                % coordinates std -> t1
                app.userdata.reg.coord2t1 = Img2ImgCoords( app, elec_pos, ...
                    reg_std2t1, ...
                    app.userdata.reg.stdimg, ...
                    app.userdata.reg.t1img);

                app.userdata.reg.coord2std = elec_pos;
            end % transformation of coordinates between different spaces

            % plot results
            InPlot( app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ENORMUIFigure = uifigure('Visible', 'off');
            app.ENORMUIFigure.Name = 'ENORM';
            app.ENORMUIFigure.CloseRequestFcn = createCallbackFcn(app, @ENORMUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ENORMUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadMenu.Tooltip = {'Load eplace data structure.'};
            app.LoadMenu.Accelerator = 'o';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Tooltip = {'Close current dataset.'};
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Tooltip = {'Quit this program.'};
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Accelerator = 'q';
            app.QuitMenu.Text = 'Quit';
            app.AboutMenu = uimenu(app.ENORMUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.CurrentdatasetMenu = uimenu(app.AboutMenu);
            app.CurrentdatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentdatasetMenuSelected, true);
            app.CurrentdatasetMenu.Tooltip = {'About the current dataset.'};
            app.CurrentdatasetMenu.Text = 'Current dataset';
            app.enormMenu = uimenu(app.AboutMenu);
            app.enormMenu.MenuSelectedFcn = createCallbackFcn(app, @enormMenuSelected, true);
            app.enormMenu.Tooltip = {'About eviewer.'};
            app.enormMenu.Text = 'enorm';
            app.HelpMenu = uimenu(app.ENORMUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Help.'};
            app.HelpMenu.Text = 'Help';
            app.StatusTextArea = uilabel(app.ENORMUIFigure);
            app.StatusTextArea.Text = 'Status';
            app.TabGroup = uitabgroup(app.ENORMUIFigure);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.GridLayout = uigridlayout(app.RegistrationTab);
            app.SelectButton_CT = uibutton(app.GridLayout, 'push');
            app.SelectButton_CT.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_CTPushed, true);
            app.SelectButton_CT.Text = 'Select';
            app.SelectButton_T1 = uibutton(app.GridLayout, 'push');
            app.SelectButton_T1.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_T1Pushed, true);
            app.SelectButton_T1.Text = 'Select';
            app.SelectButton_MNI = uibutton(app.GridLayout, 'push');
            app.SelectButton_MNI.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_MNIPushed, true);
            app.SelectButton_MNI.Text = 'Select';
            app.SelectButton_CT2T1 = uibutton(app.GridLayout, 'push');
            app.SelectButton_CT2T1.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_CT2T1Pushed, true);
            app.SelectButton_CT2T1.Text = 'Select';
            app.SelectButton_T12MNI = uibutton(app.GridLayout, 'push');
            app.SelectButton_T12MNI.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_T12MNIPushed, true);
            app.SelectButton_T12MNI.Text = 'Select';
            app.SelectButton_CT2MNI = uibutton(app.GridLayout, 'push');
            app.SelectButton_CT2MNI.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_CT2MNIPushed, true);
            app.SelectButton_CT2MNI.Text = 'Select';
            app.SelectButton_Raw2Orig = uibutton(app.GridLayout, 'push');
            app.SelectButton_Raw2Orig.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_Raw2OrigPushed, true);
            app.SelectButton_Raw2Orig.Text = 'Select';
            app.SelectButton_Orig = uibutton(app.GridLayout, 'push');
            app.SelectButton_Orig.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_OrigPushed, true);
            app.SelectButton_Orig.Text = 'Select';
            app.SelectButton_Surface = uibutton(app.GridLayout, 'push');
            app.SelectButton_Surface.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_SurfacePushed, true);
            app.SelectButton_Surface.Text = 'Select';
            app.SelectButton_MNISurf = uibutton(app.GridLayout, 'push');
            app.SelectButton_MNISurf.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_MNISurfPushed, true);
            app.SelectButton_MNISurf.Text = 'Select';
            app.MNIImageEditFieldLabel = uilabel(app.GridLayout);
            app.MNIImageEditFieldLabel.Text = 'MNI Image';
            app.MNIImageEditField = uieditfield(app.GridLayout, 'text');
            app.MNIImageEditField.ValueChangedFcn = createCallbackFcn(app, @MNIImageEditFieldValueChanged, true);
            app.MNIImageEditField.Tooltip = {'Full-path-to-Standard MNI brain (MNI152_T1_1mm_brain) that was used in registration.'};
            app.CTImageEditFieldLabel = uilabel(app.GridLayout);
            app.CTImageEditFieldLabel.Text = 'CT Image';
            app.CTImageEditField = uieditfield(app.GridLayout, 'text');
            app.CTImageEditField.ValueChangedFcn = createCallbackFcn(app, @CTImageEditFieldValueChanged, true);
            app.CTImageEditField.Tooltip = {'Full-path-to-post-operative CT image.'};
            app.T1ImageEditFieldLabel = uilabel(app.GridLayout);
            app.T1ImageEditFieldLabel.Text = 'T1 Image';
            app.T1ImageEditField = uieditfield(app.GridLayout, 'text');
            app.T1ImageEditField.ValueChangedFcn = createCallbackFcn(app, @T1ImageEditFieldValueChanged, true);
            app.T1ImageEditField.Tooltip = {'Individual anatomical image (T1.nii.gz)'};
            app.CTT1EditFieldLabel = uilabel(app.GridLayout);
            app.CTT1EditFieldLabel.Text = 'CT -> T1';
            app.CTT1EditField = uieditfield(app.GridLayout, 'text');
            app.CTT1EditField.ValueChangedFcn = createCallbackFcn(app, @CTT1EditFieldValueChanged, true);
            app.CTT1EditField.Tooltip = {'FSL''s flirt transformation matrix (CT->T1)'};
            app.T1MNIEditFieldLabel = uilabel(app.GridLayout);
            app.T1MNIEditFieldLabel.Text = 'T1 -> MNI';
            app.T1MNIEditField = uieditfield(app.GridLayout, 'text');
            app.T1MNIEditField.ValueChangedFcn = createCallbackFcn(app, @T1MNIEditFieldValueChanged, true);
            app.T1MNIEditField.Tooltip = {'FSL''s flirt transformation matrix (T1->MNI)'};
            app.CTMNIEditFieldLabel = uilabel(app.GridLayout);
            app.CTMNIEditFieldLabel.Text = 'CT -> MNI';
            app.CTMNIEditField = uieditfield(app.GridLayout, 'text');
            app.CTMNIEditField.ValueChangedFcn = createCallbackFcn(app, @CTMNIEditFieldValueChanged, true);
            app.CTMNIEditField.Tooltip = {'FSL''s flirt transformation matrix (CT->MNI)'};
            app.FSRawOrigLabel = uilabel(app.GridLayout);
            app.FSRawOrigLabel.Text = 'FS Raw->Orig';
            app.FSRawOrigEditField = uieditfield(app.GridLayout, 'text');
            app.FSRawOrigEditField.ValueChangedFcn = createCallbackFcn(app, @FSRawOrigEditFieldValueChanged, true);
            app.FSRawOrigEditField.Tooltip = {'FSL''s flirt transformation matrix (Freesurfer raw.nii.gz -> orig.nii.gz).'};
            app.FSOrigEditFieldLabel = uilabel(app.GridLayout);
            app.FSOrigEditFieldLabel.Text = 'FS Orig';
            app.FSOrigEditField = uieditfield(app.GridLayout, 'text');
            app.FSOrigEditField.ValueChangedFcn = createCallbackFcn(app, @FSOrigEditFieldValueChanged, true);
            app.FSOrigEditField.Tooltip = {'Freesurfer orig.nii.gz (mri_convert orig.mgz orig.nii.gz)'};
            app.MNISurfaceEditFieldLabel = uilabel(app.GridLayout);
            app.MNISurfaceEditFieldLabel.Text = 'MNI Surface';
            app.MNISurfaceEditField = uieditfield(app.GridLayout, 'text');
            app.MNISurfaceEditField.ValueChangedFcn = createCallbackFcn(app, @MNISurfaceEditFieldValueChanged, true);
            app.MNISurfaceEditField.Tooltip = {'MNI brain surface. A Matlab data structure return by CreateSurf.m'};
            app.SurfaceTextArea = uitextarea(app.GridLayout);
            app.SurfaceTextArea.ValueChangedFcn = createCallbackFcn(app, @SurfaceTextAreaValueChanged, true);
            app.SurfaceTextArea.Editable = 'off';
            app.SurfaceTextArea.Tooltip = {'Freesurfer brain surface files (lh.pial and/or rh.pial)'};
            app.SurfaceLabel = uilabel(app.GridLayout);
            app.SurfaceLabel.Text = 'Surface';
            app.ElectrodeSpaceDropDownLabel = uilabel(app.GridLayout);
            app.ElectrodeSpaceDropDownLabel.Text = 'Electrode Space';
            app.ElectrodeSpaceDropDown = uidropdown(app.GridLayout);
            app.ElectrodeSpaceDropDown.Items = {'Unknown', 'CT', 'T1', 'MNI'};
            app.ElectrodeSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ElectrodeSpaceDropDownValueChanged, true);
            app.ElectrodeSpaceDropDown.Value = 'Unknown';
            app.SurfaceSpaceDropDownLabel = uilabel(app.GridLayout);
            app.SurfaceSpaceDropDownLabel.Text = 'Surface Space';
            app.SurfaceSpaceDropDown = uidropdown(app.GridLayout);
            app.SurfaceSpaceDropDown.Items = {'Unknown', 'T1', 'Freesurfer', 'MNI'};
            app.SurfaceSpaceDropDown.Value = 'Unknown';
            app.AutoFillButton_Transf = uibutton(app.GridLayout, 'push');
            app.AutoFillButton_Transf.ButtonPushedFcn = createCallbackFcn(app, @AutoFillButton_TransfPushed, true);
            app.AutoFillButton_Transf.Text = 'Auto Fill';
            app.ReadImagesOnlyCheckBox = uicheckbox(app.GridLayout);
            app.ReadImagesOnlyCheckBox.Tooltip = {'Check this option to avoid coordinates transformation.'};
            app.ReadImagesOnlyCheckBox.Text = 'Read Files Only';
            app.ApplyButton_ApplyTransf = uibutton(app.GridLayout, 'push');
            app.ApplyButton_ApplyTransf.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_ApplyTransfPushed, true);
            app.ApplyButton_ApplyTransf.Tooltip = {'Apply all transformation matrix to original coordinates.'};
            app.ApplyButton_ApplyTransf.Text = 'Apply';
            app.ReadFileButton = uibutton(app.GridLayout, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read files';
            app.ApplyTransformButton = uibutton(app.GridLayout, 'push');
            app.ApplyTransformButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformButtonPushed, true);
            app.ApplyTransformButton.Text = 'Apply transformation';
            app.ProjectionTab = uitab(app.TabGroup);
            app.ProjectionTab.Title = 'Projection';
            app.SurfaceOptionsPanel = uipanel(app.ProjectionTab);
            app.SurfaceOptionsPanel.Title = 'Surface Options';
            app.FreesurferParcellationListBoxLabel = uilabel(app.SurfaceOptionsPanel);
            app.FreesurferParcellationListBoxLabel.Text = 'Freesurfer Parcellation';
            app.FSParcellationListBox = uilistbox(app.SurfaceOptionsPanel);
            app.FSParcellationListBox.Items = {};
            app.FSParcellationListBox.Multiselect = 'on';
            app.FSParcellationListBox.ValueChangedFcn = createCallbackFcn(app, @FSParcellationListBoxValueChanged, true);
            app.FSParcellationListBox.Value = {};
            app.FSsurfOpacitySlider = uislider(app.SurfaceOptionsPanel);
            app.FSsurfOpacitySlider.MajorTicks = [];
            app.FSsurfOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @FSsurfOpacitySliderValueChanged, true);
            app.FSsurfOpacitySlider.MinorTicks = [];
            app.FSsurfLhCheckBox = uicheckbox(app.SurfaceOptionsPanel);
            app.FSsurfLhCheckBox.ValueChangedFcn = createCallbackFcn(app, @FSsurfLhCheckBoxValueChanged, true);
            app.FSsurfLhCheckBox.Text = 'lh';
            app.FSsurfLhCheckBox.Value = true;
            app.FSsurfRhCheckBox = uicheckbox(app.SurfaceOptionsPanel);
            app.FSsurfRhCheckBox.ValueChangedFcn = createCallbackFcn(app, @FSsurfRhCheckBoxValueChanged, true);
            app.FSsurfRhCheckBox.Text = 'rh';
            app.FSsurfRhCheckBox.Value = true;
            app.OpacityLabel = uilabel(app.SurfaceOptionsPanel);
            app.OpacityLabel.Text = 'Opacity';
            app.OpacityEdit = uieditfield(app.ProjectionTab, 'numeric');
            app.OpacityEdit.ValueChangedFcn = createCallbackFcn(app, @OpacityEditValueChanged, true);
            app.OpacityEdit.Value = 1;
            app.SurfaceOpacitySliderLabel = uilabel(app.ProjectionTab);
            app.SurfaceOpacitySliderLabel.Text = 'Surface Opacity';
            app.SurfaceOpacitySlider = uislider(app.ProjectionTab);
            app.SurfaceOpacitySlider.MajorTicks = [];
            app.SurfaceOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @SurfaceOpacitySliderValueChanged, true);
            app.SurfaceOpacitySlider.MinorTicks = [];
            app.SurfaceOpacitySlider.Value = 100;
            app.AtlasOptionsPanel = uipanel(app.ProjectionTab);
            app.AtlasOptionsPanel.Title = 'Atlas Options';
            app.AtlasGroupListbox = uilistbox(app.AtlasOptionsPanel);
            app.AtlasGroupListbox.Items = {'Item 1'};
            app.AtlasGroupListbox.ValueChangedFcn = createCallbackFcn(app, @AtlasGroupListboxValueChanged, true);
            app.QueryButton = uibutton(app.AtlasOptionsPanel, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Tooltip = {'Atlas query using Freesurfer''s segmentation and FSL''s Harvard-Oxford atlases.'};
            app.QueryButton.Text = 'Query';
            app.ShowAtlasButton = uibutton(app.AtlasOptionsPanel, 'push');
            app.ShowAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAtlasButtonPushed, true);
            app.ShowAtlasButton.Text = 'Seletec Atlas';
            app.ElecLabelListbox = uilistbox(app.ProjectionTab);
            app.ElecLabelListbox.Items = {};
            app.ElecLabelListbox.Multiselect = 'on';
            app.ElecLabelListbox.ValueChangedFcn = createCallbackFcn(app, @ElecLabelListboxValueChanged, true);
            app.ElecLabelListbox.Value = {};
            app.ChannelsLabel = uilabel(app.ProjectionTab);
            app.ChannelsLabel.Text = 'Channels';
            app.ElecLabelGroupInfo = uilabel(app.ProjectionTab);
            app.ElecLabelGroupInfo.Text = {'Label'; ''};
            app.ChanelgroupLabel = uilabel(app.ProjectionTab);
            app.ChanelgroupLabel.Text = 'Chanel group';
            app.ElecLabelGroupListbox = uilistbox(app.ProjectionTab);
            app.ElecLabelGroupListbox.Items = {};
            app.ElecLabelGroupListbox.Multiselect = 'on';
            app.ElecLabelGroupListbox.ValueChangedFcn = createCallbackFcn(app, @ElecLabelGroupListboxValueChanged, true);
            app.ElecLabelGroupListbox.Value = {};
            app.SelectedchannelsLabel = uilabel(app.ProjectionTab);
            app.SelectedchannelsLabel.Text = 'Selected channels';
            app.SelectedLabelListbox = uilistbox(app.ProjectionTab);
            app.SelectedLabelListbox.Items = {};
            app.SelectedLabelListbox.Value = {};
            app.AtlasofselectedchannelPanel = uipanel(app.ProjectionTab);
            app.AtlasofselectedchannelPanel.Title = 'Atlas of selected channel';
            app.AtlasListbox = uilistbox(app.AtlasofselectedchannelPanel);
            app.AtlasListbox.Items = {'Item 1'};
            app.AtlasListbox.Multiselect = 'on';
            app.AtlasListbox.Value = {'Item 1'};
            app.ElectrodeProjectionPanel = uipanel(app.ProjectionTab);
            app.ElectrodeProjectionPanel.Title = 'Electrode Projection';
            app.EqualSpaceLineFit = uicheckbox(app.ElectrodeProjectionPanel);
            app.EqualSpaceLineFit.Text = 'Equal space for Line Fit';
            app.EqualSpaceLineFit.Value = true;
            app.GridSizeLabel = uilabel(app.ElectrodeProjectionPanel);
            app.GridSizeLabel.Text = 'Grid Size';
            app.GridSizeEditField = uieditfield(app.ElectrodeProjectionPanel, 'text');
            app.GridSizeEditField.ValueChangedFcn = createCallbackFcn(app, @GridSizeEditFieldValueChanged2, true);
            app.GridSizeEditField.Tooltip = {'A 1 x 2 vector.'; '[4, 8] such that the channels are'; 'row_1: 1, 2, 3, 4, 5, 6, 7, 8;'; 'row 2: 9, 10, 11, 12, 13, 14, 15, 16;'; 'row_3: 17, 18, 19, 20, 21, 23, 24;'; 'row_4: 25, 26, 27, 28, 29, 30, 31, 32'; ''};
            app.PreviewProjButton = uibutton(app.ElectrodeProjectionPanel, 'push');
            app.PreviewProjButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewProjButtonPushed, true);
            app.PreviewProjButton.Text = 'Preview';
            app.ApplyProjButton = uibutton(app.ElectrodeProjectionPanel, 'push');
            app.ApplyProjButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyProjButtonPushed, true);
            app.ApplyProjButton.Text = 'Apply';
            app.ClearProjButton = uibutton(app.ElectrodeProjectionPanel, 'push');
            app.ClearProjButton.ButtonPushedFcn = createCallbackFcn(app, @ClearProjButtonPushed, true);
            app.ClearProjButton.Text = 'Clear';
            app.MethodButtonGroup = uibuttongroup(app.ElectrodeProjectionPanel);
            app.MethodButtonGroup.Title = 'Method';
            app.PorjMethodDykstraButton = uiradiobutton(app.MethodButtonGroup);
            app.PorjMethodDykstraButton.Text = 'Dykstra';
            app.PorjMethodGroppeButton = uiradiobutton(app.MethodButtonGroup);
            app.PorjMethodGroppeButton.Text = 'Groppe';
            app.PorjMethodHemesButton = uiradiobutton(app.MethodButtonGroup);
            app.PorjMethodHemesButton.Text = 'Hemes';
            app.PorjMethodLineFitButton = uiradiobutton(app.MethodButtonGroup);
            app.PorjMethodLineFitButton.Text = 'Line Fit';
            app.PorjMethodWangButton = uiradiobutton(app.MethodButtonGroup);
            app.PorjMethodWangButton.Text = 'Wang';
            app.PorjMethodWangButton.Value = true;
            app.PlotButton = uibutton(app.ProjectionTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Show plots';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DisplayoptionsPanel = uipanel(app.SettingsTab);
            app.DisplayoptionsPanel.Title = 'Display options';
            app.ElectrodestoshowButtonGroup = uibuttongroup(app.DisplayoptionsPanel);
            app.ElectrodestoshowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ElectrodestoshowButtonGroupSelectionChanged, true);
            app.ElectrodestoshowButtonGroup.Title = 'Electrodes to show';
            app.ShowallelectrodesButton = uiradiobutton(app.ElectrodestoshowButtonGroup);
            app.ShowallelectrodesButton.Text = 'Show all electrodes';
            app.ShowallelectrodesButton.Value = true;
            app.ShowselectedelectrodesonlyButton = uiradiobutton(app.ElectrodestoshowButtonGroup);
            app.ShowselectedelectrodesonlyButton.Text = 'Show selected electrodes only';
            app.ShowallwithouthighlightingselectedelectrodeButton = uiradiobutton(app.ElectrodestoshowButtonGroup);
            app.ShowallwithouthighlightingselectedelectrodeButton.Text = 'Show all without highlighting selected electrode';
            app.ShowprojectedelectrodesCheckBox = uicheckbox(app.DisplayoptionsPanel);
            app.ShowprojectedelectrodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowprojectedelectrodesCheckBoxValueChanged, true);
            app.ShowprojectedelectrodesCheckBox.Text = 'Show projected electrodes';
            app.ChangeelectrodescolorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.ChangeelectrodescolorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeelectrodescolorButtonPushed, true);
            app.ChangeelectrodescolorButton.Text = 'Change electrodes color';
            app.ChangeselectedelectrodescolorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.ChangeselectedelectrodescolorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeselectedelectrodescolorButtonPushed, true);
            app.ChangeselectedelectrodescolorButton.Text = 'Change selected electrodes color';
            app.ChangeprojectedelectrodescolorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.ChangeprojectedelectrodescolorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeprojectedelectrodescolorButtonPushed, true);
            app.ChangeprojectedelectrodescolorButton.Text = 'Change projected electrodes color';
            app.ElectrodessizeEditFieldLabel = uilabel(app.DisplayoptionsPanel);
            app.ElectrodessizeEditFieldLabel.Text = 'Electrodes size';
            app.ElectrodessizeEditField = uieditfield(app.DisplayoptionsPanel, 'numeric');
            app.ElectrodessizeEditField.Limits = [1 Inf];
            app.ElectrodessizeEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodessizeEditFieldValueChanged, true);
            app.ElectrodessizeEditField.Value = 1;
            app.SelectedelectrodesizeEditFieldLabel = uilabel(app.DisplayoptionsPanel);
            app.SelectedelectrodesizeEditFieldLabel.Text = 'Selected electrode size';
            app.SelectedelectrodesizeEditField = uieditfield(app.DisplayoptionsPanel, 'numeric');
            app.SelectedelectrodesizeEditField.Limits = [1 Inf];
            app.SelectedelectrodesizeEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedelectrodesizeEditFieldValueChanged, true);
            app.SelectedelectrodesizeEditField.Value = 1;
            app.ProjectedelectrodesizeEditFieldLabel = uilabel(app.DisplayoptionsPanel);
            app.ProjectedelectrodesizeEditFieldLabel.Text = 'Projected electrode size';
            app.ProjectedelectrodesizeEditField = uieditfield(app.DisplayoptionsPanel, 'numeric');
            app.ProjectedelectrodesizeEditField.Limits = [1 Inf];
            app.ProjectedelectrodesizeEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectedelectrodesizeEditFieldValueChanged, true);
            app.ProjectedelectrodesizeEditField.Value = 1;
            app.T1brainintensityPanel = uipanel(app.DisplayoptionsPanel);
            app.T1brainintensityPanel.Title = 'T1 brain intensity';
            app.LowLabel = uilabel(app.T1brainintensityPanel);
            app.LowLabel.Text = 'Low';
            app.T1IntensityLo = uieditfield(app.T1brainintensityPanel, 'numeric');
            app.T1IntensityLo.ValueChangedFcn = createCallbackFcn(app, @T1IntensityLoValueChanged, true);
            app.T1IntensityHi = uieditfield(app.T1brainintensityPanel, 'numeric');
            app.T1IntensityHi.ValueChangedFcn = createCallbackFcn(app, @T1IntensityHiValueChanged, true);
            app.DefaultT1Clim = uibutton(app.T1brainintensityPanel, 'push');
            app.DefaultT1Clim.ButtonPushedFcn = createCallbackFcn(app, @DefaultT1ClimButtonPushed, true);
            app.DefaultT1Clim.Text = 'Reset';
            app.HighLabel = uilabel(app.T1brainintensityPanel);
            app.HighLabel.Text = 'High';
            app.MNIbrainintensityPanel = uipanel(app.DisplayoptionsPanel);
            app.MNIbrainintensityPanel.Title = 'MNI brain intensity';
            app.LowLabel_2 = uilabel(app.MNIbrainintensityPanel);
            app.LowLabel_2.Text = 'Low';
            app.MNIintensityLo = uieditfield(app.MNIbrainintensityPanel, 'numeric');
            app.MNIintensityLo.ValueChangedFcn = createCallbackFcn(app, @MNIintensityLoValueChanged, true);
            app.MNIIntensityHi = uieditfield(app.MNIbrainintensityPanel, 'numeric');
            app.MNIIntensityHi.ValueChangedFcn = createCallbackFcn(app, @MNIIntensityHiValueChanged, true);
            app.HighLabel_2 = uilabel(app.MNIbrainintensityPanel);
            app.HighLabel_2.Text = 'High';
            app.DefaultStdClim = uibutton(app.MNIbrainintensityPanel, 'push');
            app.DefaultStdClim.ButtonPushedFcn = createCallbackFcn(app, @DefaultStdClimButtonPushed, true);
            app.DefaultStdClim.Text = 'Reset';
            app.FigurebBkColorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.FigurebBkColorButton.ButtonPushedFcn = createCallbackFcn(app, @FigurebBkColorButtonPushed, true);
            app.FigurebBkColorButton.Tooltip = {'Replot required.'};
            app.FigurebBkColorButton.Text = 'Figure background color';
            % Show the figure after all components are created
            app.ENORMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enorm_dev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ENORMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ENORMUIFigure)
        end
    end
end