classdef eplace_dev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        File_M                     matlab.ui.container.Menu
        Open_M                     matlab.ui.container.Menu
        Save_M                     matlab.ui.container.Menu
        Close_M                    matlab.ui.container.Menu
        Quit_M                     matlab.ui.container.Menu
        Load_M                     matlab.ui.container.Menu
        Elec_M                     matlab.ui.container.Menu
        Label_M                    matlab.ui.container.Menu
        Mask_M                     matlab.ui.container.Menu
        Stop_M                     matlab.ui.container.Menu
        AutoPlace_M                matlab.ui.container.Menu
        About_M                    matlab.ui.container.Menu
        CurData_M                  matlab.ui.container.Menu
        Eplace_M                   matlab.ui.container.Menu
        TabGroup3d                 matlab.ui.container.TabGroup
        dplotsTab                  matlab.ui.container.Tab
        GridLayout9                matlab.ui.container.GridLayout
        dlinefittingresultsLabel   matlab.ui.control.Label
        ElectrodesplacedLabel      matlab.ui.control.Label
        UIAxesAllElec              matlab.ui.control.UIAxes
        UIAxes3dLineFit            matlab.ui.control.UIAxes
        Tab_G                      matlab.ui.container.TabGroup
        EplaceTab                  matlab.ui.container.Tab
        MainGridLayout             matlab.ui.container.GridLayout
        ElecTransp_SL              matlab.ui.control.Label
        ElecTransp_SLD             matlab.ui.control.Slider
        LabelInfo                  matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        OverlapLabel               matlab.ui.control.Label
        OverlapThr_E               matlab.ui.control.NumericEditField
        ThrE_4L                    matlab.ui.control.Label
        OverlapRadius_E            matlab.ui.control.NumericEditField
        RadiusE_4L                 matlab.ui.control.Label
        CenterEstPanel             matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        CenterEstThr_E             matlab.ui.control.NumericEditField
        ThrEdit_3L                 matlab.ui.control.Label
        RadiusEdit_3L              matlab.ui.control.Label
        CenterEstRadius_E          matlab.ui.control.NumericEditField
        CenterEst_CB               matlab.ui.control.CheckBox
        LocalPkSearchPanel         matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        PkSerachRange_E            matlab.ui.control.NumericEditField
        RangeE_L                   matlab.ui.control.Label
        PkSearch_CB                matlab.ui.control.CheckBox
        MethodLabel                matlab.ui.control.Label
        RmElec_B                   matlab.ui.control.Button
        PreviewElec_B              matlab.ui.control.Button
        AddElec_B                  matlab.ui.control.Button
        DepthElecPanel             matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        ElecFitRadiusEF            matlab.ui.control.NumericEditField
        RadiusLabel                matlab.ui.control.Label
        ElecFitThrEF               matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ElecFitIdxEF               matlab.ui.control.EditField
        ElecFitRefEF               matlab.ui.control.EditField
        TotalNLabel                matlab.ui.control.Label
        ElecFitTotalEF             matlab.ui.control.NumericEditField
        LineFitButton              matlab.ui.control.Button
        ReferenceEEFL              matlab.ui.control.Label
        DistanceEFL                matlab.ui.control.Label
        IndexEFL                   matlab.ui.control.Label
        ElecFitAutoIdxCB           matlab.ui.control.CheckBox
        ElecFitDistEF              matlab.ui.control.NumericEditField
        ElecFitEstDistCB           matlab.ui.control.CheckBox
        FinalSigma_E               matlab.ui.control.NumericEditField
        FinalSigma_EL              matlab.ui.control.Label
        Method_BG                  matlab.ui.container.ButtonGroup
        MethManual_B               matlab.ui.control.RadioButton
        MethUsePk_B                matlab.ui.control.RadioButton
        MethUseExact_B             matlab.ui.control.RadioButton
        MethAuto_B                 matlab.ui.control.RadioButton
        ShowAll                    matlab.ui.control.StateButton
        CenterCursor_B             matlab.ui.control.Button
        ZR_L                       matlab.ui.control.Label
        YB_L                       matlab.ui.control.Label
        YT_L                       matlab.ui.control.Label
        YR_L                       matlab.ui.control.Label
        YL_L                       matlab.ui.control.Label
        XR_L                       matlab.ui.control.Label
        Intens_L                   matlab.ui.control.Label
        StdZ_E                     matlab.ui.control.NumericEditField
        StdY_E                     matlab.ui.control.NumericEditField
        StdX_E                     matlab.ui.control.NumericEditField
        ZB_L                       matlab.ui.control.Label
        ImgZ_SP                    matlab.ui.control.Spinner
        ImgY_SP                    matlab.ui.control.Spinner
        Z_L                        matlab.ui.control.Label
        Y_L                        matlab.ui.control.Label
        X_L                        matlab.ui.control.Label
        ImgX_SP                    matlab.ui.control.Spinner
        ZL_L                       matlab.ui.control.Label
        XL_L                       matlab.ui.control.Label
        ZT_L                       matlab.ui.control.Label
        XB_L                       matlab.ui.control.Label
        XT_L                       matlab.ui.control.Label
        Status_L                   matlab.ui.control.Label
        Sort_B                     matlab.ui.control.Button
        ReLabel_B                  matlab.ui.control.Button
        LabelAutoMove_CB           matlab.ui.control.CheckBox
        ReLabel_TE                 matlab.ui.control.EditField
        AssignLbl_B                matlab.ui.control.Button
        RealLabel                  matlab.ui.control.ListBox
        Label                      matlab.ui.control.ListBox
        Target_L                   matlab.ui.control.Label
        Label_LBL                  matlab.ui.control.Label
        MaxIntens_E                matlab.ui.control.NumericEditField
        MinIntens_E                matlab.ui.control.NumericEditField
        MinIntens_L                matlab.ui.control.Label
        Scale_SP                   matlab.ui.control.Spinner
        ScaleSpin_L                matlab.ui.control.Label
        UIAxesY                    matlab.ui.control.UIAxes
        UIAxesZ                    matlab.ui.control.UIAxes
        UIAxesX                    matlab.ui.control.UIAxes
        PreferencesTab             matlab.ui.container.Tab
        GridLayout6                matlab.ui.container.GridLayout
        PkSearchType_DD            matlab.ui.control.DropDown
        TypeDD_L                   matlab.ui.control.Label
        AutoPlacePanel             matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        IntensityofthresholdLabel  matlab.ui.control.Label
        ThresholdIntensity         matlab.ui.control.Label
        AutoPlaceThr               matlab.ui.container.ButtonGroup
        AutoThrHist_RB             matlab.ui.control.RadioButton
        AutoThrPerc_RB             matlab.ui.control.RadioButton
        AutoThrPeak_RB             matlab.ui.control.RadioButton
        AutoThrAbs_RB              matlab.ui.control.RadioButton
        AutoThr_E                  matlab.ui.control.NumericEditField
        AutoThrE_L                 matlab.ui.control.Label
        ElecSz_E                   matlab.ui.control.NumericEditField
        ElecSzE_L                  matlab.ui.control.Label
        IntensRange_T              matlab.ui.control.TextArea
        IntensRangeT_L             matlab.ui.control.Label
        MaskClear_B                matlab.ui.control.Button
        MaskSet_B                  matlab.ui.control.Button
        Mask_E                     matlab.ui.control.EditField
        MaskE_L                    matlab.ui.control.Label
        Status_L2                  matlab.ui.control.Label
        FontSz_E                   matlab.ui.control.NumericEditField
        FontSz_L                   matlab.ui.control.Label
        SaveSourceImg_BG           matlab.ui.container.ButtonGroup
        NoSaveSrc_B                matlab.ui.control.RadioButton
        YesSaveSrc_B               matlab.ui.control.RadioButton
        CoordType_BG               matlab.ui.container.ButtonGroup
        UnweigCenter_B             matlab.ui.control.RadioButton
        WeigCenter_B               matlab.ui.control.RadioButton
        Peak_B                     matlab.ui.control.RadioButton
    end

    properties (Access = private)
        usrdata
        status_label
    end


    properties (Access = public)
        prefer
    end


    methods (Access = private)

        function [status, msg, fnam] = LoadImage(app)
            % Load post-operative CT image *.nii.gz using Freesrufer's MRIread
            % status, 0 - image loading succeeded
            %         1 - failed to read image
            %         2 - image loading cancelled
            % msg, message

            if isempty( app.usrdata.source_image)
                [fnam, p] = uigetfile( '*.nii.gz', 'Please select an image.');

            else
                [p, fnam] = IFileparts( app, app.usrdata.source_image);
            end

            % No file selected, Cancel was pressed
            status = 2;
            if isequal( fnam, 0) || isequal( p, 0)
                msg = 'Image loading cancelled.';
                return;
            end

            % Failed to read image status
            status = 1;

            if ~exist( 'MRIread', 'file')
                % Freesurfer Matlab toolbox was not available
                msg = 'Freesurfer''s MRIread function was not found.';
                return;
            end

            try
                cd( p);
                f = fullfile( p, fnam);
                app.usrdata.img.mri = MRIread( f);                
                app.usrdata.img.mri.vol = app.usrdata.img.mri.vol( :, :, :, 1);
                p_dim = [2, 1, 3, 4];
                app.usrdata.img.mri.vol = permute( app.usrdata.img.mri.vol, p_dim);
                app.usrdata.img.mri.volres = app.usrdata.img.mri.volres( [2, 1, 3]);
                app.usrdata.img.info.filename = f;
                msg = 'Image was loaded successfully.';
                nb_vols = size( app.usrdata.img.mri.vol, 4);
                if nb_vols > 1
                    msg = sprintf( '%s. 1/%d image will be used', msg, nb_vols);
                end

                status = 0;

            catch
                msg = 'Failed to load image';
            end
        end % function load image


        function LoadBrainmask( app, str)
            if nargin < 2
                [fnam, p] = uigetfile( '*.nii.gz', 'Select a brain mask.');
            else
                % str, full-path-to-mask-file
                [p, fnam] = IFileparts( app, str);
            end

            % Load a brain mask for automatical electrode placement
            if isequal( fnam, 0) || isequal( p, 0)
                msg = 'Brain mask loading canceled.';
                if ~isempty( app.usrdata.brainmask)
                    msg = sprintf( '%s. However, a mask has been loaded already.', msg);
                end

                set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.msg_fontcolor);
                return;
            end

            try
                mask = MRIread( fullfile( p, fnam));
                mask.vol = double( permute( mask.vol( :, :, :, 1), [2, 1, 3]) > 0);
                if ~isequal( size( mask.vol), app.usrdata.img.info.dims)
                    msg = 'Invalid brain mask.';
                else
                    app.usrdata.brainmask = mask;
                    msg = 'Brain mask was successfully loaded.';
                end

                app.Mask_E.Value = fullfile( p, fnam);
                set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.msg_fontcolor);

            catch
                app.usrdata.brainmask = [];
                app.Mask_E.Value = '';
                set( app.status_label, 'Text', 'Error. Failed to load brain mask.', ...
                    'FontColor', app.usrdata.err_fontcolor);
            end

            drawnow;
        end % function

        
        function [p, nam] = IFileparts( ~, x)
            % x is a full-path-to-nifti file, with or without extension
            if endsWith( x, '.nii.gz')
                x = regexprep( x, '\.nii\.gz$', '');
            end

            [p, nam] = fileparts( x);
            if isempty( p)
                p = pwd;
            end

            nam = [nam, '.nii.gz'];
        end


        % return mouse click point
        function AxesBtnDnX(app)
            % ButtonDown callback for UIAxesX
            if ~strcmpi( app.UIFigure.SelectionType, 'normal')
                return;
            end

            app.usrdata.cursor.j = round( app.UIAxesX.CurrentPoint( 1, 1));
            app.usrdata.cursor.k = round( app.UIAxesX.CurrentPoint( 1, 2));
            app.usrdata.last_ax = 1;
            UpdateCursor(app);
        end


        function AxesBtnDnY(app)
            % ButtonDown callback for UIAxesY
            if ~strcmpi( app.UIFigure.SelectionType, 'normal')
                return;
            end

            app.usrdata.cursor.i = round( app.UIAxesY.CurrentPoint( 1, 1));
            app.usrdata.cursor.k = round( app.UIAxesY.CurrentPoint( 1, 2));

            
            app.usrdata.last_ax = 2;
            UpdateCursor(app);
        end


        function AxesBtnDnZ(app)
            % ButtonDown callback for UIAxesZ
            if ~strcmpi( app.UIFigure.SelectionType, 'normal')
                return;
            end

            app.usrdata.cursor.i = round( app.UIAxesZ.CurrentPoint( 1, 1));
            app.usrdata.cursor.j = round( app.UIAxesZ.CurrentPoint( 1, 2));
            app.usrdata.last_ax = 3;
            UpdateCursor(app);
        end


        function UpdateCursor(app)
            % image coordinate to real world coordinates
            i = app.usrdata.cursor.i;
            j = app.usrdata.cursor.j;
            k = app.usrdata.cursor.k;

            c = app.usrdata.img.mri.vox2ras1 * [i; j; k; 1];
            app.usrdata.cursor.std = c( 1:3);

            app.ImgX_SP.Value = i;
            app.ImgY_SP.Value = j;
            app.ImgZ_SP.Value = k;

            app.StdX_E.Value = c(1);
            app.StdY_E.Value = c(2);
            app.StdZ_E.Value = c(3);

            UpdateYokeCoord( app);
            Redraw( app);
            
            if length( app.Label.Value) ~= 1
                return;
            end

            % Calculate the distance between cursor and selected label
            d = CalDistance( app, app.Label.Value);
            fmt = sprintf( 'Distance to %%s: %s mm', app.usrdata.distance_format);
            txt = sprintf( fmt, strjoin( app.Label.Value, ', '), d);
            set( app.LabelInfo, 'Text', txt, 'Fontcolor', app.usrdata.msg_fontcolor);
            drawnow;
        end % update cursor locatoin


        % update plots
        function Redraw(app, varargin)
            if isempty( app.usrdata.img.mri)
                return;
            end

            place_mode = 'add';
            if nargin > 1
                % preview label
                c_ind = varargin{1};
                [cmat, ctable] = ColorTable( app, {c_ind});
                place_mode = 'preview';
            end

            sz = app.usrdata.img.info.dims;
            i = app.usrdata.cursor.i;
            j = app.usrdata.cursor.j;
            k = app.usrdata.cursor.k;

            % x&y lims sets will take effect for next call
            UpdateXYLims( app);

            % two draws on the first two axeses define a cubic
            % use ax(1)-xData for y, ax(1)-yData for z, ax(2)-xData for x
            [xData, yData, zData] = RetriveBoxData( app);

            crs_ind = {[2, 3], [1, 3], [1, 2]};
            coords = [i, j, k];
            for ax_idx = 1 : 3
                cur_ax = app.usrdata.axh( ax_idx);
                if ax_idx == 1
                    img_h = imagesc( cur_ax, squeeze( app.usrdata.img.mri.vol( i, :, :))');
                    img_h.ButtonDownFcn = @(h, e) AxesBtnDnX(app);
                    if strcmpi( place_mode, 'add')
                        m = squeeze( app.usrdata.imgdisp.cmat( i, :, :))';
                    else
                        m = squeeze( cmat( i, :, :))';
                    end

                elseif ax_idx == 2
                    img_h = imagesc( cur_ax, squeeze( app.usrdata.img.mri.vol( :, j, :))');
                    img_h.ButtonDownFcn = @(h, e) AxesBtnDnY(app);
                    if strcmpi( place_mode, 'add')
                        m = squeeze( app.usrdata.imgdisp.cmat( :, j, :))';
                    else
                        m = squeeze( cmat( :, j, :))';
                    end

                else
                    img_h = imagesc( cur_ax, squeeze( app.usrdata.img.mri.vol( :, :, k))');
                    img_h.ButtonDownFcn = @(h, e) AxesBtnDnZ(app);
                    if strcmpi( place_mode, 'add')
                        m = squeeze( app.usrdata.imgdisp.cmat( :, :, k))';
                    else
                        m = squeeze( cmat( :, :, k))';
                    end
                end

                axis( cur_ax, 'off');
                axis( cur_ax, 'equal');
                axis( cur_ax, 'vis3d');
                set( cur_ax, 'DataAspectRatio', app.usrdata.ax( ax_idx).aspectratio,...
                    'xlim', app.usrdata.ax( ax_idx).xlim,...
                    'ylim', app.usrdata.ax( ax_idx).ylim,...
                    'color', [0, 0, 0], 'xtick', [], 'ytick', [], 'ydir', 'normal');

                colormap( cur_ax, gray);
                CrossHair( app, cur_ax, img_h, coords( crs_ind{ ax_idx}));
                clim( cur_ax, app.usrdata.imgdisp.clim);

                hold( cur_ax, 'on');
                if any( m(:))
                    msz = sz( crs_ind{ ax_idx}( [2 1]));
                    if strcmpi( place_mode, 'add')
                        h = imagesc( cur_ax, reshape( app.usrdata.imgdisp.ctable( m, :), msz(1), msz(2), 3));
                    else
                        h = imagesc( cur_ax, reshape( ctable( m, :), msz(1), msz(2), 3));
                    end

                    set( h, 'AlphaData', app.ElecTransp_SLD.Value * (m > 1) / 100);
                    h.ButtonDownFcn = img_h.ButtonDownFcn;
                end

                % hand-drawn boxes
                if ~isempty( app.usrdata.box( ax_idx).data)
                    app.usrdata.box( ax_idx).h = line( cur_ax, ...
                        app.usrdata.box( ax_idx).data{1},...
                        app.usrdata.box( ax_idx).data{2},...
                        'linestyle', app.usrdata.box_linestyle,...
                        'linewidth', app.usrdata.box_linewidth,...
                        'color', app.usrdata.box_color);
                end

                % cubic ROI defined by boxes on two out of the three axeses
                if ~isempty( xData) && ~isempty( yData) && ~isempty( zData)
                    x_ind = [1, 2, 2, 1, 1];
                    y_ind = [1, 1, 2, 2, 1];
                    if ax_idx == 1
                        x4plot = yData( x_ind);
                        y4plot = zData( y_ind);
                    elseif ax_idx == 2
                        x4plot = xData( x_ind);
                        y4plot = zData( y_ind);
                    else
                        x4plot = xData( x_ind);
                        y4plot = yData( y_ind);
                    end

                    line( cur_ax, x4plot, y4plot,...
                        'linestyle', app.usrdata.roi_linestyle,...
                        'linewidth', app.usrdata.roi_linewidth,...
                        'color', app.usrdata.roi_color);
                end

                hold( cur_ax, 'off');
            end % axes loop

            % intensity at curernt voxel
            val = nan;
            if i >= 1 && i <= sz(1) && j >= 1 && j <= sz(2) && k >= 1 && k <= sz(3)
                val = app.usrdata.img.mri.vol( i, j, k);
            end

            app.Intens_L.Text = num2str( round( 100*val)/100);

            % yoke 3d dot plot of electrodes, and cursor location
            UpdateYokePlot( app)

            % highlight label that enclose current cursor when left button press was within axes
            lbl_ind = [];
            if strcmpi( app.usrdata.axes_click, 'yes')
                cur_ind = sub2ind( app.usrdata.img.info.dims, i, j, k);
                lbl_ind = find( cellfun( @(x) any( x == cur_ind), app.usrdata.elec.rois));
            end

            % update label listbox
            s = 'No label has been defined at the cursor.';
            if ~isempty( lbl_ind)
                lbl = app.usrdata.elec.roiname( lbl_ind);
                if ~any( ismember( app.Label.Value, lbl))
                    % highlight just one label
                    app.Label.Value = lbl{1};
                end

                lbl = strjoin( lbl(:)', ' ');
                nb_roi = length( app.usrdata.elec.roiname);
                nb_lbl = length( lbl_ind);
                if nb_lbl == 1
                    s = sprintf( 'Current label (%d/%d): %s.', lbl_ind, nb_roi, lbl);

                elseif nb_lbl <= 3
                    lbl_ind = strjoin( string( lbl_ind(:)'), ', ');
                    s = sprintf( 'Current labels ([%s]/%d): %s.', lbl_ind, nb_roi, lbl);

                else
                    s = sprintf( '%d/%d labels are selected.', nb_lbl, nb_roi);
                end
            end

            set( app.status_label, 'Text', s, 'FontColor', app.usrdata.msg_fontcolor);
        end % function Redraw


        function Initializeusrdata(app, init_img)
            % fields that can be added to elec by other software
            app.usrdata.opt_fields = {'reg', 'proj', 'atlas', 'surf', 'srcdata'};
            app.usrdata.re_reg_required = 'no';

            % CT image, data structure returned by MRIread
            if strcmpi( init_img, 'yes')
                app.usrdata.img.mri = [];
                app.usrdata.img.info = [];
            end

            app.usrdata.brainmask = [];

            app.usrdata.axh(1) = app.UIAxesX;
            app.usrdata.axh(2) = app.UIAxesY;
            app.usrdata.axh(3) = app.UIAxesZ;
            app.usrdata.cur_ax = [];
            app.usrdata.last_ax = [];
            app.usrdata.dims = [0 0 0];

            for k = 1 : 3
                app.usrdata.ax(k).xlim = app.usrdata.axh(k).XLim;
                app.usrdata.ax(k).ylim = app.usrdata.axh(k).YLim;
                app.usrdata.ax(k).raw_xlim = app.usrdata.ax(k).xlim;
                app.usrdata.ax(k).raw_ylim = app.usrdata.ax(k).ylim;

                % boxes field
                app.usrdata.box(k).start = 'yes'; %  0, start plotting
                app.usrdata.box(k).h = []; % handle to line object
                app.usrdata.box(k).data = [];
                app.usrdata.box(k).move = 'no';
            end

            % hand-drawn boxex
            app.usrdata.box_color = [1, 0, 0];
            app.usrdata.box_linestyle = '--';
            app.usrdata.box_linewidth = 1;

            app.usrdata.roi_linestyle = '-';
            app.usrdata.roi_linewidth = 1.5;
            app.usrdata.roi_color = [0, 1, 0];

            app.usrdata.currentobject = [];

            % cursor location
            % image coordinate
            app.usrdata.cursor.i = [];
            app.usrdata.cursor.j = [];
            app.usrdata.cursor.k = [];

            % replace above with
            app.usrdata.cursor.img = [];

            % real world coordinate, [x, y, z]
            app.usrdata.cursor.std = [];

            % [min, max] climit of colormap
            app.usrdata.imgdisp.clim = [];
            app.usrdata.imgdisp.ctable = [];
            app.usrdata.imgdisp.cmat = [];

            app.usrdata.draw.status = 'off'; % 'on' | 'off'
            app.usrdata.draw.start = 'no';
            app.usrdata.draw.pensize = 1; % size of pen
            app.usrdata.draw.penval = 1; % intensity of the pen
            app.usrdata.draw.mask = []; % index x val
            app.usrdata.draw.history = {}; % Ctrl+Z to undo {action, index; etc.}
            app.usrdata.draw.ctable = []; % color table
            app.usrdata.draw.cmat = []; % color table index
            app.usrdata.draw.linecolor = 'w';
            app.usrdata.draw.linewidth = 1.5;
            app.usrdata.draw.colormap = [1, 0 0];

            % 'yes' | 'no', this value can be changed in WindowButtonUpFcn
            app.usrdata.axes_click = 'no';

            % handle to yoke figure
             app.usrdata.yoke_coord = [];     

            app.usrdata.marker_sz = 6;
            app.usrdata.marker_color = 'b';
            app.usrdata.cur_marker_color = 'r';

            % index of all electrodes
            app.usrdata.elec = [];
            app.usrdata.elec.rois = {};
            % cell array of string
            app.usrdata.elec.roiname = {};
            % 3d line fit center, interpolated coordinates
            app.usrdata.elec.fit3d = [];
            % TODO. Make it a table


            % reserved field for other apps
            app.usrdata.elec.reg = '';
            app.usrdata.elec.proj = '';
            app.usrdata.elec.atlas = [];
            app.usrdata.elec.surf = [];

            % 'yes' | 'no'
            app.usrdata.results_saved = 'no';

            % draw ROIs manually
            % 'on' | 'off'
            app.usrdata.pen.status = 'off';
            % 'yes' | 'no'
            app.usrdata.pen.start = 'no';
            app.usrdata.pen.size = 1;
            app.usrdata.pen.val = 1;
            app.usrdata.pen.mask = [];
            app.usrdata.pen.history = {};
            app.usrdata.pen.ctable = [];
            app.usrdata.pen.cmat = [];
            app.usrdata.pen.linecolor = 'w';
            app.usrdata.pen.linewidth = 1.5;
            app.usrdata.pen.colormap = [1, 0, 0];

            % Current focus
            app.usrdata.edit_focus = false;
        end % function InitializeData


        function [cmat, ctable] = ColorTable( app, rois)
            if nargin < 2
                rois = app.usrdata.elec.rois;
                to_update = true;
            else
                to_update = false;
            end

            dims = app.usrdata.img.info.dims;
            tmpimg = zeros( dims);
            tmpimg2 = zeros( dims);
            empty_roi = [];
            cnt = 0;
            for roi_idx = 1 : length( rois)
                ind = rois{ roi_idx};
                if ~isempty( ind)
                    tmpimg( ind) = tmpimg( ind) + 1;
                    cnt = cnt + 1;
                    tmpimg2( ind) = tmpimg2( ind) + cnt;
                else
                    empty_roi = cat( 1, empty_roi, roi_idx);
                end
            end

            % prepare random color table
            if ~any( tmpimg2(:))
                cmat = zeros( dims);
                ctable = rand( 1, 3);
            else
                % overlaps
                tmp = tmpimg > 1;
                if ~any( tmp(:))
                    cmat = tmpimg2 + 1;
                else
                    % number of overlaps
                    val = unique( tmpimg( tmp));
                    N = length( val);
                    % non-overlaps index
                    tmpimg2 = tmpimg2 .* (1 - tmp);
                    tt = zeros( dims);
                    for k = 1 : N
                        tt = tt + k*( tmpimg == val( k));
                    end
                    cmat = tmpimg2 + (tt + cnt * tmp) + 1;
                end

                % random color
                ctable = rand( max( cmat(:)) + 1, 3);
            end

            % save to app data structure
            if ~to_update
                return;
            end

            app.usrdata.imgdisp.cmat = cmat;
            raw_N = size( app.usrdata.imgdisp.ctable, 1);
            new_N = size( ctable, 1);
            if raw_N < new_N
                ctable = ctable( raw_N+1 : end, :);
                app.usrdata.imgdisp.ctable = cat( 1, app.usrdata.imgdisp.ctable, ctable);
            else
                app.usrdata.imgdisp.ctable = app.usrdata.imgdisp.ctable( 1:new_N, :);
            end
        end % function


        % Show orientation labels
        function SetOriLabel( app)
            s = { '', '', '', '', '', ''};
            if ~isempty( app.usrdata.img.mri)
                labels = { {'P', 'A'}, {'L', 'R'}, {'I', 'S'}};
                ars = app.usrdata.img.mri.vox2ras0( 1:3, [2, 1, 3]);
                [~, midx] = max( abs( ars), [], 2);
                for k = 1 : 3
                    ind = 2*(k-1) + 1 : 2*k;
                    if ars( k, midx(k)) > 0
                        s( ind) = labels{ midx(k)}( [1, 2]);
                    else
                        s( ind) = labels{ midx(k)}( [2, 1]);
                    end
                end
            end

            % set orientation labels
            p = {'Enable', 'on', 'Visible', 'on'};
            set( [app.YL_L, app.ZL_L], 'Text', s{1}, p{:});
            set( [app.YR_L, app.ZR_L], 'Text', s{2}, p{:});
            set( [app.XL_L, app.ZB_L], 'Text', s{3}, p{:});
            set( [app.XR_L, app.ZT_L], 'Text', s{4}, p{:});
            set( [app.XB_L, app.YB_L], 'Text', s{5}, p{:});
            set( [app.XT_L, app.YT_L], 'Text', s{6}, p{:});
        end % func SetOriLabel


        function s = GetOrientation( app)
            s = '';
            if isempty( app.usrdata.img.mri)
                return;
            end

            M = app.usrdata.img.mri.vox2ras0( 1:3, 1:3);
            sz = sqrt( sum( M.^2, 1));
            M = M ./ sz;
            [~, loc] = max( abs( M), [], 1);
            txt = {{'L', 'R'}, {'P', 'A'}, {'I', 'S'}};
            M = sign( M);
            s = arrayfun( @(a) txt{ a}{ M( loc(a), a) == [-1, 1]}, 1:3, 'un', 0);
            s = lower( strjoin( s, ''));
        end


        function UpdateXYLims( app)
            % move current Cursor to center if it's out of view
            c = app.usrdata.cursor;
            for k = 1 : 3
                if k == 1
                    x = c.j;
                    y = c.k;
                elseif k == 2
                    x = c.i;
                    y = c.k;
                else
                    x = c.i;
                    y = c.j;
                end

                app.usrdata.ax( k).xlim = CenterView( app, app.usrdata.ax( k).xlim, x);
                app.usrdata.ax( k).ylim = CenterView( app, app.usrdata.ax( k).ylim, y);
            end
        end % func UpdateXYLims


        function lim = CenterView(~, lim, loc)
            d = diff( lim);
            if loc < lim(1) || loc > lim( 2)
                lim = loc + d*[-0.5, 0.5];
            end
        end


        function CrossHair(app, h, img_h, pnt)
            % h, handle to axes
            % img_h, handle to imagesc object
            % pnt, [x, y] point
            x = pnt(1);
            y = pnt(2);
            x_lim = get( h, 'xlim');
            y_lim = get( h, 'ylim');
            [~, ~, xpx_w, ypx_w] = GetImgInfo( app, img_h);
            ln_prop = {'color', [252, 102, 3]/255, 'linewidth', 0.5};

            if x == x_lim(1)
                line( h, x_lim + xpx_w, [y, y], ln_prop{:});

            elseif x == x_lim(2)
                line( h, [x_lim(1), x] - xpx_w, [y, y], ln_prop{:});

            else
                line( h, [x_lim(1), x] - xpx_w, [y, y], ln_prop{:});
                line( h, [x, x_lim(2)] + xpx_w, [y, y], ln_prop{:});
            end

            if y == y_lim(1)
                line( h, [x, x], y_lim + ypx_w, ln_prop{:});

            elseif y == y_lim(2)
                line( h, [x, x], [y_lim(1), y] - ypx_w, ln_prop{:});

            else
                line( h, [x, x], [y, y_lim(2)] + ypx_w, ln_prop{:});
                line( h, [x, x], [y_lim(1), y] - ypx_w, ln_prop{:});
            end
        end % function CrossHair


        function [xlim, ylim, xpx_w, ypx_w, xdata, ydata] = GetImgInfo( app, h)
            % h, handle to imagesc object
            [ylim, xlim] = size( get( h, 'CData'));
            [xpx_w, xdata] = GetPixelWidth( app, get( h, 'xdata'), xlim);
            [ypx_w, ydata] = GetPixelWidth( app, get( h, 'yData'), ylim);
        end


        function [raw_ind, i, j, k] = PlaceLabel( app, start_c, varargin)
            % varargin{1}, true | false, do peak search
            % varargin{2}, true | false, full space search

            opts = {'no', 'yes'};

            manu_model = opts{ app.MethManual_B.Value + 1};
            % return the cursor location
            use_exact = opts{ app.MethUseExact_B.Value + 1};
            init_center = opts{ app.CenterEst_CB.Value + 1};
            return_peak = opts{ app.MethUsePk_B.Value + 1};

            if strcmpi( return_peak, 'yes')
                % force to do peak search
                do_peak_search = 'yes';
            else
                % return peak within a sphere
                do_peak_search = opts{ app.PkSearch_CB.Value + 1};
            end

            if nargin > 2 && varargin{1}
                % auto mode, disable center estimate and peak search
                do_peak_search = 'no';
                init_center = 'no';
            end

            full_search = false;
            if nargin > 3
                full_search = varargin{2};
            end

            if isempty( start_c)
                % current location
                i = app.usrdata.cursor.i;
                j = app.usrdata.cursor.j;
                k = app.usrdata.cursor.k;

            else
                % automatic find all
                i = start_c(1);
                j = start_c(2);
                k = start_c(3);

                manu_model = 'no';
                return_peak = 'no';
                use_exact = 'no';
                do_peak_search = 'yes';
                init_center = 'yes';
            end

            if strcmpi( init_center, 'yes')
                est_center = true;
            else
                est_center = false;
            end

            pk_r = app.PkSerachRange_E.Value;
            pk_typ = app.PkSearchType_DD.Value;

            % center estimate radius
            center_thr = app.CenterEstRadius_E.Value;
            center_r = app.CenterEstThr_E.Value;

            % threshold for separating overlaps
            clust_r = app.OverlapRadius_E.Value;
            overlap_thr = app.OverlapThr_E.Value;

            dims = app.usrdata.img.info.dims;
            if strcmpi( manu_model, 'yes')
                % hand-drawn ROI
                [xData, yData, zData] = RetriveBoxData( app);
                if isempty( xData) || isempty( yData) || isempty( zData)
                    raw_ind = [];
                    return;

                else
                    xlo = max( [1, xData(1)]);
                    xhi = min( [dims(1), xData(2)]);
                    ylo = max( [1, yData(1)]);
                    yhi = min( [dims(2), yData(2)]);
                    zlo = max( [1, zData(1)]);
                    zhi = min( [dims(3), zData(2)]);
                    sel_x = xlo : xhi;
                    sel_y = ylo : yhi;
                    sel_z = zlo : zhi;

                    val = app.usrdata.img.mri.vol( sel_x, sel_y, sel_z);
                    [mval, midx] = max( val(:));
                    [ii, jj, kk] = ind2sub( size( val), midx);
                    i = sel_x( ii);
                    j = sel_y( jj);
                    k = sel_z( kk);
                end

            else
                % automatic mode
                if strcmpi( use_exact, 'yes')
                    raw_ind = sub2ind( app.usrdata.img.info.dims, i, j, k);
                    return;
                end

                vsz = app.usrdata.img.mri.volres( :)';

                if strcmpi( pk_typ, 'sphere') && est_center
                    N = max( [pk_r, center_r, clust_r]);

                elseif ~strcmpi( pk_typ, 'sphere') && est_center
                    N = max( [center_r, clust_r]);

                elseif strcmpi( pk_typ, 'sphere') && ~est_center
                    N = pk_r;

                else
                    % do nothing
                end

                N = max( [3, N]);
                N = 10 * N / min( vsz);

                if strcmpi( pk_typ, 'sphere') || est_center
                    if full_search
                        [XX, YY, ZZ] = ndgrid( 1:dims(1), 1:dims(2), 1:dims(3));
                    else
                        N = ceil( N);
                        xlo = max( [1, i-N]);
                        xhi = min( [i+N, dims(1)]);
                        ylo = max( [1, j-N]);
                        yhi = min( [j+N, dims(2)]);
                        zlo = max( [1, k-N]);
                        zhi = min( [k+N, dims(3)]);
                        [XX, YY, ZZ] = ndgrid( xlo:xhi, ylo:yhi, zlo:zhi);
                    end

                    sz_XX = size( XX);
                    XX = XX(:);
                    YY = YY(:);
                    ZZ = ZZ(:);
                end

                if ~strcmpi( do_peak_search, 'yes')
                    mval = app.usrdata.img.mri.vol( i, j, k);

                else
                    if strcmpi( pk_typ, 'sphere')
                        d = [XX-i, YY-j, ZZ-k] .* vsz;
                        d = sqrt( sum( d.^2, 2));
                        d = find( d <= pk_r);
                        [sel_x, sel_y, sel_z] = SubRange( app, d, sz_XX, [xlo, ylo, zlo]);

                    else
                        d = ceil( pk_r / vsz(:));
                        sel_x = (max([1, i-d(1)]) : min( [i+d(1), dims(1)]))';
                        sel_y = (max([1, j-d(2)]) : min( [j+d(2), dims(2)]))';
                        sel_z = (max([1, k-d(3)]) : min( [k+d(3), dims(3)]))';
                    end

                    val = app.usrdata.img.mri.vol( sel_x, sel_y, sel_z);
                    [mval, midx] = max( val(:));
                    [ii, jj, kk] = ind2sub( size( val), midx);

                    % update the location if use max within a sphere was set
                    i = sel_x( ii);
                    j = sel_y( jj);
                    k = sel_z( kk);

                    if strcmpi( return_peak, 'yes')
                        raw_ind = sub2ind( dims, i, j, k);
                        return;
                    end
                end

                if est_center
                    % % estimate of an inital center-of-mass center with a small sphere
                    d = [XX-i, YY-j, ZZ-k] .* vsz;
                    d = sqrt( sum( d.^2, 2));
                    d = find( d <= pk_r);
                    [sel_x, sel_y, sel_z] = SubRange( app, d, sz_XX, [xlo, ylo, zlo]);

                    % choose a threshold to cluster current sphere
                    val = app.usrdata.img.mri.vol( sel_x, sel_y, sel_z);
                    if center_thr < 1
                        CC = bwconncomp( val >= mval * center_thr);
                        tmp_loc = GetClustIndex( app, sel_x, sel_y, sel_z, [i, j, k], CC);
                        ind = CC.PixelIdxList{ tmp_loc};
                    else
                        ind = find( 0*val(:) > -1);
                    end

                    [ii, jj, kk] = ind2sub( size( val), ind);
                    col_val = val( ind);
                    col_val = col_val ./ sum( col_val);
                    if any( isnan( col_val))
                        i = round( mean( sel_x( ii)));
                        j = round( mean( sel_y( jj)));
                        k = round( mean( sel_z( kk)));
                    else
                        i = round( sel_x( ii)' * col_val);
                        j = round( sel_y( jj)' * col_val);
                        k = round( sel_z( kk)' * col_val);
                    end
                end

                % % draw a large ROI centered at current location
                % (peak, initial_center, or current location)
                d = [XX-i, YY-j, ZZ-k] .* vsz;
                d = sqrt( sum( d.^2, 2));
                d = find( d <= pk_r);
                [sel_x, sel_y, sel_z] = SubRange( app, d, sz_XX, [xlo, ylo, zlo]);
            end

            val = app.usrdata.img.mri.vol( sel_x, sel_y, sel_z);
            if overlap_thr > 1
                % use absolute value
                thr = min( [mval, overlap_thr]);
            else
                % percentage of maximal
                thr = mval * overlap_thr;
            end
            
            % thr = min( [app.usrdata.img.mri.vol( i, j, k), thr]);
            CC = bwconncomp( val >= thr);
            % index of the cluster in raw image
            c_loc = GetClustIndex( app, sel_x, sel_y, sel_z, [i, j, k], CC);
            if isempty( c_loc)
                raw_ind = [];
                return;
            end

            sigma = app.FinalSigma_E.Value;
            val_sz = size( val);
            if sigma > 0 && app.MethManual_B.Value == 0
                % smooth the roi to increase its size
                tmp = zeros( val_sz);
                tmp( CC.PixelIdxList{ c_loc}) = 1;
                volSmooth = imgaussfilt3( tmp, sigma);
                [ii, jj, kk] = ind2sub( val_sz, find( volSmooth(:) > 0));

            else
                [ii, jj, kk] = ind2sub( val_sz, CC.PixelIdxList{ c_loc});
            end

            % voxel index in raw 3d image
            raw_ind = sub2ind( dims, sel_x( ii), sel_y( jj), sel_z( kk));
        end % function


        function  loc = GetClustIndex( ~, sel_x, sel_y, sel_z, c, CC)
            % index of the cluster that contains the cursor coordinate
            % sel_x, sel_y, sel_z: subscriptions
            % c, 1x3 coordinates
            x = find( sel_x == c(1));
            y = find( sel_y == c(2));
            z = find( sel_z == c(3));
            if any( [isempty(x), isempty( y), isempty( z)])
                loc = [];
                return;
            end

            ind = sub2ind( CC.ImageSize, x, y, z);
            loc = find( cellfun( @(x) ismember( ind, x), CC.PixelIdxList));
        end


        function [x, y, z] = SubRange( ~, ind, dims, sloc)
            % index of cubic ROI
            [x, y, z] = ind2sub( dims, ind);
            x = (min( x) : max( x))';
            y = (min( y) : max( y))';
            z = (min( z) : max( z))';

            if nargin > 3
                x = x + sloc(1) - 1;
                y = y + sloc(2) - 1;
                z = z + sloc(3) - 1;
            end
        end


        function in_pnt = InRange( ~, rng, pnt)
            %   rng, [x_lo, x_hi, y_lo, y_hi]
            %   pnt, (x, y)
            x_ok = prod( pnt(1) - sort( rng(1:2))) <= 0;
            y_ok = prod( pnt(2) - sort( rng(3:4))) <= 0;
            in_pnt = x_ok && y_ok;
        end


        function is_in = PntInAxes( ~, pnt, p)
            % pnt, [lo, hi]
            % pos, [l, b, w, h]
            x = pnt(1); y = pnt(2);
            is_in = x >= p(1) && x <= p(1)+p(3) && y >= p(2) && y <= p(4)+p(2);
        end


        function [xData, yData, zData] = RetriveBoxData(app)
            % retrive boundaries from two boxes
            xData = [];
            yData = [];
            zData = [];

            box1_ok = ~isempty( app.usrdata.box(1).data);
            box2_ok = ~isempty( app.usrdata.box(2).data);
            box3_ok = ~isempty( app.usrdata.box(3).data);
            if box1_ok && box2_ok
                xData = app.usrdata.box( 2).data{1};
                yData = app.usrdata.box( 1).data{1};
                zData = app.usrdata.box( 1).data{2};

            elseif box1_ok && box3_ok
                xData = app.usrdata.box( 3).data{1};
                yData = app.usrdata.box( 1).data{1};
                zData = app.usrdata.box( 1).data{2};

            elseif box2_ok && box3_ok
                xData = app.usrdata.box( 2).data{1};
                zData = app.usrdata.box( 2).data{2};
                yData = app.usrdata.box( 3).data{2};

            elseif box1_ok
                yData = app.usrdata.box( 1).data{1};
                zData = app.usrdata.box( 1).data{2};

            elseif box2_ok
                xData = app.usrdata.box( 2).data{1};
                zData = app.usrdata.box( 2).data{2};

            elseif box3_ok
                xData = app.usrdata.box( 3).data{1};
                yData = app.usrdata.box( 3).data{2};
            end

            if ~isempty( xData)
                xData = sort( xData);
                xData = [floor( xData(1)), ceil( xData(end))];
            end

            if ~isempty( yData)
                yData = sort( yData);
                yData = [floor( yData(1)), ceil( yData(end))];
            end

            if ~isempty( zData)
                zData = sort( zData);
                zData = [floor( zData(1)), ceil( zData(end))];
            end
        end % function


        function elec = GetResults( app)
            % retrieve results
            elec = [];
            if isempty( app.usrdata.img.mri) || isempty( app.usrdata.elec.rois)
                return;
            end

            % sort labels
            [elec.label, sidx] = SortStr( app, app.usrdata.elec.roiname);
            elec.elecpos = [];
            elec.rois = app.usrdata.elec.rois( sidx);

            vox2ras = app.usrdata.img.mri.vox2ras1;
            elec.coord.img2mm = vox2ras;
            img_sz = app.usrdata.img.info.dims;
            elec.coord.dims = img_sz;

            % for backward compatiblity
            ind = cellfun( @(x) isfield( app.usrdata.elec, x), app.usrdata.opt_fields);
            opt_field = app.usrdata.opt_fields( ind);
            for k = 1 : length( opt_field)
                if ~isempty( app.usrdata.elec.( opt_field{k}))
                    elec.( opt_field{k}) = app.usrdata.elec.( opt_field{k});
                end
            end

            % innominate user name
            filename = app.usrdata.img.info.filename;
            home_d = getenv( 'HOME');
            if ~isempty( home_d) && ~startsWith( filename, '~')
                usrnam = sprintf( '^%s', home_d);
                elec.coord.src_img = regexprep( filename, usrnam, '~');
            else
                elec.coord.src_img = filename;
            end

            % get max, mess center etc
            [X, Y, Z] = ndgrid( 1 : img_sz(1), 1 : img_sz(2), 1 : img_sz(3));
            X = X(:);
            Y = Y(:);
            Z = Z(:);
            elec.coord.peak = [];
            elec.coord.unweighted_center = [];
            elec.coord.weighted_center = [];
            elec.coord.img_peak = [];
            elec.coord.img_unweighted_center = [];
            elec.coord.img_weighted_center = [];
            for roi_idx = 1 : length( elec.rois)
                roi_ind = elec.rois{ roi_idx};
                val = app.usrdata.img.mri.vol( roi_ind);
                [~, midx] = max( val);
                [xx, yy, zz] = ind2sub( img_sz, roi_ind( midx));
                elec.coord.img_peak( roi_idx, 1:3) = [xx, yy, zz];

                pk_img = vox2ras * [xx; yy; zz; 1];
                elec.coord.peak( roi_idx, 1:3) = pk_img( 1:3);
               
                roi_x = X( roi_ind);
                roi_y = Y( roi_ind);
                roi_z = Z( roi_ind);

                % unweighted center
                c = mean( [roi_x, roi_y, roi_z], 1);

                % weighted center
                if length( val) == 1 || max( val) == min( val)
                    val = ones( size( val));
                else
                    % normalize to 0-1
                    val = (val - min( val)) / (max( val) - min( val));                    
                end

                val = val ./ sum( val);

                weight_c = [roi_x'*val(:), roi_y'*val(:), roi_z'*val(:)];

                elec.coord.img_unweighted_center( roi_idx, 1:3) = c;
                elec.coord.img_weighted_center( roi_idx, 1:3) = weight_c;

                c_img = vox2ras * [c(:); 1];
                elec.coord.unweighted_center( roi_idx, 1:3) = c_img( 1:3);

                tmp = vox2ras * [weight_c(:); 1];
                elec.coord.weighted_center( roi_idx, 1:3) = tmp( 1:3);
            end

            % use unweighted center in mm as default
            c_typ = app.prefer.coord_type;
            elec.elecpos.coord_type = c_typ;
            if strcmpi( c_typ, 'peak')
                elec.elecpos.coord = elec.coord.peak;

            elseif strcmpi( c_typ, 'weighted_center')
                elec.elecpos.coord = elec.coord.weighted_center;

            else % unweighted_center
                elec.elecpos.coord = elec.coord.unweighted_center;
            end

            elec.elecpos.img2mm = vox2ras;
            elec.elecpos.dims = app.usrdata.img.info.dims;
            elec.elecpos.unit = 'mm';
            elec.elecpos.sourcefile = app.usrdata.img.info.filename;

            % Raw CT Image
            if app.YesSaveSrc_B.Value == 1
                elec.elecpos.srcimg = app.usrdata.img.mri;
            end

            elec = rmfield( elec, 'coord');

            % % hand-drawn ROIs
            % elec.mask = app.usrdata.draw.mask;
            % elec.tool = 'eplace';
        end % function GetResults


        function [B, I] = SortStr( ~, s)
            % Sort string cell array
            % Input
            %   s = {'A1', 'B2', 'B1', 'A2', 'A10', 'C', '12'};
            %
            % Output
            %   B = s(I);

            if ~iscellstr( s) || ~isvector( s) || isempty( s)
                B = s;
                I = find( ones( size( s)));
                return;
            end

            sz = size( s);
            s = s(:);

            % Test whether end with a number
            sloc = cellfun( @(x) regexp(x, '\d*$', 'once'), s, 'un', 0);

            % Sort those labels end with numbers
            ind = cellfun( @(x) ~isempty( x) && x > 1, sloc);

            % Labels not end with a number
            us = s( ~ind);
            us_orig_ind = find( ~ind);
            [us, sidx] = sort( us);
            us_orig_ind = us_orig_ind( sidx);

            % Labels end with a number
            ss = s( ind);
            ss_sloc = sloc( ind);
            ss_orig_ind = find( ind);

            ss_prefix = cellfun( @(x, y) x( 1:y-1), ss, ss_sloc, 'un', 0);
            ss_nb = cellfun( @(x, y) str2double( x( y:end)), ss, ss_sloc);
            u_lbl = unique( ss_prefix);

            B = cell( length( ss), 1);
            I = nan( length( ss), 1);
            for k = 1 : length( u_lbl)
                loc = ismember( ss_prefix, u_lbl{ k});
                tmp_nb = ss_nb( loc);
                tmp_s = ss( loc);
                tmp_ind = ss_orig_ind( loc);

                [~, sidx] = sort( tmp_nb);
                tmp_s = tmp_s( sidx);
                tmp_ind = tmp_ind( sidx);

                cnt = sum( ismember( ss_prefix, u_lbl( 1:k)));
                ind = cnt - sum( loc) + 1 : cnt;
                B( ind) = tmp_s;
                I( ind) = tmp_ind;
            end

            B = [B; us];
            I = [I; us_orig_ind];

            B = reshape( B, sz);
            I = reshape( I, sz);
        end % function


        function InitializeYoke( app)
            % update yoke figure for each call of this function
            cla( app.UIAxesAllElec);
            set( app.UIAxesAllElec, 'xtick', [], 'ytick', []);
            axis( app.UIAxesAllElec, 'off');
            axis( app.UIAxesAllElec, 'equal');
            axis( app.UIAxesAllElec, 'vis3d');           
        end


        function Init3dFitPlot( app)
            % update yoke figure for each call of this function
            cla( app.UIAxes3dLineFit);
            set( app.UIAxes3dLineFit, 'xtick', [], 'ytick', []);
            axis( app.UIAxes3dLineFit, 'off');
            axis( app.UIAxes3dLineFit, 'equal');
            axis( app.UIAxes3dLineFit, 'vis3d');            
        end


        function UpdateYokeCoord( app)
            if isempty( app.usrdata.img.mri)
                return;
            end

            N = length( app.usrdata.elec.rois);
            app.usrdata.yoke_coord = zeros( N, 3);
            for k = 1 : N
                [xx, yy, zz] = ind2sub( app.usrdata.img.info.dims, app.usrdata.elec.rois{ k});
                tmp = mean( [xx(:), yy(:), zz(:)], 1)';
                tmp = app.usrdata.img.mri.vox2ras1 * [tmp; 1];
                app.usrdata.yoke_coord( k, :) = tmp( 1:3);
            end
        end % function update yoke coordinates


        function lbl_nam = LabelName( ~, lbls, len, varargin)
            % Generate label name label***
            % lbls = {'label001', 'label003', 'labelfa34', '14'}; existing labels
            if nargin < 3
                len = [];
            end

            if nargin < 4
                prefix = 'label';
            else
                prefix = varargin{1};
            end

            if isempty( lbls)
                lbl_nam = sprintf( '%s%03d', prefix, 1);
                return;
            end

            sloc = cellfun( @(x) regexp(x, ['^', prefix, '\d*$'], 'once'), lbls, 'un', 0);
            ind = cellfun( @(x) ~isempty( x), sloc);
            lbls = lbls( ind);
            lbls = cellfun( @(x) strrep( x, prefix, ''), lbls, 'un', 0);
            N = min( cellfun( @length, lbls));
            N = max( [N, 3, len]);
            val = cellfun( @str2double, lbls);
            k = 1 : 10^N - 1;
            k( ismember( k, val)) = [];
            while isempty( k)
                N = N + 1;
                k = 1 : 10^N - 1;
                k( ismember( k, val)) = [];
            end

            fmt = sprintf( '%s%%0%dd', prefix, N);
            lbl_nam = sprintf( fmt, k(1));
        end % function


        function DeleteBox(app)
            % Delete manual box
            for k = 1 : 3
                if isempty( app.usrdata.box( k).h) || ~isvalid( app.usrdata.box( k).h)
                    continue;
                end

                delete( app.usrdata.box( k).h);
                app.usrdata.box( k).h = [];
                app.usrdata.box( k).data = [];
                app.usrdata.box( k).start = 'yes';
                app.usrdata.box( k).move = 'no';
            end

            Redraw( app);
        end% function

        
        function [d, c] = CalDistance( app, cur_lbl, varargin)
            % Calculate distance between electrodes
            % 
            % d, pair-wise distance
            % c, coordinates for electrode, which would be unweighted
            % center, peak, or something else.

            if nargin < 3
                cal_dist = true;
            else
                cal_dist = varargin{1};
            end

            if isempty( cur_lbl)
                d = nan;
                return;
            end

            [~, loc] = ismember( cur_lbl, app.usrdata.elec.roiname);
            N = length( loc);
            c = zeros( N, 3);
            for k = 1 : N
                roi_ind = app.usrdata.elec.rois{ loc( k)}(:);
                [xx, yy, zz] = ind2sub( size( app.usrdata.img.mri.vol), roi_ind);

                if app.UnweigCenter_B.Value == 1
                    % unweighted center
                    c( k, :) = mean( [xx, yy, zz], 1);

                elseif app.Peak_B.Value == 1
                    % use peak
                    [~, midx] = max( app.usrdata.img.mri.vol( roi_ind));
                    [pk_x, pk_y, pk_z] = ind2sub( img_sz, roi_ind( midx));
                    c( k, :) = [pk_x, pk_y, pk_z];

                else
                    val = app.usrdata.img.mri.vol( roi_ind);
                    lo = min( val);
                    hi = max( val);
                    if numel( val) == 1 || lo == hi
                        val = ones( size( val));
                    else
                        val = (val - lo) / (hi - lo);                        
                    end

                    val = val / sum( val);
                    c( k, :) = [xx(:)'*val(:), yy(:)'*val(:), zz(:)'*val(:)];
                end
            end

            if N == 1
                c( 2, :) =[app.usrdata.cursor.i, app.usrdata.cursor.j, app.usrdata.cursor.k];
            end

            if ~cal_dist
                d = [];
                return;
            end

            % voxel resolution
            vol_res = app.usrdata.img.mri.volres(:)';
            if N <= 2 
                d = diff( c, [], 1) .* vol_res;
                d = sqrt( sum( d .^ 2));
                return;
            end

            % pairwise distance
            comb = nchoosek( 1:N, 2);
            % number of all combinations
            M = size( comb, 1);
            d = nan( M, 1);
            for k = 1 : M
                tmp = diff( c( comb( k, :), :), [], 1) .* vol_res;
                d( k) = sqrt( sum( tmp .^ 2));
            end
        end % calculate distance

        
        function UpdateYokePlot( app)
            cla( app.UIAxesAllElec);
            yc = app.usrdata.yoke_coord;
            r = IGroupLabels( app, app.usrdata.elec.roiname);
            for k = 1 : size( r, 1)
                kk = r{ k, 2};
                elec_prefix = r{ k, 1};
                if strcmpi( elec_prefix, 'label')
                    ck = app.usrdata.marker_color;
                else
                    ck = rand( 1, 3);
                end

                line( app.UIAxesAllElec, yc( kk, 1), yc( kk, 2), yc( kk, 3),...
                    'marker', 'o', 'linestyle', 'none',...
                    'color', app.usrdata.marker_color,...
                    'markersize', app.usrdata.marker_sz,...
                    'markerfacecolor', ck);

                if strcmpi( elec_prefix, 'label')
                    continue;
                end

                txt_c = yc( kk( end), :);
                text( app.UIAxesAllElec, txt_c( 1), txt_c( 2), txt_c( 3), elec_prefix,...
                    'fontsize', 16, 'fontname', 'arial', 'Fontweight', 'bold');
            end
  
            % cursor location
            hold( app.UIAxesAllElec, 'on');
            vox2ras = app.usrdata.img.mri.vox2ras1;
            c = vox2ras * [app.usrdata.cursor.i;
                app.usrdata.cursor.j; app.usrdata.cursor.k; 1];
            c = c( 1:3)';
            line( app.UIAxesAllElec, c(1), c(2), c(3), ...
                'marker', 'o',...
                'color', app.usrdata.cur_marker_color,...
                'markersize', 1.5*app.usrdata.marker_sz,...
                'markerfacecolor', app.usrdata.cur_marker_color);

            axis( app.UIAxesAllElec, 'off');
            axis( app.UIAxesAllElec, 'equal');
            axis( app.UIAxesAllElec, 'vis3d');

            c = cat( 1, yc, c);
            clo = min( c, [], 1);
            chi = max( c, [], 1);

            lo = vox2ras * [0; 0; 0; 1];
            hi = vox2ras * [size( app.usrdata.img.mri.vol)'+1; 1];            
            x_lim = sort( [lo(1), hi(1)]);
            y_lim = sort( [lo(2), hi(2)]);
            z_lim = sort( [lo(3), hi(3)]);

            x_lim(1) = min( [x_lim(1), clo(1)]);
            x_lim(2) = max( [x_lim(2), chi(1)]);
            y_lim(1) = min( [y_lim(1), clo(2)]);
            y_lim(2) = max( [y_lim(2), chi(2)]);
            z_lim(1) = min( [z_lim(1), clo(3)]);
            z_lim(2) = max( [z_lim(2), chi(3)]);

            set( app.UIAxesAllElec, 'xlim', x_lim, 'ylim', y_lim, 'zlim', z_lim);
        end % update 3d dot plot


        function r = IGroupLabels( ~, lbl)
            % Parse labels
            %
            % Usage
            %   r = IGroupLabels( elec_name);
            %   r = IGroupLabels( elec_name, 1);
            %
            % Input
            %   elec_name, cell array of strings
            %              each string starts with letters and end with numbers
            %              {'A0', 'A1', 'B2', 'B4', 'AH1', 'AH2'};
            %   varargin{1}, 1|0
            %                1, print results to command window
            %
            % Output
            %   result, cell array
            %       {prefix, location, names, index}
            %
            % GZ

            r = {};
            if nargin < 1 || isempty( lbl)
                return;
            end

            if ~iscellstr( lbl) || ~isvector( lbl)
                warning( 'Input must be a cell array of strings');                
                return;
            end

            lbl = lbl(:);
            nb_lbls = length( lbl);
            if length( unique( lbl)) ~= nb_lbls
                error( 'Input has non-unique labels.');
            end
            
            lbl_prefix = repmat( {''}, nb_lbls, 1);
            lbl_num = nan( nb_lbls, 1);
            unsort_lbl = repmat( {''}, nb_lbls, 1);
            cnt = 1;
            % location of labels which are not LetterNumber
            invalid_loc = [];
            for k = 1 : nb_lbls
                cur_lbl = lbl{ k};
                % prefix
                s = cur_lbl;
                s( regexp( cur_lbl,'[0-9]')) = [];

                % index
                d_str = regexp( lbl{ k}, '\d*', 'Match');
                d = str2double( d_str);

                lbl_prefix{ k, 1} = s;
                if ~isempty( d)
                    lbl_num( k) = d;
                else
                    lbl_num( k) = nan;
                end

                % it's made of LetterNumber
                no_ltr = isempty( regexp( cur_lbl, '^[a-zA-Z]', 'once'));
                if isempty( s) || length( d) ~= 1 || length( d_str) ~= 1 ...
                        || no_ltr || ~strcmp( cur_lbl, [s, d_str{1}])

                    unsort_lbl{ cnt, 1} = cur_lbl;
                    cnt = cnt + 1;
                    invalid_loc = cat( 1, invalid_loc, k);
                end
            end

            lbl_prefix( invalid_loc) = [];
            lbl_num( invalid_loc) = [];
            orig_loc = (1 : nb_lbls)';
            orig_loc( invalid_loc) = [];
            unsort_lbl( cnt : end) = [];

            u_lbl = unique( lbl_prefix, 'stable');
            r = cell( length( u_lbl), 3);
            for k =  1 : length( u_lbl)
                loc = cellfun( @(x) strcmp( x, u_lbl{ k}), lbl_prefix, 'un', 0);
                loc = cell2mat( loc);
                loc = find( loc);

                % prefix
                r{ k, 1} = u_lbl{ k};
                % location in original label list
                r{ k, 2} = orig_loc( loc);
                % full label name
                r{ k, 3} = lbl( orig_loc( loc));
                % label index
                r{ k, 4} = lbl_num( loc);
                % a stats
                r{ k, 5} = [u_lbl{ k}, ' ', num2str( length( loc))];
            end

            if isempty( unsort_lbl)
                return;
            end

            N = size( r, 1);
            M = length( unsort_lbl);
            ind = N + 1 : N + M;
            r( ind, 1) = unsort_lbl;
            r( ind, 2) = mat2cell( find( ismember( lbl, unsort_lbl)), ones( M, 1));
            r( ind, 3) = unsort_lbl;
        end % function

    end % Private Method


    methods (Access = public)

        function [px_width, ydata] = GetPixelWidth( ~, ydata, ylim)
            px_width = 0.5;
            if ylim <= 1
                return;
            end

            if length( ydata) == 2
                px_width = diff( ydata) / (2*(ylim-1));
                ydata = linspace( ydata(1), ydata(2), ylim);

            elseif length( ydata) > 2
                px_width = diff( ydata) / 2;
            end

            px_width = px_width(1);
        end % function get pixel widht

    end % publick methods


    methods( Access = private)
        function SetDefaultVis( app, status)
            % Object visibility
            objs = findall( app.UIFigure);
            for k = 1 : length( objs)
                is_vis = isprop( objs( k), 'Visible');
                typ_ok = ~ismember( lower( objs(k).Type), {'uimenu', 'figure'});
                if is_vis && typ_ok
                    objs(k).Visible = status;
                end
            end

            % UImenu, Open and Quit are always visible and enbaled
            set( [app.Save_M, app.Close_M, app.Load_M, app.About_M, app.Stop_M],...
                'Visible', 'on', 'Enable', status);

            set( [app.Open_M, app.Quit_M], 'Visible', 'on', 'Enable', 'on');
            if strcmpi( status, 'off')
                set( [app.Mask_E, app.IntensRange_T], 'Value', '');
            end
        end
    end % methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ct_img)
            disableDefaultInteractivity( app.UIAxesX);
            disableDefaultInteractivity( app.UIAxesY);
            disableDefaultInteractivity( app.UIAxesZ);

            app.UIAxesX.Toolbar.Visible = 'off';
            app.UIAxesY.Toolbar.Visible = 'off';
            app.UIAxesZ.Toolbar.Visible = 'off';

            app.prefer.coord_type = 'unweighted_center';
            app.UnweigCenter_B.Value = 1;

            % Font color
            font_ck = [1 1 1];

            % background color of edit boxes
            % it has to be rgb
            bk_ck = [ 0 0 0];

            % color, font size etc
            app.UIFigure.Color = bk_ck;
            % app.EplaceTab.BackgroundColor = bk_ck;
            % app.PreferencesTab.BackgroundColor = bk_ck;            
            app.GridLayout6.BackgroundColor = bk_ck;
            app.MainGridLayout.BackgroundColor = bk_ck;            
            for c = [app.GridLayout6, app.MainGridLayout]
                obj = findall( c);
                for k = 1 : length( obj)

                    if isempty( obj( k)) || isempty( obj( k).Type) || ...
                            ismember( obj( k).Type, {'axestoolbar'})
                        continue;
                    end

                    if isprop( obj(k), 'FontColor')
                        set( obj(k), 'FontColor', font_ck);
                    end

                    if isprop( obj(k), 'ForegroundColor')
                        set( obj(k), 'ForegroundColor', font_ck);
                    end

                    if isprop( obj(k), 'BackgroundColor')
                        set( obj(k), 'BackgroundColor', bk_ck);
                    end
                end
            end

            app.usrdata.msg_fontcolor = 'y';
            app.usrdata.err_fontcolor = 'w';
            app.usrdata.distance_format = '%.2f';

            app.status_label = [app.Status_L, app.Status_L2];

            Initializeusrdata( app, 'yes');
            InitializeYoke( app);
            Init3dFitPlot( app);            

            % Object visibility
            SetDefaultVis( app, 'off');

            % Freesurfer Matlab toolbox
            fs_home = getenv( 'FREESURFER_HOME');
            if ~isempty( fs_home) && ~exist( 'MRIread', 'file')
                addpath( fullfile( fs_home, 'matlab'));
            end

            if nargin > 1
                app.usrdata.source_image = ct_img;
                Open_MSelected( app);
            else
                app.usrdata.source_image = '';
            end            
        end

        % Menu selected function: Open_M
        function Open_MSelected(app, event)
            % If current results are saved
            if ~isempty( app.usrdata.elec.rois) && strcmpi( app.usrdata.results_saved, 'no')
                txt = 'Continue without saving results?';
                answer = questdlg( txt, '', 'Yes', 'No', 'No');

                if isempty( answer) || strcmpi( answer, 'no')
                    return;
                end
            end

            % Make everything invisible
            SetDefaultVis( app, 'off');

            err_font_ck = app.usrdata.err_fontcolor;
            msg_font_ck = app.usrdata.msg_fontcolor;

            % Load CT image    app.Status_L.Visible = 'on';
            set( app.Tab_G, 'visible', 'on');
            set( app.status_label, 'Text', '', 'Visible', 'on', 'FontColor', msg_font_ck);
            set( app.Status_L, 'Text', 'Reading source image ...');
            drawnow;

            [status, msg] = LoadImage( app);
            if status ~= 0
                % Failed to load selected image
                set( app.status_label, 'Text', msg, 'FontColor', err_font_ck);
                drawnow;
                return;
            end

            % Check orientation
            img_orient = GetOrientation( app);
            if ~ismember( lower( img_orient), {'las', 'ras'})
                msg = sprintf( ['The orientation of the image %s ' ...
                    'does not match the standard template (MNI152). ' ...
                    'This may or may not be a probelm. ' ...
                    'Use FSL''s fslreorient2std to fix it.'], img_orient);

                set( app.status_label, 'Text', msg, 'FontColor', err_font_ck);
                drawnow;

                dlg = warndlg( msg, 'Non-standard image orientation');
                waitfor( dlg);
            end

            % Everything should be ok for now.
            msg = sprintf( '%s %s', msg, 'Plotting, please wait ...');
            set( app.status_label, 'Text', msg, 'FontColor', msg_font_ck);
            drawnow;

            % Do not initialize app.usrdata.img
            Initializeusrdata( app, 'no');

            % resolution
            resol = app.usrdata.img.mri.volres;

            % starting point
            sz = size( app.usrdata.img.mri.vol);
            app.usrdata.img.info.dims = sz;

            crs_c = round( sz( 1 : 3)/2);
            app.usrdata.cursor.img = crs_c;

            app.usrdata.cursor.i = crs_c(1);
            app.usrdata.cursor.j = crs_c(2);
            app.usrdata.cursor.k = crs_c(3);           

            tmp = app.usrdata.img.mri.vox2ras1 * [crs_c, 1]';
            app.usrdata.cursor.std = tmp(1:3);

            app.usrdata.ax(1).xlim = [1, sz(2)];
            app.usrdata.ax(1).ylim = [1, sz(3)];
            app.usrdata.ax(1).aspectratio = resol( [3, 2, 1]);

            app.usrdata.ax(2).xlim = [1, sz(1)];
            app.usrdata.ax(2).ylim = [1, sz(3)];
            app.usrdata.ax(2).aspectratio = resol( [3, 1, 2]);

            app.usrdata.ax(3).xlim = [1, sz(1)];
            app.usrdata.ax(3).ylim = [1, sz(2)];
            app.usrdata.ax(3).aspectratio = resol( [2, 1, 3]);

            for k = 1:3
                app.usrdata.ax(k).raw_xlim = app.usrdata.ax(k).xlim;
                app.usrdata.ax(k).raw_ylim = app.usrdata.ax(k).ylim;
            end

            % intensity range
            % c_lim = prctile( app.usrdata.img.mri.vol(:), [10, 99.9]);
            [~, edge] = histcounts( app.usrdata.img.mri.vol(:), 999);
            c_lim = edge( [300, 990]);
            app.usrdata.imgdisp.clim = c_lim;
            app.MinIntens_E.Value = app.usrdata.imgdisp.clim(1);
            app.MaxIntens_E.Value = app.usrdata.imgdisp.clim(2);

            app.ImgX_SP.Limits = [1, sz(1)];
            app.ImgY_SP.Limits = [1, sz(2)];
            app.ImgZ_SP.Limits = [1, sz(3)];

            [lo, hi] = bounds( app.usrdata.img.mri.vol, 'all');
            app.IntensRange_T.Value = sprintf( '[%.0f, %.0f]', lo, hi);
            AutoPlaceThrSelectionChanged( app);

            % label color
            ColorTable( app);
            SetOriLabel( app);
            UpdateCursor( app);
            Redraw(app);

            set( app.status_label, 'Text', 'Ready.', 'FontColor', msg_font_ck);
            SetDefaultVis( app, 'on');

            disableDefaultInteractivity( app.UIAxesX);
            disableDefaultInteractivity( app.UIAxesY);
            disableDefaultInteractivity( app.UIAxesZ);
            app.UIAxesX.Toolbar.Visible = 'off';
            app.UIAxesY.Toolbar.Visible = 'off'; 
            app.UIAxesZ.Toolbar.Visible = 'off'; 
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % Which Axes was the click in
            pnt = app.UIFigure.CurrentPoint;
            if PntInAxes( app, pnt, app.UIAxesX.Position)
                ax_ind = 1;

            elseif PntInAxes( app, pnt, app.UIAxesY.Position)
                ax_ind = 2;

            elseif PntInAxes( app, pnt, app.UIAxesZ.Position)
                ax_ind = 3;

            else
                ax_ind = [];
            end

            app.usrdata.cur_ax = ax_ind;
            app.usrdata.last_ax = ax_ind;

            % Image orientation warning
            if ~isempty( findobj( 'tag', 'MessageBox'))
                return;
            end

            % manual cubic mask plotting with right mouse click
            is_rmouse = strcmpi( app.UIFigure.SelectionType, 'alt') ;
            if ~isempty( ax_ind) && is_rmouse && app.MethManual_B.Value == 1
                x = app.usrdata.axh( ax_ind).CurrentPoint( 1, 1);
                y = app.usrdata.axh( ax_ind).CurrentPoint( 1, 2);

                if isempty( app.usrdata.box( ax_ind).data)
                    % is box is empty, start plotting
                    app.usrdata.box( ax_ind).start = 'no';
                    xData = x * ones( 1, 5);
                    yData = y * ones( 1, 5);
                    hold( app.usrdata.axh( ax_ind), 'on');
                    app.usrdata.box( ax_ind).h = line( app.usrdata.axh( ax_ind), xData, yData);
                    app.usrdata.box( ax_ind).data = { xData, yData};

                    set( app.usrdata.box( ax_ind).h,...
                        'linestyle', app.usrdata.box_linestyle,...
                        'linewidth', app.usrdata.box_linewidth,...
                        'color', app.usrdata.box_color);

                else
                    % a box already exists
                    x_lim = sort( app.usrdata.box( ax_ind).data{1});
                    x_lim = x_lim( [1, end]);
                    y_lim = sort( app.usrdata.box( ax_ind).data{2});
                    y_lim = y_lim( [1, end]);

                    if InRange( app, [x_lim, y_lim], [x, y])
                        % delete the box if a click was within the axes
                        % but outside of the box
                        delete( app.usrdata.box( ax_ind).h);
                        app.usrdata.box( ax_ind).h = [];
                        app.usrdata.box( ax_ind).data = [];
                        app.usrdata.box( ax_ind).start = 'yes';
                        app.usrdata.box( ax_ind).move = 'no';

                    else
                        % enable moving option and change the cursor to "hand"
                        app.usrdata.box( ax_ind).move = 'yes';
                        set( app.UIFigure, 'Pointer', 'hand');
                    end
                end % whether the box is empty
            end % right mouse click

        end

        % Value changed function: MinIntens_E
        function MinIntens_EValueChanged(app, event)
            val = app.MinIntens_E.Value;
            if val < app.usrdata.imgdisp.clim(2) && val > -inf
                app.usrdata.imgdisp.clim( 1) = val;
                clim( app.UIAxesX, app.usrdata.imgdisp.clim);
                clim( app.UIAxesY, app.usrdata.imgdisp.clim);
                clim( app.UIAxesZ, app.usrdata.imgdisp.clim);

            else
                app.MinIntens_E.Value = app.usrdata.imgdisp.clim(1);
            end
        end

        % Value changed function: MaxIntens_E
        function MaxIntens_EValueChanged(app, event)
            val = app.MaxIntens_E.Value;
            if val > app.usrdata.imgdisp.clim(1) && val < inf
                app.usrdata.imgdisp.clim( 2) = val;
                clim( app.UIAxesX, app.usrdata.imgdisp.clim);
                clim( app.UIAxesY, app.usrdata.imgdisp.clim);
                clim( app.UIAxesZ, app.usrdata.imgdisp.clim);

            else
                app.MaxIntens_E.Value = app.usrdata.imgdisp.clim(2);
            end
        end

        % Value changed function: Scale_SP
        function Scale_SPValueChanged(app, event)
            app.usrdata.edit_focus = false;

            if isempty( app.usrdata.img.mri)
                return;
            end

            scale = 100/app.Scale_SP.Value;
            if abs( scale - 1) < eps
                % 100 - default view
                msg = 'Center cusor only works when scale is not 100%.';
                for ax_idx = 1 : 3
                    app.usrdata.ax( ax_idx).xlim = app.usrdata.ax( ax_idx).raw_xlim;
                    app.usrdata.ax( ax_idx).ylim = app.usrdata.ax( ax_idx).raw_ylim;
                end

            else
                msg = 'Cusor was set to the center.';
                i = app.usrdata.cursor.i;
                j = app.usrdata.cursor.j;
                k = app.usrdata.cursor.k;
                x = [j; i; i];
                y = [k; k; j];
                for ax_idx = 1 : 3
                    xlim_r = diff( app.usrdata.ax( ax_idx).raw_xlim);
                    ylim_r = diff( app.usrdata.ax( ax_idx).raw_ylim);
                    xlim = scale * xlim_r * [-0.5, 0.5];
                    ylim = scale * ylim_r * [-0.5, 0.5];
                    app.usrdata.ax( ax_idx).xlim = xlim + x( ax_idx);
                    app.usrdata.ax( ax_idx).ylim = ylim + y( ax_idx);
                end
            end

            Redraw( app);
            set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.msg_fontcolor);
            drawnow;
        end

        % Value changed function: ImgX_SP
        function ImgX_SPValueChanged(app, event)
            val = app.ImgX_SP.Value;
            if val >= 1 && val <= app.usrdata.img.info.dims(1)
                app.usrdata.cursor.i = val;
                UpdateCursor(app);
            else
                app.ImgX_SP.Value = app.usrdata.cursor.i;
            end
        end

        % Value changed function: ImgY_SP
        function ImgY_SPValueChanged(app, event)
            val = app.ImgY_SP.Value;
            if val >= 1 && val <= app.usrdata.img.info.dims(2)
                app.usrdata.cursor.j = val;
                UpdateCursor(app);
            else
                app.ImgY_SP.Value = app.usrdata.cursor.j;
            end
        end

        % Value changed function: ImgZ_SP
        function ImgZ_SPValueChanged(app, event)
            val = app.ImgZ_SP.Value;
            if val >= 1 && val <= app.usrdata.img.info.dims(3)
                app.usrdata.cursor.k = val;
                UpdateCursor(app);
            else
                app.ImgZ_SP.Value = app.usrdata.cursor.k;
            end
        end

        % Value changed function: StdX_E
        function StdX_EValueChanged(app, event)
            val = app.StdX_E.Value;
            c = app.usrdata.cursor.std(:);
            if isscalar( val) && ~isnan( val)
                val = app.usrdata.img.mri.vox2ras1 \ [val; c(2:3); 1];
                val = round( val(1));

                if val >= 1 && val <= app.usrdata.img.info.dims(2)
                    app.usrdata.cursor.i = val;
                    UpdateCursor(app);
                end

            else
                app.StdX_E.Value = c(1);
            end
        end

        % Value changed function: StdY_E
        function StdY_EValueChanged(app, event)
            val = app.StdY_E.Value;
            c = app.usrdata.cursor.std(:);
            if isscalar( val) && ~isnan( val)
                val = app.usrdata.img.mri.vox2ras1 \ [ c(1); val; c(3); 1];
                val = round( val(2));
                if val >= 1 && val <= app.usrdata.img.info.dims(2)
                    app.usrdata.cursor.j = val;
                    UpdateCursor(app);
                end

            else
                app.StdY_E.Value = c(2);
            end
        end

        % Value changed function: StdZ_E
        function StdZ_EValueChanged(app, event)
            val = app.StdZ_E.Value;
            c = app.usrdata.cursor.std(:);
            if isscalar( val) && ~isnan( val)
                val = app.usrdata.img.mri.vox2ras1 \ [ c(1:2); val; 1];
                val = round( val(3));
                if  val >= 1 && val <= app.usrdata.img.info.dims(3)
                    app.usrdata.cursor.k =  val;
                    UpdateCursor(app);
                end

            else
                app.StdZ_E.Value = c(3);
            end
        end

        % Button pushed function: CenterCursor_B
        function CenterCursor_BPushed(app, event)
            % make cursor to be center
            Scale_SPValueChanged( app);
        end

        % Button pushed function: AddElec_B
        function AddElec_BButtonPushed(app, event)
            ck = app.usrdata.msg_fontcolor;
            set( app.status_label, 'Text', 'Identifying electrodes ...', 'FontColor', ck);
            drawnow;

            [clust_ind, i, j, k] = PlaceLabel( app, []);
            if isempty( clust_ind)
                [clust_ind, i, j, k] = PlaceLabel( app, [], true);
            end

            if isempty( clust_ind)
                set( app.status_label, 'Text', 'No electrode found.', 'FontColor', ck);
                drawnow;
                return;
            end

            % Check whether it exists or overlaps with existing ones
            is_overlap = cellfun( @(x) length( intersect( x, clust_ind)), app.usrdata.elec.rois);
            if any( is_overlap == length( clust_ind))
                msg = 'Warning. Electrode already exists.';
                set( app.status_label, 'Text', msg, 'FontColor', ck);
                drawnow;
                return;
            end

            % overlaps
            exist_lbl = strjoin( app.usrdata.elec.roiname( is_overlap > 0), '; ');

            % Add new electrode
            lbl_nam = LabelName( app, app.usrdata.elec.roiname);
            N = length( app.usrdata.elec.rois);
            app.usrdata.elec.rois{ N + 1, 1} = clust_ind(:);
            app.usrdata.elec.roiname{ N + 1} = lbl_nam;
            set( app.Label, 'Items', app.usrdata.elec.roiname, 'Value', lbl_nam);

            ColorTable( app);

            app.usrdata.cursor.i = i;
            app.usrdata.cursor.j = j;
            app.usrdata.cursor.k = k;
            app.usrdata.cursor.img = [i, j, k];
            UpdateCursor(app);

            if ~isempty( exist_lbl)
                msg = sprintf( 'Warning. %s overlaps with %s.', lbl_nam, exist_lbl);
            else
                msg = sprintf( '%s was added.', lbl_nam);
            end

            set( app.status_label, 'Text', msg, 'FontColor', ck);
            drawnow;

            app.usrdata.results_saved = 'no';
        end

        % Button pushed function: PreviewElec_B
        function PreviewElec_BButtonPushed(app, event)
            clust_ind = PlaceLabel( app, []);
            if isempty( clust_ind)
                % try again
                clust_ind = PlaceLabel( app, [], true);
            end

            if ~isempty( clust_ind)
                Redraw( app, clust_ind);
                msg = 'Previewing electrode ...';
            else
                msg = 'No electrode found ...';
            end

            set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.msg_fontcolor);
            drawnow;

            % Show the distance with current selected label (1 selected)
        end

        % Button pushed function: RmElec_B
        function RmElec_BButtonPushed(app, event)
            lbl_rm = app.Label.Value;
            if isempty( lbl_rm)
                return;
            end

            answer = questdlg( 'Remove Electrodes', ...
                'Are you sure to remove selected electrodes?', ...
                'Yes', 'No', 'No');

            if strcmpi( answer, 'no')
                set( app.status_label, 'Text', 'Electodes removal cancelled', ...
                    'FontColor', app.usrdata.err_fontcolor);
                return;
            end

            N = length( app.usrdata.elec.roiname);
            [~, loc] = ismember( lbl_rm, app.usrdata.elec.roiname);
            app.usrdata.elec.roiname( loc) = [];
            app.usrdata.elec.rois( loc) = [];

            if isempty( app.usrdata.elec.roiname)
                app.Label.Items = {};
                app.Label.Value = {};

            else                
                app.Label.Items = app.usrdata.elec.roiname;
                loc = min( [max(loc) + 1, N]) - length( loc);
                loc = max( [1, loc]);
                loc = min( [loc, numel( app.usrdata.elec.roiname)]);
                app.Label.Value = app.usrdata.elec.roiname{ loc};
            end

            ColorTable( app);
            UpdateYokeCoord( app);
            Redraw( app);

            nb_rm = length( lbl_rm);
            if nb_rm < 4
                lbl_rm = strjoin( lbl_rm, '; ');
                txt = [lbl_rm, ' were removed.'];
            else
                txt = sprintf( '%d labels were removed', nb_rm);
            end

            app.usrdata.results_saved = 'no';

            set( app.status_label, 'Text', txt, ...
                'FontColor', app.usrdata.err_fontcolor);
            drawnow;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            try
                if isempty( app.usrdata.cur_ax)
                    return;
                end
            catch
                return;
            end

            ax_idx = app.usrdata.cur_ax;
            x = app.usrdata.axh( ax_idx).CurrentPoint( 1, 1);
            y = app.usrdata.axh( ax_idx).CurrentPoint( 1, 2);
            xlim = get( app.usrdata.axh( ax_idx), 'xlim');
            ylim = get( app.usrdata.axh( ax_idx), 'ylim');

            hold( app.usrdata.axh( ax_idx), 'on');
            if strcmpi( app.UIFigure.SelectionType, 'normal')
                % draw ROI voxel by voxel

            elseif strcmpi( app.UIFigure.SelectionType, 'alt')
                % right mouse click
                is_box_exist = ~isempty( app.usrdata.box( ax_idx).h);
                is_start_box = strcmpi( app.usrdata.box( ax_idx).start, 'no');
                is_move_box = strcmpi( app.usrdata.box( ax_idx).move, 'yes');
                is_box_data = ~isempty( app.usrdata.box( ax_idx).data);

                if is_box_exist && is_start_box
                    % Plot a new box
                    xData = get( app.usrdata.box( ax_idx).h, 'xData');
                    yData = get( app.usrdata.box( ax_idx).h, 'yData');
                    x = max( [xlim(1), x]);
                    xData( 2:3) = min( [xlim(2), x]);

                    y = max( [ylim(1), y]);
                    yData( 3:4) = min( [ylim(2), y]);

                    set( app.usrdata.box( ax_idx).h, 'xData', xData, 'yData', yData);
                    app.usrdata.box( ax_idx).data = {xData, yData};

                elseif is_box_data && is_move_box
                    % Moving an existing box
                    xData = app.usrdata.box( ax_idx).data{1};
                    yData = app.usrdata.box( ax_idx).data{2};

                    % move box, to-do
                    [lo, hi] = bounds( xData);
                    d = (hi - lo) / 2;
                    if x >= xlim(1)+d && x <= xlim(2) - d
                        xData = x + d *[-1, 1, 1, -1, -1];
                    end

                    [lo, hi] = bounds( yData);
                    d = (hi - lo) / 2;
                    if y >= ylim(1) + d && y <= ylim(2) - d
                        yData = y + d * [-1, -1, 1, 1, -1];
                    end

                    set( app.usrdata.box( ax_idx).h, 'xData', xData, 'yData', yData);
                    app.usrdata.box( ax_idx).data = {xData, yData};

                else
                    % do nothing
                end
            end % click type
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            set( app.UIFigure, 'Pointer', 'arrow');
            if isempty( app.usrdata.img.mri)
                return;
            end

            update_plot = false;
            app.usrdata.axes_click = 'no';
            ax_idx = app.usrdata.cur_ax;
            if ~isempty( ax_idx)
                % if isobject( app.usrdata.box( ax_idx).h)
                %     delete(  app.usrdata.box( ax_idx).h);
                %     app.usrdata.box( ax_idx).h = [];
                % end
                %
                % app.usrdata.box( ax_idx).h = [];
                app.usrdata.box( ax_idx).start = 'yes';
                app.usrdata.box( ax_idx).move = 'no';

                x = round( app.usrdata.axh( ax_idx).CurrentPoint( 1, 1));
                y = round( app.usrdata.axh( ax_idx).CurrentPoint( 1, 2));

                if strcmpi( get( app.UIFigure, 'SelectionType'), 'normal')
                    % Left mouse click within axes
                    x_ok = x >= 1 && x <= app.usrdata.ax( ax_idx).raw_xlim(2);
                    y_ok = y >= 1 && y <= app.usrdata.ax( ax_idx).raw_ylim(2);
                    app.usrdata.last_ax = [];
                    if x_ok && y_ok
                        % button up point was within one of the axeses
                        app.usrdata.last_ax = ax_idx;
                        if ax_idx == 1
                            app.usrdata.cursor.j = x;
                            app.usrdata.cursor.k = y;

                        elseif ax_idx == 2
                            app.usrdata.cursor.i = x;
                            app.usrdata.cursor.k = y;

                        else
                            app.usrdata.cursor.i = x;
                            app.usrdata.cursor.j = y;
                        end
                    end

                    app.usrdata.axes_click = 'yes';
                    update_plot = true;

                elseif strcmpi( get( app.UIFigure, 'SelectionType'), 'alt')
                    update_plot = true;
                end % left click
            end % current axes

            % set axes selected to none
            app.usrdata.cur_ax = [];

            if strcmpi( app.usrdata.draw.start, 'yes')
                % not used for now
                update_plot = true;
                app.usrdata.draw.start = 'no';
                app.usrdata.cursor.i = app.usrdata.draw.init_i;
                app.usrdata.cursor.j = app.usrdata.draw.init_j;
                app.usrdata.cursor.k = app.usrdata.draw.init_k;
                app.usrdata.cursor.img = app.usrdata.draw.init_img;
                for ax_idx = 1 : 3
                    delete( app.usrdata.draw.h( ax_idx));
                end
            end

            tmp = app.usrdata.img.mri.vox2ras1 * [app.usrdata.cursor.img(:); 1];
            app.usrdata.cursor.std = tmp( 1:3);
            if update_plot
                Redraw( app);
            end
        end

        % Value changed function: Label
        function LabelValueChanged(app, event)
            cur_lbl = app.Label.Value;
            font_ck = app.usrdata.msg_fontcolor;

            nb_sel = length( cur_lbl);
            N = length( app.usrdata.elec.roiname);
            if any( [nb_sel, N] == 0)
                set( app.status_label, 'Text', '', 'FontColor', font_ck);
                drawnow;
                return;
            end

            [~, loc] = ismember( cur_lbl, app.usrdata.elec.roiname);
            if nb_sel == 1
                % Move cursor to current label if only one label is selected
                [X, Y, Z] = ind2sub( app.usrdata.img.info.dims, app.usrdata.elec.rois{ loc});
                app.usrdata.cursor.i = round( mean(X));
                app.usrdata.cursor.j = round( mean(Y));
                app.usrdata.cursor.k = round( mean(Z));
                app.usrdata.cursor.img = round( [mean( X), mean( Y), mean( Z)]);
                UpdateCursor( app);

                s = sprintf( 'Current label (%d/%d): %s.', loc, N, strjoin( cur_lbl, ' '));
                set( app.status_label, 'Text', s, 'FontColor', font_ck);
                drawnow;
                return;
            end


            d = CalDistance( app, cur_lbl);
            d_fmt = app.usrdata.distance_format;
            if nb_sel == 2   
                fmt = sprintf( 'Distance: %s mm.', d_fmt);
                s = sprintf( fmt, d);
                set( app.status_label, 'Text', s, 'FontColor', font_ck);
                set( app.LabelInfo, 'Text', s, 'FontColor', font_ck);

            else % nb_sel > 2
                avg = max( d) / (nb_sel - 1);
                fmt = sprintf( 'Average distance: %s mm. Range: %s - %s.', d_fmt, d_fmt, d_fmt);
                s = sprintf( fmt, avg, min( d), max( d));
                set( app.status_label, 'Text', s, 'FontColor', font_ck);                
                fmt = sprintf( '%s [%s, %s] mm.', d_fmt, d_fmt, d_fmt);
                s = sprintf( fmt, avg, min( d), max( d));
                set( app.LabelInfo, 'Text', s, 'FontColor', font_ck);
            end

            if app.ElecFitEstDistCB.Value == 1
                app.ElecFitDistEF.Value = max( d) / (nb_sel - 1);
            end

            drawnow;

        end

        % Button pushed function: ReLabel_B
        function ReLabel_BPushed(app, event)
            str = app.ReLabel_TE.Value;
            if isempty( str)
                msg = 'A new label name must be specified.';
                set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.err_fontcolor);
                drawnow;
                return;
            end

            if length( app.Label.Value) ~= 1
                msg = 'Only one label can be renamed at a time.';
                set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.err_fontcolor);
                drawnow;
                return;
            end

            if any( ismember( str, app.usrdata.elec.roiname)) && ~strcmp( app.Label.Value, str)
                txt = sprintf( '%s already exists, please use a different name.', str);
                % txt_ck = app.usrdata.err_fontcolor;
                txt_ck = app.usrdata.msg_fontcolor;

                set( app.LabelInfo, 'Text', ...
                    sprintf( '%s exists already.', str), 'fontcolor', txt_ck);

            else
                app.usrdata.results_saved = 'no';

                [~, loc] = ismember( app.Label.Value, app.usrdata.elec.roiname);
                orig_lbl = app.usrdata.elec.roiname{ loc};
                app.usrdata.elec.roiname{ loc} = str;
                set( app.Label, 'Items', app.usrdata.elec.roiname, 'Value', str);

                txt = sprintf( '%s was renamed to %s', orig_lbl, str);
                txt_ck = app.usrdata.msg_fontcolor;
                if app.LabelAutoMove_CB.Value == 1
                    N = min( [loc+1, length( app.Label.Items)]);
                    app.Label.Value = app.Label.Items{ N};
                end

                set( app.LabelInfo, 'Text', ...
                    sprintf( '%s >> %s', orig_lbl, str), 'fontcolor', txt_ck);
            end

            set( app.status_label, 'Text', txt, 'FontColor', txt_ck);
            drawnow;
        end

        % Button pushed function: AssignLbl_B
        function AssignLbl_BPushed(app, event)
            if length( app.Label.Value) ~= 1
                set( app.status_label, 'FontColor', app.usrdata.err_fontcolor,...
                    'Text', 'Select a label to rename.');
                drawnow;
                return;
            end

            if isempty( app.RealLabel.Items) || isempty( app.RealLabel.Value)
                return;
            end

            if ~ismember( app.RealLabel.Value, app.Label.Items)
                % avoid name conflications
                tmp = app.Label.Items;
                [~, loc] = ismember( app.Label.Value, tmp);
                orig_lbl = app.Label.Value;
                tmp{ loc} = app.RealLabel.Value;

                app.Label.Items = tmp;
                app.Label.Value = tmp{ loc};

                txt = [orig_lbl{1}, ' was renamed to ', tmp{ loc}];
                txt_ck = app.usrdata.err_fontcolor;

                % move dest to next
                [~, tmp_loc] = ismember( app.RealLabel.Value, app.RealLabel.Items);
                tmp_loc =  min([ tmp_loc+1, length( app.RealLabel.Items)]);
                app.RealLabel.Value = app.RealLabel.Items( tmp_loc);
                app.usrdata.elec.roiname = app.Label.Items;

                if app.LabelAutoMove_CB.Value == 1
                    app.Label.Value = tmp{ min([ loc+1, length( tmp)])};
                end

                app.usrdata.results_saved = 'no';

            else
                % errordlg( [app.RealLabel.Value, ' already exists.'], 'Label conflication');
                txt = [app.RealLabel.Value, ' already exists. '];
                txt_ck = app.usrdata.err_fontcolor;
            end

            set( app.status_label, 'Text', txt, 'FontColor', txt_ck);
            drawnow;
        end

        % Menu selected function: Save_M
        function Save_MSelected(app, event)
            set( app.status_label, 'FontColor', app.usrdata.msg_fontcolor,...
                'Text', 'Retrieving results ... please wait ...');
            drawnow;

            elec = GetResults( app);
            if isempty( elec)
                set( app.status_label, 'FontColor', app.usrdata.err_fontcolor,...
                    'Text', 'No results to save.');
                drawnow;
                return;
            end

            [p, nam] = fileparts( app.usrdata.img.info.filename);
            [~, fold_nam] = fileparts( p);            
            savefile = fullfile( p, sprintf( '%s_%s_eplace.mat', fold_nam, nam));
            [fnam, p] = uiputfile( '*.mat', 'Save Eplace results.', savefile);
            if isequal( fnam,0) || isequal( p, 0)
                set(app.status_label, 'FontColor', app.usrdata.msg_fontcolor,...
                    'Text', 'Results saving was cancelled.');
            else
                if strcmpi( app.usrdata.re_reg_required, 'yes')
                    elec = rmfield( elec, app.usrdata.opt_fields);
                end

                savefile = fullfile( p, fnam);
                save( savefile, 'elec');
                app.usrdata.results_saved = 'yes';
                set( app.status_label, 'FontColor', app.usrdata.msg_fontcolor,...
                    'Text', ['Results saved as: ', savefile]);
            end

            drawnow;
        end

        % Menu selected function: Close_M
        function Close_MSelected(app, event)
            % make sure results were saved before closing current dataset
            answer = 'yes';
            if ~isempty( app.usrdata.elec.rois) && strcmpi( app.usrdata.results_saved, 'no')
                answer = questdlg( 'Close current image without saving results?', ...
                    '', 'Yes', 'No', 'No');
            end

            if strcmpi( answer, 'no')
                return;
            end

            % close all electrodes 3d plot axes
            cla( app.UIAxesAllElec)

            % close 3d line fitting result axes
            cla( app.UIAxes3dLineFit);

            set( [app.Label, app.RealLabel], 'Value', {}, 'Items', {});
            set( app.ThresholdIntensity, 'Text', '');
            app.IntensRange_T.Value = '';
            app.Mask_E.Value = '';

            % Make everything invisible
            SetDefaultVis( app, 'off');
            app.usrdata.results_saved = 'yes';
            app.usrdata.source_image = '';
        end

        % Menu selected function: Quit_M
        function Quit_MSelected(app, event)
            Close_MSelected( app, []);

            % everything is invisible if the current image was closed successfully
            if strcmpi( app.Close_M.Enable, 'off')
                delete( app);
            end
        end

        % Menu selected function: Elec_M
        function Elec_MSelected(app, event)
            set( app.status_label, 'FontColor', app.usrdata.msg_fontcolor, ...
                'Text', 'Loading electrode coordinate file.');
            drawnow;

            [filename, p] = uigetfile( '*.mat', 'Load EPLACE result file.');
            if isequal( filename, 0) && isequal( p, 0)
                set(app.status_label, 'FontColor', app.usrdata.msg_fontcolor, ...
                    'Text', 'Cancelled. Loading EPLACE results.');

                drawnow;
                return;
            end

            elec = load( fullfile( p, filename));

            err_fc = app.usrdata.err_fontcolor;
            if ~isstruct( elec)
                set( app.status_label, 'FontColor', err_fc, 'Text', ...
                    'Error. Failed to load electrode coordinate file.');

                drawnow;
                return;
            end

            nam = fieldnames( elec);
            if length( nam) ~= 1
                set( app.status_label, 'FontColor', err_fc, 'Text', ...
                    'Error. Failed to load electrode coordinate file.');

                drawnow;
                return;
            end

            elec = elec.( nam{1});
            if ~isstruct( elec) || ~isfield( elec, 'label') || ~isfield( elec, 'rois')
                set( app.status_label, 'FontColor', err_fc, 'Text', ...
                    'Error. Electrode coordinate file was not created by EPLACE.');

                drawnow;
                return;
            end

            if ~iscell( elec.label) || ~iscell( elec.rois) || ...
                    length( elec.label) ~= length( elec.rois)
                set( app.status_label, 'FontColor', err_fc,  'Text', ...
                    'Error. Electrode coordinate file was not created by EPLACE.');

                drawnow;
                return;
            end

            % ROI index must be within range
            max_ind = prod( app.usrdata.img.info.dims);
            elec.rois = cellfun( @(x) round( x), elec.rois, 'un', 0);
            ind = cellfun( @(x) any( x < 1 | x > max_ind), elec.rois);
            if any( ind)
                msg = 'Electrode coordinate out of range.';
                set( app.status_label, 'Text', msg, 'FontColor', err_fc);
                drawnow;
                return;
            end

            if isfield( elec.elecpos, 'coord_type')
                c_typ = lower( elec.elecpos.coord_type);
                
                if strcmpi( c_typ, 'peak')
                    app.Peak_B.Value = 1;

                elseif ismember( c_typ, {'weighted center', 'weighted_center'})
                    app.WeigCenter_B.Value = 1;

                else
                    % unweighted center
                    app.UnweigCenter_B.Value = 1;
                end
            end

            % It should be good now
            app.usrdata.elec.roiname = elec.label;
            app.usrdata.elec.rois = elec.rois;
            app.usrdata.results_saved = 'yes';

            % Need to think about this
            % reg, proj etc. these fields need to be updated if labels were
            % renamed or new electrodes are added.
            % For now, these fields will be removed when the electrodes are saved
            % Remember to re-run registration and projection
            for k = 1 : length( app.usrdata.opt_fields)
                nam = app.usrdata.opt_fields{ k};
                if isfield( elec, nam)
                    app.usrdata.elec.( nam) = elec.( nam);
                end
            end

            app.Label.Items = app.usrdata.elec.roiname;
            if ~isempty( app.Label.Items)
                app.Label.Value = app.Label.Items(1);
            else
                app.Label.Value = {};
            end

            ColorTable( app);

            % update existing yoke fiugre
            InitializeYoke( app);
            UpdateYokeCoord( app);
            Redraw( app);

            set( app.status_label, 'FontColor', app.usrdata.msg_fontcolor, ...
                'Text', 'Ready. Electrodes loaded successfully.');
            drawnow;
        end

        % Menu selected function: Label_M
        function Label_MSelected(app, event)
            % load label
            [filename, p] = uigetfile( '*.txt', 'Select label file');
            if isequal( filename, 0) || isequal( p, 0)
                set( app.status_label, 'Text', 'Labels loading canceled.', ...
                    'FontColor', app.usrdata.msg_fontcolor);
                return;
            end

            try
                lbls = importdata( fullfile( p, filename));
                set( app.RealLabel, 'Enable', 'on', 'Visible', 'on',...
                    'Items', lbls, 'Value', lbls{1});

                msg = 'Labels were loaded successfully.';
                ck = app.usrdata.msg_fontcolor;

            catch
                msg = 'Error. Failed to load labels.';
                ck = app.usrdata.err_fontcolor;
            end

            set( app.status_label, 'Text', msg, 'FontColor', ck);
            drawnow;
        end

        % Selection changed function: Method_BG
        function Method_BGSelectionChanged(app, event)
            if app.MethUseExact_B.Value == 1
                % use cursor location
                set( findall( app.LocalPkSearchPanel, '-property', 'enable'), 'enable', 'off');
                set( findall( app.CenterEstPanel, '-property', 'enable'), 'enable', 'off');
                set( findall( app.Panel_2, '-property', 'enable'), 'enable', 'off');
                app.FinalSigma_E.Enable = 'off';

            elseif app.MethManual_B.Value == 1
                set( findall( app.LocalPkSearchPanel, '-property', 'enable'), 'enable', 'off');
                set( findall( app.CenterEstPanel, '-property', 'enable'), 'enable', 'off');

                app.FinalSigma_EL.Enable = 'off';
                app.FinalSigma_E.Enable = 'off';

                set( findall( app.Panel_2, '-property', 'enable'), 'enable', 'on');
                app.RadiusE_4L.Enable = 'off';
                app.OverlapRadius_E.Enable = 'off';

            elseif app.MethUsePk_B.Value == 1
                % do a peak search
                set( findall( app.LocalPkSearchPanel, '-property', 'enable'), 'enable', 'on');
                app.PkSearch_CB.Value = 1;
                set( findall( app.CenterEstPanel, '-property', 'enable'), 'enable', 'off');
                set( findall( app.Panel_2, '-property', 'enable'), 'enable', 'off');
                app.FinalSigma_E.Enable = 'off';

            else
                % automatic method
                set( findall( app.LocalPkSearchPanel, '-property', 'enable'), 'enable', 'on');
                set( findall( app.CenterEstPanel, '-property', 'enable'), 'enable', 'on');
                set( findall( app.Panel_2, '-property', 'enable'), 'enable', 'on');
                app.FinalSigma_E.Enable = 'on';
            end

            if app.MethManual_B.Value ~= 1
                DeleteBox( app);
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            % ** Revision needed when current object feature becomes available **
            % ** Not yet in 2020b **
            if isempty( app.usrdata.img.mri)
                return;
            end

            tag = app.UIFigure.CurrentObject;

            i = app.usrdata.cursor.i;
            j = app.usrdata.cursor.j;
            k = app.usrdata.cursor.k;
            sz = app.usrdata.img.info.dims;
            
            try
                tag = tag.Tag;
            catch
                tag = '';
            end

            fig_focus = strcmpi( tag, 'main_tab');
            ax_focus = ismember( tag, {'ax_x', 'ax_y', 'ax_z'});
            ax_click = ~isempty( app.usrdata.last_ax);
            lbl_focus = strcmpi( tag, 'label');

            key = lower( event.Key);
            if ismember( key, {'uparrow', 'downarrow', 'leftarrow', 'rightarrow'})
                % navigation keys
                if ~isempty( tag)
                    update_cursor = true;
                    if ismember( tag, {'img_x', 'std_x'})
                        if strcmpi( key, 'uparrow')
                            i = min( [i+1, sz(1)]);
                        elseif strcmpi( key, 'downarrow')
                            i = max( [i-1, 1]);
                        end

                    elseif ismember( tag, {'img_y', 'std_y'})
                        if strcmpi( key, 'uparrow')
                            j = min( [j+1, sz(2)]);
                        elseif strcmpi( key, 'downarrow')
                            j = max( [j-1, 1]);
                        end

                    elseif ismember( tag, {'img_z', 'std_z'})
                        if strcmpi( key, 'uparrow')
                            k = min( [k+1, sz(3)]);
                        elseif strcmpi( key, 'downarrow')
                            k = max( [k-1, 1]);
                        end

                    else
                        update_cursor = false;
                        [~, loc] = ismember( app.Label.Value, app.Label.Items);
                        if ismember( key, {'uparrow', 'leftarrow'})
                            new_loc = max( [loc-1, 1]);
                        else
                            len = length( app.Label.Items);
                            new_loc = min( [loc+1, len]);
                        end

                        app.Label.Value = app.Label.Items{ new_loc};
                        LabelValueChanged( app);
                    end % focus on object


                    app.usrdata.cursor.i = i;
                    app.usrdata.cursor.j = j;
                    app.usrdata.cursor.k = k;
                    app.usrdata.cursor.img = [i, j, k];

                    if update_cursor && ismember( key, {'downarrow', 'uparrow'})
                        UpdateCursor( app);
                    end


                elseif isempty( tag) || ax_focus % empty tag
                    % current axes was set
                    if app.usrdata.last_ax == 1
                        % move along y&z direction
                        if strcmpi( key, 'uparrow')
                            k = min( [k+1, sz(3)]);
                        elseif strcmpi( key, 'downarrow')
                            k = max( [k-1, 1]);
                        elseif strcmpi( key, 'leftarrow')
                            j = max( [1, j-1]);
                        else% strcmpi( key, 'rightarrow')
                            j = min( [j+1, sz(2)]);
                        end

                    elseif app.usrdata.last_ax == 2
                        % move along x&z direction
                        if strcmpi( key, 'uparrow')
                            k = min( [k+1, sz(3)]);
                        elseif strcmpi( key, 'downarrow')
                            k = max( [k-1, 1]);
                        elseif strcmpi( key, 'leftarrow')
                            i = max( [1, i-1]);
                        else% strcmpi( key, 'rightarrow')
                            i = min( [i+1, sz(1)]);
                        end

                    else
                        % move along x&y direction
                        if strcmpi( key, 'uparrow')
                            j = min( [j+1, sz(2)]);
                        elseif strcmpi( key, 'downarrow')
                            j = max( [j-1, 1]);
                        elseif strcmpi( key, 'leftarrow')
                            i = max( [1, i-1]);
                        else% strcmpi( key, 'rightarrow')
                            i = min( [i+1, sz(1)]);
                        end
                    end % the last click was within one of the axeses

                    app.usrdata.cursor.i = i;
                    app.usrdata.cursor.j = j;
                    app.usrdata.cursor.k = k;
                    UpdateCursor(app);
                end % focus is on edit box

            elseif strcmpi( key, 'a') && any( [fig_focus, ax_focus, ax_click])
                % add label
                AddElec_BButtonPushed( app);

            elseif strcmpi( key, 'c') && any( [fig_focus, ax_focus, ax_click])
                % center view
                Scale_SPValueChanged( app);

            elseif strcmpi( key, 'z')
                % center view
                app.Scale_SP.Value = app.Scale_SP.Value + 50;
                Scale_SPValueChanged( app);

            elseif strcmpi( key, 'd') 
                % center view
                app.Scale_SP.Value = max( [app.Scale_SP.Value - 50, 10]);
                Scale_SPValueChanged( app);

            elseif strcmpi( key, 'r')
                % remove label
                if any( [fig_focus, ax_focus, ax_click, lbl_focus])
                    RmElec_BButtonPushed( app);
                end

            elseif strcmpi( key, 'n')
                % rename current selected label
                if any( [fig_focus, ax_focus, ax_click, lbl_focus]) ...
                        && ~isempty( app.Label.Value)
                    str = inputdlg( 'Enter new label: ', 'Rename Label');
                    if ~isempty( str)
                        str = strjoin( str, '_');
                        str = strtrim( str);
                        old_val = app.ReLabel_TE.Value;
                        app.ReLabel_TE.Value = str;
                        ReLabel_BPushed( app);
                        app.ReLabel_TE.Value = old_val;
                    end
                end

            elseif strcmpi( key, 'comma')
                % assign label
                if any( [fig_focus, lbl_focus, strcmpi( tag, 'target_label')])
                    AssignLbl_BPushed( app, []);
                end

            else
                % Ctrl + L to load labels
                ctl_modif = strcmpi( event.Modifier, 'control');
                if all( [ctl_modif, strcmpi( key, 'l')])
                    Label_MSelected( app);
                end

                % Ctrl + M to load brain mask
                if all( [ctl_modif, strcmpi( key, 'm')])
                    LoadBrainmask( app);
                end
            end % key switch

        end

        % Value changing function: Scale_SP
        function Scale_SPValueChanging(app, event)
            app.usrdata.currentobject = 'ScaleSpinner';
            app.usrdata.edit_focus = true;
        end

        % Value changed function: ShowAll
        function ShowAllValueChanged(app, event)
            if isempty( app.usrdata.img.mri)
                return;
            end

            if isequal( app.ShowAll.Value, 1)
                InitializeYoke( app);
                Redraw(app);
                set( app.status_label, 'FontColor', app.usrdata.msg_fontcolor,...
                    'Text', '3d plot of electrodes is ready.');
                drawnow;
                return;
            end

            cla( app.UIAxesAllElec);
            Redraw( app);
            set( app.status_label, 'FontColor', app.usrdata.msg_fontcolor,...
                'Text',  '3d plot of electrodes was closed.');
            drawnow;
        end

        % Button pushed function: Sort_B
        function Sort_BPushed(app, event)
            % Sort labels
            if isempty( app.usrdata.elec.roiname)
                set( app.status_label, 'Text', 'No labels to sort.', ...
                    'FontColor', app.usrdata.err_fontcolor);
                drawnow;
                return;
            end

            [str, sidx] = SortStr( app, app.usrdata.elec.roiname);
            app.usrdata.elec.roiname = str;
            app.Label.Items = str;
            app.Label.Value = str{1};
            app.usrdata.elec.rois = app.usrdata.elec.rois( sidx);

            UpdateYokeCoord( app);
            Redraw( app);

            app.usrdata.results_saved = 'no';

            set( app.status_label, 'Text', 'Labels have been sorted.', ...
                'FontColor', app.usrdata.err_fontcolor);

            drawnow;
        end

        % Value changed function: ElecTransp_SLD
        function ElecTransp_SLDValueChanged(app, event)
            Redraw( app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Quit_MSelected( app, []);
        end

        % Value changed function: FontSz_E
        function FontSz_EValueChanged(app, event)
            objs = findall( app.UIFigure.Children);
            for k = 1 : length( objs)
                try
                    set( objs(k), 'FontSize', app.FontSz_E.Value);
                catch
                    % do nothing
                end
            end
        end

        % Selection changed function: CoordType_BG
        function CoordType_BGSelectionChanged(app, event)
            selectedButton = app.CoordType_BG.SelectedObject;
            app.prefer.coord_type = selectedButton.Text;
        end

        % Menu selected function: AutoPlace_M
        function AutoPlace_MSelected(app, event)
            set( app.status_label, 'Text', 'Not yet implemented.', ...
                'FontColor', app.usrdata.msg_fontcolor);
            drawnow;
            % return;

            thr = app.AutoThr_E.Value;
            if app.AutoThrAbs_RB.Value == 1
                % do nothing
            elseif app.AutoThrPeak_RB.Value == 1
                thr = thr * max( app.usrdata.img.mri.vol(:));

            elseif app.AutoThrPerc_RB.Value == 1
                thr = prctile( app.usrdata.img.mri.vol(:), thr);

            elseif app.AutoThrHist_RB.Value == 1
                thr = round( thr) * 10;
                [~, edge] = histcounts( app.userdata.img.mri.vol(:), 999);
                thr = edge( thr);

            else
                thr = 0.95 * max( app.usrdata.img.mri.vol(:));
            end

            % range threshold, 15mm
            % Upper boundary of the threshold, number of voxels
            sz_thr = app.ElecSz_E.Value;
            % Lower boundary of the threshold, number of voxels
            sz_lo = 2;

            if isempty( app.usrdata.brainmask)
                b = bwconncomp( app.usrdata.img.mri.vol > thr);
            else
                b = bwconncomp( app.usrdata.img.mri.vol .* app.usrdata.brainmask.vol > thr);
            end

            font_ck = app.usrdata.msg_fontcolor;
            if isempty( b.PixelIdxList)
                msg = 'No electrodes were found. Consider changing the threshold.';
                set( app.status_label, 'Text', msg, 'FontColor', font_ck);
                drawnow;
                return;
            end

            vol_dim = size( app.usrdata.img.mri.vol);
            % % constrains
            % c = nan( length( b.PixelIdxList), 3);
            % cnt = 0;
            % vol_res = app.usrdata.img.mri.volres;
            % % for k = 1 : length( b.PixelIdxList)
            %     [ii, jj, kk] = ind2sub( vol_dim, b.PixelIdxList{ k});
            %     tmp = [ii, jj, kk];
            %     rng = max( tmp) - min( tmp);
            %     rng = rng(:) .* vol_res(:);
            %     if any( rng > sz_thr) || all( rng < sz_lo)
            %         continue;
            %     end
            %
            %     % get the peak coordinate
            %     [~, midx] = max( app.usrdata.img.mri.vol( b.PixelIdxList{ k}));
            %     midx = b.PixelIdxList{ k}( midx);
            %     cnt = cnt + 1;
            %     [c( cnt, 1), c( cnt, 2), c( cnt, 3)] = ind2sub( vol_dim, midx);
            %     % c( cnt, :) = [mean( ii), mean( jj), mean( kk)];
            % end
            %
            % c( isnan( c( :, 1)), :) = [];
            % c = round( c);

            % just use peak within each cluster
            [~, midx] = cellfun( @(a) max( app.usrdata.img.mri.vol( a)), b.PixelIdxList);
            midx = arrayfun( @(a) b.PixelIdxList{ a}( midx( a)), 1 : length( midx));
            [ii, jj, kk] = ind2sub( vol_dim, midx);
            c = [ii(:), jj(:), kk(:)];           

            if isempty( c)
                msg = ['No electrodes were identified.' ...
                    ' Consider changing the electrode size threshold.'];
                set( app.status_label, 'Text', msg, 'FontColor', font_ck);
                drawnow;
                return;
            end

            set( app.status_label, 'Text', 'Localizing electrodes ...', ...
                'FontColor', font_ck);
            drawnow;

            nb_c = size( c, 1);
            len = floor( log10( nb_c + 1)) + 1;
            orig_N = length( app.usrdata.elec.rois);
            cnt = 0;
            for pk_idx = 1 : nb_c
                msg = sprintf( 'Auto placement: %d/%d ...', pk_idx, nb_c);
                set( app.status_label, 'Text', msg, 'FontColor', font_ck);
                drawnow;

                clust_ind = PlaceLabel( app, c( pk_idx, :), true);
                if isempty( clust_ind)
                    continue;
                end

                % Check whether it exists or overlaps with existing ones
                is_overlap = cellfun( @(x) ...
                    length( intersect( x, clust_ind)), app.usrdata.elec.rois);
                if any( is_overlap == length( clust_ind))
                    continue;
                end

                % Add electrode
                cnt = cnt + 1;
                N = length( app.usrdata.elec.rois) + 1;
                app.usrdata.elec.rois( N, 1) = {clust_ind};
                lbl_nam = LabelName( app, app.usrdata.elec.roiname, len);
                app.usrdata.elec.roiname{ N} = lbl_nam;
            end

            if cnt < 1
                msg = 'No additional electrodes were found. Consider changing the threshold.';
                set( app.status_label, 'Text', msg, 'FontColor', font_ck);
                drawnow;
                return;
            end

            % Combine labels with overlaps
            msg = 'Combing labels with overlaps ...';
            set( app.status_label, 'Text', msg, 'FontColor', font_ck);
            drawnow;

            %tmp = zeros( size( app.usrdata.img.mri.vol));
            tmp = zeros( app.usrdata.img.info.dims);
            ind = cat( 1, app.usrdata.elec.rois{:});
            tmp( ind) = 1;
            b = bwconncomp( tmp > 0);

            app.usrdata.elec.rois = b.PixelIdxList(:);
            N = length( b.PixelIdxList);
            len = floor( log10( N + 1)) + 1;
            fmt = sprintf( 'label%%0%dd', len);
            app.usrdata.elec.roiname = arrayfun( @(x) sprintf( fmt, x), 1:N, 'un', 0);
            set( app.Label, 'Items', app.usrdata.elec.roiname,...
                'Value', app.usrdata.elec.roiname{1});

            ColorTable( app);

            [i, j, k] = ind2sub( b.ImageSize, b.PixelIdxList{1}(1));
            app.usrdata.cursor.i = i;
            app.usrdata.cursor.j = j;
            app.usrdata.cursor.k = k;
            app.usrdata.cursor.img = [i, j, k];
            UpdateCursor(app);

            app.ShowAll.Value = 1;
            ShowAllValueChanged( app, []);

            msg = sprintf( 'The number of eletrodes changed from %d to %d', orig_N, N);
            set( app.status_label, 'Text', msg, 'FontColor', font_ck);
            drawnow;
        end

        % Menu selected function: Mask_M
        function Mask_MSelected(app, event)
            LoadBrainmask( app);
        end

        % Value changed function: AutoThr_E
        function AutoThr_EValueChanged(app, event)
            val = app.AutoThr_E.Value;

            % Set to default if inputs are invalid
            msg = 'Threshold for auto placement was changed.';
            if app.AutoThrPerc_RB.Value == 1
                if val <= 0 || val >= 100
                    msg = 'The percentile must be between 0 and 100. Set to default 99.';
                    app.AutoThr_E.Value = 99;
                end
            end

            % absoulute value
            if app.AutoThrAbs_RB.Value == 1
                if ~isempty( app.usrdata.img.mri)
                    lo = min( app.usrdata.img.mri.vol(:));
                    hi = max( app.usrdata.img.mri.vol(:));
                    if val <= lo || val >= hi
                        msg = sprintf( 'Absolute threshold must be within [%.0f, %.0f].', lo, hi);
                        app.AutoThr_E.Value = 0.95 * hi;
                    end

                    app.ThresholdIntensity.Text = sprintf( '%.1f', app.AutoThr_E.Value);

                else
                    app.ThresholdIntensity.Text = sprintf( '');
                end
            end

            if app.AutoThrPeak_RB.Value == 1
                if val <= 0 || val >= 1
                    msg = 'The percentile must be between 0 and 100. Set to default 0.95.';
                    app.AutoThr_E.Value = 0.95;
                end
            end

            if app.AutoThrHist_RB.Value == 1
                val = round( val);
                if val < 1 || val > 100
                    msg = 'The histogram edged must be between 1 and 100. Set to default 80.';
                    val = 80;
                end

                app.AutoThr_E.Value = val;
                if ~isempty( app.usrdata.img.mri)
                    [~, edges] = histcounts( app.usrdata.img.mri.vol(:), 999);
                    tmp = edges( val);
                    app.ThresholdIntensity.Text = sprintf( '%.1f', tmp);
                else
                    app.ThresholdIntensity.Text = sprintf( '');
                end
            end

            set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.msg_fontcolor);
            drawnow;
        end

        % Selection changed function: AutoPlaceThr
        function AutoPlaceThrSelectionChanged(app, event)
            if app.AutoThrAbs_RB.Value == 1
                s = 'Use absolute value for thresholding.';
            elseif app.AutoThrPeak_RB.Value == 1
                s = 'Use percentage of maximal intensity for thresholding.';
            elseif app.AutoThrPerc_RB.Value == 1
                s = 'Use percentile of intensity distribution for thresholding.';
            else
                s = 'Use histogram of intensity distribution for thresholding.';
            end

            app.AutoThr_E.Tooltip = s;
            AutoThr_EValueChanged( app, []);
        end

        % Button pushed function: MaskClear_B
        function MaskClear_BButtonPushed(app, event)
            msg = 'Ready.';
            if ~isempty( app.usrdata.brainmask)
                app.usrdata.brainmask = [];
                app.Mask_E.Value = '';
                msg = 'Brain mask was removed.';
            end

            set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.msg_fontcolor);
            drawnow;
        end

        % Button pushed function: MaskSet_B
        function MaskSet_BButtonPushed(app, event)
            LoadBrainmask( app);
        end

        % Value changed function: Mask_E
        function Mask_EValueChanged(app, event)
            LoadBrainmask( app, app.Mask_E.Value);
        end

        % Value changed function: CenterEst_CB
        function CenterEst_CBValueChanged(app, event)
            s = 'off';
            if app.CenterEst_CB.Value == 1
                s = 'on';
            end

            set( [app.CenterEstRadius_E, app.CenterEstThr_E,...
                app.RadiusEdit_3L, app.ThrEdit_3L],...
                'Enable', s);
            drawnow;
        end

        % Value changed function: PkSearch_CB
        function PkSearch_CBValueChanged(app, event)
            s = 'off';
            if app.PkSearch_CB.Value == 1
                s = 'on';
            end

            set( [app.PkSearchType_DD, app.PkSerachRange_E,...
                app.TypeDD_L, app.RangeE_L], 'Enable', s);
            drawnow;
        end

        % Menu selected function: CurData_M
        function CurData_MMenuSelected(app, event)
            msg = sprintf( 'Current dataset: %s.', app.usrdata.img.info.filename);
            set( app.status_label, 'Text', msg, 'FontColor', app.usrdata.msg_fontcolor);
            drawnow;
        end

        % Menu selected function: Eplace_M
        function Eplace_MMenuSelected(app, event)
            set( app.status_label, 'Text', 'Hello world', ...
                'FontColor', app.usrdata.msg_fontcolor);
            drawnow;
        end

        % Button pushed function: LineFitButton
        function LineFitButtonPushed(app, event)
            % return;
            % under construction

            % Fit a straight line on selected electrodes
            lbl = app.Label.Value;
            if ~iscell( lbl)
                lbl = {lbl};
            end

            nb_lbl = numel( lbl);
            if nb_lbl < 2
                set( app.status_label, 'Text', 'Two electrodes at least required.', ...
                    'fontcolor', app.usrdata.msg_fontcolor);
                return;
            end            

            % The total number of contacts on this electrode
            n_total = app.ElecFitTotalEF.Value;
            if n_total < 1 || n_total ~= round( n_total) || n_total < length( lbl)
                set( app.status_label, 'Text', 'Total number of elctrodes was incorrect.', ...
                    'fontcolor', app.usrdata.msg_fontcolor);
                return;
            end
            
            % delimiter for input string
            delm = {',', ' ', '; '};
            ref = strsplit( app.ElecFitRefEF.Value, delm);
            ref = cellfun( @(x) str2double( strtrim( x)), ref);
            if numel( ref) ~= 2
                set( app.status_label, 'Text', 'Index of two references required.', ...
                    'fontcolor', app.usrdata.msg_fontcolor);
                return;
            end

            lbl_grp = IGroupLabels( app, lbl);
            if app.ElecFitAutoIdxCB.Value == 1
                if size( lbl_grp, 1) > 1
                    set( app.status_label, 'Text', 'Failed to determine electrode index.', ...
                        'fontcolor', app.usrdata.msg_fontcolor);
                    return;
                end

                idx = lbl_grp{ 4};
                app.ElecFitIdxEF.Value = strjoin( cellstr( string( idx)), ', ');

            else
                idx = strsplit( app.ElecFitIdxEF.Value, delm);
                idx = cellfun( @(x) str2double( strtrim( x)), idx);
            end

            if length( idx) ~= nb_lbl
                set( app.status_label, 'Text', ...
                    'The length of index must be same to the number of selected electrodes.', ...
                    'fontcolor', app.usrdata.msg_fontcolor);
                return;
            end

            [d, c] = CalDistance( app, lbl, true);
            if app.ElecFitEstDistCB.Value ~= 1                
                d = app.ElecFitDistEF.Value;
            else
                d = max( d) / ( nb_lbl - 1);
                app.ElecFitDistEF.Value = d;
            end          

            % real location in the depth electrode
            vol_res = app.usrdata.img.mri.volres(:)';
            Init3dFitPlot( app);

            % idx = [4 5 6 7];
            % ref = [4, 7];
            % n_total = 14;
            % d = 3.5; % in mm 
            fc = eplace_3dLineFit( app.UIAxes3dLineFit, c, 'do_fit', true, ...
                'distance', d, 'n', n_total, 'index', idx, 'reference', ref,...
                'volres', vol_res, 'type', 'fixed', 'display', 'yes');

            sz = size( app.usrdata.img.mri.vol);
            [X, Y, Z] = ndgrid( 1:sz(1), 1:sz(2), 1:sz(3));
            G = [X(:), Y(:), Z(:)];
            
            % radius threshold
            r_thr = app.ElecFitRadiusEF.Value;
            intens_thr = app.ElecFitThrEF.Value;
            % len = floor( log10( n_total + 1)) + 1;
            all_lbl_grp = IGroupLabels( app, app.usrdata.elec.roiname);
            prefix = sprintf( 'Fit%s', lbl_grp{1});
            cnt = 0;
            while ismember( prefix, all_lbl_grp( :, 1))
                cnt = cnt + 1;
                M = ceil( log10( cnt)) + 1;
                prefix = sprintf( sprintf( 'Fit%%sV%d%%d', M), lbl_grp{1}, cnt);
            end

            for k = 1 : n_total
                x = fc.interp( k, 1);
                y = fc.interp( k, 2);
                z = fc.interp( k, 3);
                ind = find( sqrt( sum( ( (G - [x, y, z]) .* vol_res) .^ 2, 2)) <= r_thr);
                val = app.usrdata.img.mri.vol( ind);
                pk = max( val);
                ind = ind( val > intens_thr*pk);
                % Add electrode
                N = numel( app.usrdata.elec.rois) + 1;
                app.usrdata.elec.rois( N, 1) = {ind};
                app.usrdata.elec.roiname{ N} = sprintf( '%s%d', prefix, k);
            end
            
            set( app.Label, 'Items', app.usrdata.elec.roiname, ...
                'Value', app.usrdata.elec.roiname{ N});

            ColorTable( app);

            app.usrdata.cursor.i = round( x);
            app.usrdata.cursor.j = round( y);
            app.usrdata.cursor.k = round( z);
            app.usrdata.cursor.img = round( [x, y, z]);
            UpdateCursor(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.File_M = uimenu(app.UIFigure);
            app.File_M.Text = 'File';
            app.Open_M = uimenu(app.File_M);
            app.Open_M.MenuSelectedFcn = createCallbackFcn(app, @Open_MSelected, true);
            app.Open_M.Accelerator = 'o';
            app.Open_M.Text = 'Open';
            app.Save_M = uimenu(app.File_M);
            app.Save_M.MenuSelectedFcn = createCallbackFcn(app, @Save_MSelected, true);
            app.Save_M.Accelerator = 's';
            app.Save_M.Text = 'Save';
            app.Close_M = uimenu(app.File_M);
            app.Close_M.MenuSelectedFcn = createCallbackFcn(app, @Close_MSelected, true);
            app.Close_M.Accelerator = 'w';
            app.Close_M.Text = 'Close';
            app.Quit_M = uimenu(app.File_M);
            app.Quit_M.MenuSelectedFcn = createCallbackFcn(app, @Quit_MSelected, true);
            app.Quit_M.Separator = 'on';
            app.Quit_M.Accelerator = 'q';
            app.Quit_M.Text = 'Quit';
            app.Load_M = uimenu(app.UIFigure);
            app.Load_M.Text = 'Load';
            app.Elec_M = uimenu(app.Load_M);
            app.Elec_M.MenuSelectedFcn = createCallbackFcn(app, @Elec_MSelected, true);
            app.Elec_M.Accelerator = 'e';
            app.Elec_M.Text = 'Electrodes';
            app.Label_M = uimenu(app.Load_M);
            app.Label_M.MenuSelectedFcn = createCallbackFcn(app, @Label_MSelected, true);
            app.Label_M.Accelerator = 'l';
            app.Label_M.Text = 'Label';
            app.Mask_M = uimenu(app.Load_M);
            app.Mask_M.MenuSelectedFcn = createCallbackFcn(app, @Mask_MSelected, true);
            app.Mask_M.Text = 'Brain Mask';
            app.Stop_M = uimenu(app.UIFigure);
            app.Stop_M.Text = 'Stop';
            app.AutoPlace_M = uimenu(app.Stop_M);
            app.AutoPlace_M.MenuSelectedFcn = createCallbackFcn(app, @AutoPlace_MSelected, true);
            app.AutoPlace_M.Text = 'Auto Placement';
            app.About_M = uimenu(app.UIFigure);
            app.About_M.Text = 'About';
            app.CurData_M = uimenu(app.About_M);
            app.CurData_M.MenuSelectedFcn = createCallbackFcn(app, @CurData_MMenuSelected, true);
            app.CurData_M.Text = 'Current Dataset';
            app.Eplace_M = uimenu(app.About_M);
            app.Eplace_M.MenuSelectedFcn = createCallbackFcn(app, @Eplace_MMenuSelected, true);
            app.Eplace_M.Text = 'Eplace';
            app.Tab_G = uitabgroup(app.UIFigure);
            app.EplaceTab = uitab(app.Tab_G);
            app.EplaceTab.Title = 'Eplace';
            app.EplaceTab.Tag = 'main_tab';
            app.MainGridLayout = uigridlayout(app.EplaceTab);
            app.UIAxesX = uiaxes(app.MainGridLayout);
            app.UIAxesX.XTick = [];
            app.UIAxesX.YTick = [];
            app.UIAxesX.ZTick = [];
            app.UIAxesX.Tag = 'ax_x';
            colormap(app.UIAxesX, 'parula')
            app.UIAxesZ = uiaxes(app.MainGridLayout);
            app.UIAxesZ.XTick = [];
            app.UIAxesZ.YTick = [];
            app.UIAxesZ.Tag = 'ax_z';
            colormap(app.UIAxesZ, 'parula')
            app.UIAxesY = uiaxes(app.MainGridLayout);
            app.UIAxesY.XTick = [];
            app.UIAxesY.YTick = [];
            app.UIAxesY.Tag = 'ax_y';
            colormap(app.UIAxesY, 'parula')
            app.ScaleSpin_L = uilabel(app.MainGridLayout);
            app.ScaleSpin_L.Text = 'Scale';
            app.Scale_SP = uispinner(app.MainGridLayout);
            app.Scale_SP.Step = 10;
            app.Scale_SP.ValueChangingFcn = createCallbackFcn(app, @Scale_SPValueChanging, true);
            app.Scale_SP.Limits = [1e-06 10000];
            app.Scale_SP.ValueDisplayFormat = '%11g';
            app.Scale_SP.ValueChangedFcn = createCallbackFcn(app, @Scale_SPValueChanged, true);
            app.Scale_SP.Tag = 'scale';
            app.Scale_SP.Value = 100;
            app.MinIntens_L = uilabel(app.MainGridLayout);
            app.MinIntens_L.Text = 'Intensity';
            app.MinIntens_E = uieditfield(app.MainGridLayout, 'numeric');
            app.MinIntens_E.ValueChangedFcn = createCallbackFcn(app, @MinIntens_EValueChanged, true);
            app.MinIntens_E.Tag = 'lo_intensity';
            app.MaxIntens_E = uieditfield(app.MainGridLayout, 'numeric');
            app.MaxIntens_E.ValueChangedFcn = createCallbackFcn(app, @MaxIntens_EValueChanged, true);
            app.MaxIntens_E.Tag = 'hi_intensity';
            app.Label_LBL = uilabel(app.MainGridLayout);
            app.Target_L = uilabel(app.MainGridLayout);
            app.Target_L.Text = 'Target';
            app.Label = uilistbox(app.MainGridLayout);
            app.Label.Items = {};
            app.Label.Multiselect = 'on';
            app.Label.ValueChangedFcn = createCallbackFcn(app, @LabelValueChanged, true);
            app.Label.Tag = 'label';
            app.Label.Value = {};
            app.RealLabel = uilistbox(app.MainGridLayout);
            app.RealLabel.Items = {};
            app.RealLabel.Tag = 'target_label';
            app.RealLabel.Value = {};
            app.AssignLbl_B = uibutton(app.MainGridLayout, 'push');
            app.AssignLbl_B.ButtonPushedFcn = createCallbackFcn(app, @AssignLbl_BPushed, true);
            app.AssignLbl_B.Tooltip = {'Rename selected label to target. Shortcut "<".'};
            app.AssignLbl_B.Text = '<';
            app.ReLabel_TE = uieditfield(app.MainGridLayout, 'text');
            app.LabelAutoMove_CB = uicheckbox(app.MainGridLayout);
            app.LabelAutoMove_CB.Tooltip = {'Shift focus to the next label after it''s been renamed.'};
            app.LabelAutoMove_CB.Text = 'Auto move';
            app.ReLabel_B = uibutton(app.MainGridLayout, 'push');
            app.ReLabel_B.ButtonPushedFcn = createCallbackFcn(app, @ReLabel_BPushed, true);
            app.ReLabel_B.Tooltip = {'Rename currently selected label.'};
            app.ReLabel_B.Text = 'Rename';
            app.Sort_B = uibutton(app.MainGridLayout, 'push');
            app.Sort_B.ButtonPushedFcn = createCallbackFcn(app, @Sort_BPushed, true);
            app.Sort_B.Tooltip = {'Sort labels.'};
            app.Sort_B.Text = 'Sort';
            app.Status_L = uilabel(app.MainGridLayout);
            app.Status_L.Text = 'Status_Bar';
            app.XT_L = uilabel(app.MainGridLayout);
            app.XT_L.Text = 'Label4';
            app.XB_L = uilabel(app.MainGridLayout);
            app.XB_L.Text = 'Label5';
            app.ZT_L = uilabel(app.MainGridLayout);
            app.ZT_L.Text = 'Label12';
            app.XL_L = uilabel(app.MainGridLayout);
            app.XL_L.Text = 'Label2';
            app.ZL_L = uilabel(app.MainGridLayout);
            app.ZL_L.Text = 'Label10';
            app.ImgX_SP = uispinner(app.MainGridLayout);
            app.ImgX_SP.Limits = [1 Inf];
            app.ImgX_SP.ValueChangedFcn = createCallbackFcn(app, @ImgX_SPValueChanged, true);
            app.ImgX_SP.Tag = 'img_x';
            app.ImgX_SP.Value = 1;
            app.X_L = uilabel(app.MainGridLayout);
            app.X_L.Text = 'X';
            app.Y_L = uilabel(app.MainGridLayout);
            app.Y_L.Text = 'Y';
            app.Z_L = uilabel(app.MainGridLayout);
            app.Z_L.Text = 'Z';
            app.ImgY_SP = uispinner(app.MainGridLayout);
            app.ImgY_SP.Limits = [1 Inf];
            app.ImgY_SP.ValueChangedFcn = createCallbackFcn(app, @ImgY_SPValueChanged, true);
            app.ImgY_SP.Tag = 'img_y';
            app.ImgY_SP.Value = 1;
            app.ImgZ_SP = uispinner(app.MainGridLayout);
            app.ImgZ_SP.Limits = [1 Inf];
            app.ImgZ_SP.ValueChangedFcn = createCallbackFcn(app, @ImgZ_SPValueChanged, true);
            app.ImgZ_SP.Tag = 'img_z';
            app.ImgZ_SP.Value = 1;
            app.ZB_L = uilabel(app.MainGridLayout);
            app.ZB_L.Text = 'Label13';
            app.StdX_E = uieditfield(app.MainGridLayout, 'numeric');
            app.StdX_E.ValueChangedFcn = createCallbackFcn(app, @StdX_EValueChanged, true);
            app.StdX_E.Tag = 'std_x';
            app.StdY_E = uieditfield(app.MainGridLayout, 'numeric');
            app.StdY_E.ValueChangedFcn = createCallbackFcn(app, @StdY_EValueChanged, true);
            app.StdY_E.Tag = 'std_y';
            app.StdZ_E = uieditfield(app.MainGridLayout, 'numeric');
            app.StdZ_E.ValueChangedFcn = createCallbackFcn(app, @StdZ_EValueChanged, true);
            app.StdZ_E.Tag = 'std_z';
            app.Intens_L = uilabel(app.MainGridLayout);
            app.Intens_L.Text = 'Label14';
            app.XR_L = uilabel(app.MainGridLayout);
            app.XR_L.Text = 'Label3';
            app.YL_L = uilabel(app.MainGridLayout);
            app.YL_L.Text = 'Label6';
            app.YR_L = uilabel(app.MainGridLayout);
            app.YR_L.Text = 'Label7';
            app.YT_L = uilabel(app.MainGridLayout);
            app.YT_L.Text = 'Label8';
            app.YB_L = uilabel(app.MainGridLayout);
            app.YB_L.Text = 'Label9';
            app.ZR_L = uilabel(app.MainGridLayout);
            app.ZR_L.Text = 'Label11';
            app.CenterCursor_B = uibutton(app.MainGridLayout, 'push');
            app.CenterCursor_B.ButtonPushedFcn = createCallbackFcn(app, @CenterCursor_BPushed, true);
            app.CenterCursor_B.Text = 'Center Cursor';
            app.ShowAll = uibutton(app.MainGridLayout, 'state');
            app.ShowAll.ValueChangedFcn = createCallbackFcn(app, @ShowAllValueChanged, true);
            app.ShowAll.Text = 'Show all';
            app.Panel = uipanel(app.MainGridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Method_BG = uibuttongroup(app.GridLayout2);
            app.Method_BG.SelectionChangedFcn = createCallbackFcn(app, @Method_BGSelectionChanged, true);
            app.MethAuto_B = uiradiobutton(app.Method_BG);
            app.MethAuto_B.Text = 'Auto';
            app.MethAuto_B.Value = true;
            app.MethUseExact_B = uiradiobutton(app.Method_BG);
            app.MethUseExact_B.Text = 'Cursor';
            app.MethUsePk_B = uiradiobutton(app.Method_BG);
            app.MethUsePk_B.Text = 'Peak';
            app.MethManual_B = uiradiobutton(app.Method_BG);
            app.MethManual_B.Text = 'Manual';
            app.FinalSigma_EL = uilabel(app.GridLayout2);
            app.FinalSigma_EL.Text = 'Final Sigma';
            app.FinalSigma_E = uieditfield(app.GridLayout2, 'numeric');
            app.FinalSigma_E.Limits = [0 10];
            app.FinalSigma_E.Value = 0.15;
            app.DepthElecPanel = uipanel(app.GridLayout2);
            app.DepthElecPanel.Tooltip = {'Example:'; 'To fit a 3d line to a depth probe which has 12 contacts (AH1, AH2, ..., AH12).'; ''; 'You manually identified three of them, e,g. AH3, AH6, AH7.'; ''; 'Highlight these three labels from the Label listboxt, then set the parameters as below'; 'Total: 12'; 'Reference: 3, 6 (or, 3, 7, or 6, 7)'; 'Distance: 3.5 (or check Estimate Distance)'; 'Index: 3, 6, 7 '; 'If the labels have been named as AH3, AH6, AH7, you could leave Index empty and check Auto Index check box.'; 'Then press Go.'; ''};
            app.DepthElecPanel.Title = 'Depth electrode auto complete';
            app.GridLayout8 = uigridlayout(app.DepthElecPanel);
            app.ElecFitEstDistCB = uicheckbox(app.GridLayout8);
            app.ElecFitEstDistCB.Text = 'Estimate Distance';
            app.ElecFitDistEF = uieditfield(app.GridLayout8, 'numeric');
            app.ElecFitDistEF.Limits = [0.1 Inf];
            app.ElecFitDistEF.Value = 0.1;
            app.ElecFitAutoIdxCB = uicheckbox(app.GridLayout8);
            app.ElecFitAutoIdxCB.Text = 'Auto';
            app.IndexEFL = uilabel(app.GridLayout8);
            app.IndexEFL.Text = 'Index';
            app.DistanceEFL = uilabel(app.GridLayout8);
            app.DistanceEFL.Text = 'Distance';
            app.ReferenceEEFL = uilabel(app.GridLayout8);
            app.ReferenceEEFL.Text = 'Reference';
            app.LineFitButton = uibutton(app.GridLayout8, 'push');
            app.LineFitButton.ButtonPushedFcn = createCallbackFcn(app, @LineFitButtonPushed, true);
            app.LineFitButton.Text = 'Go';
            app.ElecFitTotalEF = uieditfield(app.GridLayout8, 'numeric');
            app.ElecFitTotalEF.Limits = [1 Inf];
            app.ElecFitTotalEF.Value = 1;
            app.TotalNLabel = uilabel(app.GridLayout8);
            app.TotalNLabel.Text = 'Total N';
            app.ElecFitRefEF = uieditfield(app.GridLayout8, 'text');
            app.ElecFitIdxEF = uieditfield(app.GridLayout8, 'text');
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout8);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ElecFitThrEF = uieditfield(app.GridLayout8, 'numeric');
            app.ElecFitThrEF.Limits = [0 1];
            app.ElecFitThrEF.Value = 0.5;
            app.RadiusLabel = uilabel(app.GridLayout8);
            app.RadiusLabel.Text = 'Radius';
            app.ElecFitRadiusEF = uieditfield(app.GridLayout8, 'numeric');
            app.ElecFitRadiusEF.Limits = [0.1 5];
            app.ElecFitRadiusEF.Value = 1.5;
            app.AddElec_B = uibutton(app.GridLayout2, 'push');
            app.AddElec_B.ButtonPushedFcn = createCallbackFcn(app, @AddElec_BButtonPushed, true);
            app.AddElec_B.Text = 'Add';
            app.PreviewElec_B = uibutton(app.GridLayout2, 'push');
            app.PreviewElec_B.ButtonPushedFcn = createCallbackFcn(app, @PreviewElec_BButtonPushed, true);
            app.PreviewElec_B.Text = 'Preview';
            app.RmElec_B = uibutton(app.GridLayout2, 'push');
            app.RmElec_B.ButtonPushedFcn = createCallbackFcn(app, @RmElec_BButtonPushed, true);
            app.RmElec_B.Text = 'Remove';
            app.MethodLabel = uilabel(app.GridLayout2);
            app.MethodLabel.Text = 'Method';
            app.LocalPkSearchPanel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.LocalPkSearchPanel);
            app.PkSearch_CB = uicheckbox(app.GridLayout3);
            app.PkSearch_CB.ValueChangedFcn = createCallbackFcn(app, @PkSearch_CBValueChanged, true);
            app.PkSearch_CB.Text = 'Peak Search';
            app.PkSearch_CB.Value = true;
            app.RangeE_L = uilabel(app.GridLayout3);
            app.RangeE_L.Text = 'Size';
            app.PkSerachRange_E = uieditfield(app.GridLayout3, 'numeric');
            app.PkSerachRange_E.Limits = [0 5];
            app.PkSerachRange_E.Value = 2;
            app.CenterEstPanel = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.CenterEstPanel);
            app.CenterEst_CB = uicheckbox(app.GridLayout4);
            app.CenterEst_CB.ValueChangedFcn = createCallbackFcn(app, @CenterEst_CBValueChanged, true);
            app.CenterEst_CB.Text = 'Estimate Center';
            app.CenterEst_CB.Value = true;
            app.CenterEstRadius_E = uieditfield(app.GridLayout4, 'numeric');
            app.CenterEstRadius_E.Limits = [2.22044604925031e-16 30];
            app.CenterEstRadius_E.Value = 2;
            app.RadiusEdit_3L = uilabel(app.GridLayout4);
            app.RadiusEdit_3L.Text = 'Radius';
            app.ThrEdit_3L = uilabel(app.GridLayout4);
            app.ThrEdit_3L.Text = 'Threshold';
            app.CenterEstThr_E = uieditfield(app.GridLayout4, 'numeric');
            app.CenterEstThr_E.Limits = [0 1];
            app.CenterEstThr_E.Value = 0.95;
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.RadiusE_4L = uilabel(app.GridLayout5);
            app.RadiusE_4L.Text = 'Radius';
            app.OverlapRadius_E = uieditfield(app.GridLayout5, 'numeric');
            app.OverlapRadius_E.Limits = [0 10];
            app.OverlapRadius_E.Value = 2.5;
            app.ThrE_4L = uilabel(app.GridLayout5);
            app.ThrE_4L.Text = 'Threshold';
            app.OverlapThr_E = uieditfield(app.GridLayout5, 'numeric');
            app.OverlapThr_E.Limits = [0 Inf];
            app.OverlapThr_E.Value = 0.9;
            app.OverlapLabel = uilabel(app.GridLayout5);
            app.OverlapLabel.Text = 'Overlap';
            app.LabelInfo = uilabel(app.MainGridLayout);
            app.LabelInfo.Text = 'LabelInfo';
            app.ElecTransp_SLD = uislider(app.MainGridLayout);
            app.ElecTransp_SLD.MajorTicks = [];
            app.ElecTransp_SLD.ValueChangedFcn = createCallbackFcn(app, @ElecTransp_SLDValueChanged, true);
            app.ElecTransp_SLD.MinorTicks = [];
            app.ElecTransp_SLD.Value = 55.8733258928571;
            app.ElecTransp_SL = uilabel(app.MainGridLayout);
            app.ElecTransp_SL.Text = 'Electrode opacity';
            app.PreferencesTab = uitab(app.Tab_G);
            app.PreferencesTab.Title = 'Preferences';
            app.GridLayout6 = uigridlayout(app.PreferencesTab);
            app.CoordType_BG = uibuttongroup(app.GridLayout6);
            app.CoordType_BG.SelectionChangedFcn = createCallbackFcn(app, @CoordType_BGSelectionChanged, true);
            app.CoordType_BG.Title = 'Coordinate Type';
            app.Peak_B = uiradiobutton(app.CoordType_BG);
            app.Peak_B.Text = 'Peak';
            app.WeigCenter_B = uiradiobutton(app.CoordType_BG);
            app.WeigCenter_B.Text = 'Weighted center';
            app.UnweigCenter_B = uiradiobutton(app.CoordType_BG);
            app.UnweigCenter_B.Text = 'Unweighted center';
            app.UnweigCenter_B.Value = true;
            app.SaveSourceImg_BG = uibuttongroup(app.GridLayout6);
            app.SaveSourceImg_BG.Title = 'Save source image';
            app.YesSaveSrc_B = uiradiobutton(app.SaveSourceImg_BG);
            app.YesSaveSrc_B.Text = 'Yes';
            app.NoSaveSrc_B = uiradiobutton(app.SaveSourceImg_BG);
            app.NoSaveSrc_B.Text = 'No';
            app.NoSaveSrc_B.Value = true;
            app.FontSz_L = uilabel(app.GridLayout6);
            app.FontSz_L.Text = 'Font Size';
            app.FontSz_E = uieditfield(app.GridLayout6, 'numeric');
            app.FontSz_E.Limits = [3 20];
            app.FontSz_E.ValueChangedFcn = createCallbackFcn(app, @FontSz_EValueChanged, true);
            app.FontSz_E.Value = 12;
            app.Status_L2 = uilabel(app.GridLayout6);
            app.Status_L2.Text = 'Status_Bar2';
            app.AutoPlacePanel = uipanel(app.GridLayout6);
            app.AutoPlacePanel.Title = 'Automatic plaement settings';
            app.GridLayout7 = uigridlayout(app.AutoPlacePanel);
            app.MaskE_L = uilabel(app.GridLayout7);
            app.MaskE_L.Text = 'Brain mask:';
            app.Mask_E = uieditfield(app.GridLayout7, 'text');
            app.Mask_E.ValueChangedFcn = createCallbackFcn(app, @Mask_EValueChanged, true);
            app.MaskSet_B = uibutton(app.GridLayout7, 'push');
            app.MaskSet_B.ButtonPushedFcn = createCallbackFcn(app, @MaskSet_BButtonPushed, true);
            app.MaskSet_B.Text = 'Select';
            app.MaskClear_B = uibutton(app.GridLayout7, 'push');
            app.MaskClear_B.ButtonPushedFcn = createCallbackFcn(app, @MaskClear_BButtonPushed, true);
            app.MaskClear_B.Text = 'Clear';
            app.IntensRangeT_L = uilabel(app.GridLayout7);
            app.IntensRangeT_L.Text = 'Image intesnsity range:';
            app.IntensRange_T = uitextarea(app.GridLayout7);
            app.IntensRange_T.Editable = 'off';
            app.ElecSzE_L = uilabel(app.GridLayout7);
            app.ElecSzE_L.Text = 'Electrode Size Limit (mm):';
            app.ElecSz_E = uieditfield(app.GridLayout7, 'numeric');
            app.ElecSz_E.Limits = [1 50];
            app.ElecSz_E.Value = 15;
            app.AutoThrE_L = uilabel(app.GridLayout7);
            app.AutoThrE_L.Text = 'Threshold for auto placement';
            app.AutoThr_E = uieditfield(app.GridLayout7, 'numeric');
            app.AutoThr_E.Limits = [0 Inf];
            app.AutoThr_E.ValueChangedFcn = createCallbackFcn(app, @AutoThr_EValueChanged, true);
            app.AutoThr_E.Value = 0.9;
            app.AutoPlaceThr = uibuttongroup(app.GridLayout7);
            app.AutoPlaceThr.SelectionChangedFcn = createCallbackFcn(app, @AutoPlaceThrSelectionChanged, true);
            app.AutoPlaceThr.Title = 'Threshold type';
            app.AutoThrAbs_RB = uiradiobutton(app.AutoPlaceThr);
            app.AutoThrAbs_RB.Text = 'Absolute value';
            app.AutoThrPeak_RB = uiradiobutton(app.AutoPlaceThr);
            app.AutoThrPeak_RB.Tooltip = {'Use percentage of maximal intensity for thresholding.'};
            app.AutoThrPeak_RB.Text = 'Percentage of peak';
            app.AutoThrPeak_RB.Value = true;
            app.AutoThrPerc_RB = uiradiobutton(app.AutoPlaceThr);
            app.AutoThrPerc_RB.Text = 'Percentile (0-100)';
            app.AutoThrHist_RB = uiradiobutton(app.AutoPlaceThr);
            app.AutoThrHist_RB.Text = 'Histogram (1-100)';
            app.ThresholdIntensity = uilabel(app.GridLayout7);
            app.ThresholdIntensity.Text = 'Label15';
            app.IntensityofthresholdLabel = uilabel(app.GridLayout7);
            app.IntensityofthresholdLabel.Text = 'Intensity of threshold:';
            app.TypeDD_L = uilabel(app.GridLayout6);
            app.TypeDD_L.Text = 'Peak search ROI type';
            app.PkSearchType_DD = uidropdown(app.GridLayout6);
            app.PkSearchType_DD.Items = {'Sphere', 'Cubic'};
            app.PkSearchType_DD.Value = 'Sphere';
            app.TabGroup3d = uitabgroup(app.UIFigure);
            app.dplotsTab = uitab(app.TabGroup3d);
            app.dplotsTab.Title = '3d plots';
            app.GridLayout9 = uigridlayout(app.dplotsTab);
            app.UIAxes3dLineFit = uiaxes(app.GridLayout9);
            colormap(app.UIAxes3dLineFit, 'parula')
            app.UIAxesAllElec = uiaxes(app.GridLayout9);
            colormap(app.UIAxesAllElec, 'parula')
            app.ElectrodesplacedLabel = uilabel(app.GridLayout9);
            app.ElectrodesplacedLabel.Text = 'Electrodes placed';
            app.dlinefittingresultsLabel = uilabel(app.GridLayout9);
            app.dlinefittingresultsLabel.Text = '3d line fitting results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eplace_dev(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end