classdef eviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EVIEWERUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        CurrentdatasetMenu              matlab.ui.container.Menu
        enormMenu                       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProjectionTab                   matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        AtlasofselectedchannelPanel     matlab.ui.container.Panel
        AtlasListbox                    matlab.ui.control.ListBox
        SelectedLabelListbox            matlab.ui.control.ListBox
        SelectedchannelsLabel           matlab.ui.control.Label
        ElecLabelGroupListbox           matlab.ui.control.ListBox
        ChanelgroupLabel                matlab.ui.control.Label
        ElecLabelGroupInfo              matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        ElecLabelListbox                matlab.ui.control.ListBox
        AtlasOptionsPanel               matlab.ui.container.Panel
        ShowAtlasButton                 matlab.ui.control.Button
        QueryButton                     matlab.ui.control.Button
        AtlasGroupListbox               matlab.ui.control.ListBox
        SurfaceOpacitySlider            matlab.ui.control.Slider
        SurfaceOpacitySliderLabel       matlab.ui.control.Label
        OpacityEdit                     matlab.ui.control.NumericEditField
        SurfaceOptionsPanel             matlab.ui.container.Panel
        OpacityLabel                    matlab.ui.control.Label
        FSsurfRhCheckBox                matlab.ui.control.CheckBox
        FSsurfLhCheckBox                matlab.ui.control.CheckBox
        FSsurfOpacitySlider             matlab.ui.control.Slider
        FSParcellationListBox           matlab.ui.control.ListBox
        FreesurferParcellationListBoxLabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        DisplayoptionsPanel             matlab.ui.container.Panel
        FigurebBkColorButton            matlab.ui.control.Button
        MNIbrainintensityPanel          matlab.ui.container.Panel
        DefaultStdClim                  matlab.ui.control.Button
        HighLabel_2                     matlab.ui.control.Label
        MNIIntensityHi                  matlab.ui.control.NumericEditField
        MNIintensityLo                  matlab.ui.control.NumericEditField
        LowLabel_2                      matlab.ui.control.Label
        T1brainintensityPanel           matlab.ui.container.Panel
        HighLabel                       matlab.ui.control.Label
        DefaultT1Clim                   matlab.ui.control.Button
        T1IntensityHi                   matlab.ui.control.NumericEditField
        T1IntensityLo                   matlab.ui.control.NumericEditField
        LowLabel                        matlab.ui.control.Label
        ProjectedelectrodesizeEditField  matlab.ui.control.NumericEditField
        ProjectedelectrodesizeEditFieldLabel  matlab.ui.control.Label
        SelectedelectrodesizeEditField  matlab.ui.control.NumericEditField
        SelectedelectrodesizeEditFieldLabel  matlab.ui.control.Label
        ElectrodessizeEditField         matlab.ui.control.NumericEditField
        ElectrodessizeEditFieldLabel    matlab.ui.control.Label
        ChangeprojectedelectrodescolorButton  matlab.ui.control.Button
        ChangeselectedelectrodescolorButton  matlab.ui.control.Button
        ChangeelectrodescolorButton     matlab.ui.control.Button
        ShowprojectedelectrodesCheckBox  matlab.ui.control.CheckBox
        ElectrodestoshowButtonGroup     matlab.ui.container.ButtonGroup
        ShowallwithouthighlightingselectedelectrodeButton  matlab.ui.control.RadioButton
        ShowselectedelectrodesonlyButton  matlab.ui.control.RadioButton
        ShowallelectrodesButton         matlab.ui.control.RadioButton
        StatusTextArea                  matlab.ui.control.Label
    end

    properties (Access = private)
        userdata % user data
        fighandle % handle to dependent figures
    end
    
    methods (Access = private)
        
        function Eviewer_InitializeUserdata(app)             
            %% whether data has been saved or not. 'yes' | 'no'
            app.userdata.data_saved = 'no';
            
            %% names of the figures
            app.userdata.surf_yoke_name = 'eviewer FS Surface';
            app.userdata.mni_surf_yoke_name = 'eviewer MNI Surface';
            app.userdata.misc.t13d_figname = 'eviewer T1';
            app.userdata.misc.std3d_figname = 'eviewer MNI brain';
            
            % figure background color
            app.userdata.bkcolor = 'k';
            
            %% marker size for electrodes overlay on brain surfaces
            app.userdata.markersize_3d = 5;
            
            % marker size for brain slices view
            app.userdata.elec_markersize = 6;     
            app.userdata.cur_elec_markersize = 6;                       
            
            % electrode color
            app.userdata.elec_color = 'b';
            app.userdata.cur_elec_color = 'r';
            app.userdata.proj_elec_markersize = 6;
            app.userdata.proj_elec_color = 'k';
            app.userdata.show_proj = 'yes';
            app.ShowprojectedelectrodesCheckBox.Value = 1;
            app.userdata.show_all_elec = 'yes';
            app.userdata.show_cur_elec = 'yes';
            
            app.ElectrodessizeEditField.Value = app.userdata.elec_markersize;
            app.SelectedelectrodesizeEditField.Value = app.userdata.cur_elec_markersize;
            app.ProjectedelectrodesizeEditField.Value = app.userdata.proj_elec_markersize;
            
            
            %% data resulted from EPLACE
            % directory the electrode location file
            app.userdata.srcdir = '';
            app.userdata.filename = '';
            app.userdata.elec = [];
                       
            app.userdata.reg = [];
            app.userdata.proj = [];
            app.userdata.srcdata = [];
            
            % atlas in individual space
            % the atlas has a volume file and a look up table
            app.userdata.atlas.t1.names = '';
            % full-path-to-atalas volumes
            app.userdata.atlas.t1.srcfile = '';
            % brain volumes read by MRIread
            app.userdata.atlas.t1.vols = '';
            % look-up table for each atlas, column 1 - index; column 2 - atlas name
            app.userdata.atlas.t1.lut = '';
            
            % MNI atlas
            app.userdata.atlas.std.names = '';
            app.userdata.atlas.std.srcfile = '';
            app.userdata.atlas.std.vols = '';
            app.userdata.atlas.std.lut = '';
            app.userdata.atlas.radius = 2;
            
            % atlas query results
            app.userdata.atlas.query = [];
            % atlas query result with Freesurfer segmentation
            app.userdata.atlas.query.fsaseg = {};
            app.userdata.atlas.query.fsl = {};           
            
            
            % figure app
            app.fighandle.fs_yoke = [];
            % handle to surface patch
            app.fighandle.fssurf.lh = [];
            app.fighandle.fssurf.rh = [];
            
            app.fighandle.fs_all_elec = [];
            app.fighandle.fs_cur_elec = [];
            app.fighandle.fs_proj_elc = [];
            
            app.fighandle.mnisurf_yoke = [];
            app.fighandle.mnisurf.lh = [];
            app.fighandle.mnisurf.rh = [];
            
            app.fighandle.mni_all_elec = [];
            app.fighandle.mni_cur_elec = [];
            app.fighandle.mni_proj_elec = [];
            
            
            app.fighandle.t13d_fig = [];
            app.fighandle.std3d_fig = [];
            app.fighandle.fs_proj_elec = [];
            app.fighandle.std_proj_elec = [];
            
            % individual surface handles
            app.fighandle.fsannot = [];
            app.fighandle.fsannot.lh = [];
            app.fighandle.fsannot.rh = [];
            
            % Freesurfer MNI surface handles
            app.fighandle.mniannot = [];
            app.fighandle.mniannot.lh = [];
            app.fighandle.mniannot.rh = [];   
            
            % initilize listboxes
            objs = {app.ElecLabelListbox,...
                app.SelectedLabelListbox,...
                app.ElecLabelGroupListbox,...
                app.AtlasGroupListbox,...
                app.AtlasListbox};            
            for k = 1 : length( objs)
                set( objs{ k}, 'Items', {});
            end            
            
            app.StatusTextArea.Text = '';            
            app.ElecLabelGroupInfo.Text = '';            
        end % function Initialize User Data
        
        
        function ChangeVisibility( app, status)
            app.SaveMenu.Enable = status;
            app.CloseMenu.Enable = status;
            app.CurrentdatasetMenu.Enable = status;
            
            for k = 1 : length( app.ProjectionTab.Children)
                if isprop( app.ProjectionTab.Children( k), 'Enable')
                    app.ProjectionTab.Children( k).Enable = status;
                end
            end            
        end
        
    
        function Eviewer_InitializeTransform(app)            
            if ~isempty( app.userdata.elec)               
                % electrode label list
                app.ElecLabelListbox.Items = app.userdata.elec.label;
                
                % group labels
                if exist( 'ECoG_GroupLabels', 'file')
                    label_grp = ECoG_GroupLabels( app.userdata.elec.label);
                    app.ElecLabelGroupListbox.Items = label_grp( :, 1);
                    app.ElecLabelGroupListbox.Value = label_grp{ 1, 1};
                    app.ElecLabelGroupInfo.Text = [label_grp{ 1, 1}, ': ',...
                        num2str( length( label_grp{ 1, 2})), ' labels'];
                    app.userdata.label_grp = label_grp;
                end
            end
        end % function Eviewer_InitializeTransform
        
        
        function InitializeYoke(app)
            % handles to individual surface and slice plots
            f = findobj( 'name', app.userdata.surf_yoke_name);
            for k = 1 : length( f)
                delete( f(k));
            end
            
            f = figure( 'units', 'normalized',...
                'position', [0.01 0.6 0.15 0.25],...
                'name', app.userdata.surf_yoke_name,...
                'numbertitle', 'off',...
                'Color', app.userdata.bkcolor);
            
            app.fighandle.fs_yoke = axes( f);
            axis( app.fighandle.fs_yoke, 'off');
            axis( app.fighandle.fs_yoke, 'equal');
            axis( app.fighandle.fs_yoke, 'vis3d');
            
            % axial, sagittal, and cronal slices
            tmp_f = findobj( 'name', app.userdata.misc.t13d_figname);
            for k = 1 : length( tmp_f)
                delete( tmp_f(k));
            end
            
            app.fighandle.t13d_fig = figure(...
                'units', 'normalized',...
                'position', [0.2 0.6 0.15 0.25],...
                'name', app.userdata.misc.t13d_figname,...
                'numbertitle', 'off',...
                'color', app.userdata.bkcolor);            
        end % function InitializeYoke
    
    
        function InitializeMNISurfYoke( app)
            % 3d brain surface figure
            f = findobj( 'name', app.userdata.mni_surf_yoke_name);
            for k = 1 : length( f)
                delete( f(k));
            end
            
            f = figure( ...
                'units', 'normalized',...
                'position', [0.01 0.1 0.15 0.25],...
                'name', app.userdata.mni_surf_yoke_name,...
                'numbertitle', 'off',...
                'Color', app.userdata.bkcolor);
            
            app.fighandle.mnisurf_yoke = axes( f);
            axis( app.fighandle.mnisurf_yoke, 'off');
            axis( app.fighandle.mnisurf_yoke, 'equal');
            axis( app.fighandle.mnisurf_yoke, 'vis3d');
            
            % axial, sagittal, and cronal slices
            tmp_f = findobj( 'name', app.userdata.misc.std3d_figname);
            for k = 1 : length( tmp_f)
                delete( tmp_f(k));
            end
            
            app.fighandle.std3d_fig = figure(...
                'units', 'normalized',...
                'position', [0.2 0.1 0.15 0.25],...
                'name', app.userdata.misc.std3d_figname,...
                'numbertitle', 'off', ...
                'color', app.userdata.bkcolor);
        end % function InitializeMNISurfYoke
        
        
        function Clear3dElecPlot( ~, h)
            if ~isempty( h) && isvalid( h)
                set( h, ...
                    'XData', [],...
                    'YData', [],...
                    'Zdata', []);
            end
        end
        
        
        function Update3dElecPlot( app, elec_hname, fig_hname, coord, ck, mark_sz)
            % elec_hname, name the handle to 3d points   app.fighandle.( elec_name)
            % fig_hname, name of the handle to figure   app.fighandle.( fig_hname)
            % coord, Nx3 coordinates
            % ck, color
            % mark_dz, marker size
            if isempty( app.fighandle.( elec_hname)) || ~isvalid( app.fighandle.( elec_hname))
                app.fighandle.( elec_hname) = plot3( ...
                    app.fighandle.( fig_hname),...
                    coord( :, 1),...
                    coord( :, 2),...
                    coord( :, 3),...
                    'o',...
                    'color',           ck,...
                    'markersize',      mark_sz,...
                    'markerfacecolor', ck);
                
            else
                set( app.fighandle.( elec_hname),...
                    'XData', coord( :, 1),...
                    'YData', coord( :, 2),...
                    'ZData', coord( :, 3),...
                    'Color', ck,...
                    'MarkerSize', mark_sz,...
                    'MarkerFaceColor', ck);
            end
        end
        
        
        function UpdateYoke( app)           
            % currently selected labels
            val = app.ElecLabelListbox.Value;
            if isempty( app.userdata.elec) ...
                    || isempty( app.userdata.elec.label)
                return;
            end
            
            
            cur_elec_loc = find( ismember( app.userdata.elec.label, val));
            if ~isempty( app.fighandle.fs_yoke) ...
                    && isvalid( app.fighandle.fs_yoke)...
                    && ~isempty( app.userdata.reg.coord2surf)
                
                hold( app.fighandle.fs_yoke, 'on');
                
                if strcmpi( app.userdata.show_all_elec, 'yes')                      
                    Update3dElecPlot( app, 'fs_all_elec', 'fs_yoke',...
                        app.userdata.reg.coord2surf,...
                        app.userdata.elec_color,...
                        app.userdata.elec_markersize);
                else
                    Clear3dElecPlot( app, app.fighandle.fs_all_elec);
                end
                
                if strcmpi( app.userdata.show_cur_elec, 'yes')
                    Update3dElecPlot( app, 'fs_cur_elec', 'fs_yoke',...
                        app.userdata.reg.coord2surf( cur_elec_loc, :),...
                        app.userdata.cur_elec_color,...
                        app.userdata.cur_elec_markersize);
                else
                    Clear3dElecPlot( app, app.fighandle.fs_cur_elec);
                end
                
                
                % projected electrodes
                if strcmpi( app.userdata.show_proj, 'yes')
                    loc = find( ismember( app.userdata.proj.label, val));
                    coord2plot = [];
                    for k = 1 : length( loc)
                        if app.userdata.proj.status( loc(k)) == 0
                            continue;
                        end
                        if size( app.userdata.proj.coord2surf, 1) >= loc(k)
                            coord2plot = cat( 1, coord2plot, app.userdata.proj.coord2surf( loc(k), :));
                        end
                    end
                    
                    if ~isempty( app.fighandle.fs_proj_elec)
                        delete( app.fighandle.fs_proj_elec);
                        app.fighandle.fs_proj_elec = [];
                    end
                    
                    if ~isempty( coord2plot)                        
                        Update3dElecPlot( app, 'fs_proj_elec', 'fs_yoke',...
                            coord2plot,...
                            app.userdata.proj_elec_color,...
                            app.userdata.proj_elec_markersize);
                    end
                    
                else
                    Clear3dElecPlot( app, app.fighandle.fs_proj_elec);
                end % plot projected electrodes
                
                hold( app.fighandle.fs_yoke, 'off');
            end % surface plot in individual space
            
            if ~isempty( app.fighandle.mnisurf_yoke) ...
                    && isvalid( app.fighandle.mnisurf_yoke) ...
                    && ~isempty( app.userdata.reg.coord2std)
                
                hold( app.fighandle.mnisurf_yoke, 'on');
                
                if strcmpi( app.userdata.show_all_elec, 'yes')
                    Update3dElecPlot( app, 'mni_all_elec', 'mnisurf_yoke',...
                        app.userdata.reg.coord2std,...
                        app.userdata.elec_color,...
                        app.userdata.elec_markersize);
                else
                    Clear3dElecPlot( app, app.fighandle.mni_all_elec);
                end
                
                if strcmpi( app.userdata.show_cur_elec, 'yes')
                    Update3dElecPlot( app, 'mni_cur_elec', 'mnisurf_yoke',...
                        app.userdata.reg.coord2std( cur_elec_loc, :),...
                        app.userdata.cur_elec_color,...
                        app.userdata.cur_elec_markersize);
                else
                    Clear3dElecPlot( app, app.fighandle.mni_cur_elec);
                end
                
                % plot projected electrodes
                if strcmpi( app.userdata.show_proj, 'yes')
                    if ~isempty( app.fighandle.std_proj_elec)
                        delete( app.fighandle.std_proj_elec);
                    end
                    
                    loc = find( ismember( app.userdata.proj.label, val));
                    coord2plot = [];
                    for k = 1 : length( loc)
                        if app.userdata.proj.status( loc(k)) == 0
                            continue;
                        end
                        if size( app.userdata.proj.coord2std, 1) >= loc(k)
                            coord2plot = cat( 1, coord2plot, app.userdata.proj.coord2std( loc(k), :));
                        end
                    end
                    
                    if ~isempty( coord2plot)
                        app.fighandle.std_proj_elec = plot3( app.fighandle.mnisurf_yoke,...
                            coord2plot( :, 1),...
                            coord2plot( :, 2),...
                            coord2plot( :, 3),...
                            'o',...
                            'color', app.userdata.proj_elec_color,...
                            'markerfacecolor', app.userdata.proj_elec_color,...
                            'markersize', app.userdata.proj_elec_markersize);
                    end
                    
                else
                    Clear3dElecPlot( app, app.fighandle.std_proj_elec);
                end % projected electroded in MNI space
                
                hold( app.fighandle.mnisurf_yoke, 'off');
            end % MNI surface plot
            
            set( app.StatusTextArea, 'Text', 'Ready.', 'FontColor', 'b');
        end % function UpdateYoke
   
        
        function ChangeSurfOpacity(app)
            % brain surface transparency
            val = app.SurfaceOpacitySlider.Value/100;
            if ~isempty( app.fighandle.fs_yoke)...
                    && isvalid( app.fighandle.fs_yoke) ...
                    && ~isempty( app.fighandle.fssurf.lh)
            
                alpha( app.fighandle.fssurf.lh, val);
                alpha( app.fighandle.fssurf.rh, val);
            end
            
            if ~isempty( app.fighandle.mnisurf_yoke)...
                    && isvalid( app.fighandle.mnisurf_yoke)...
                    && ~isempty( app.fighandle.mnisurf.lh)  
            
                alpha( app.fighandle.mnisurf.lh, val);
                alpha( app.fighandle.mnisurf.rh, val);
            end
        end
        
        
        function Eviewer_AtlasQuery( app)
            % look for Freesufer segmentation
            is_fs_performed = 'yes';
            fs_atlas_result = [];
            if ~isempty( app.userdata.reg.surfvol)
                subj_mridir = fileparts( app.userdata.reg.surfvol);
                file = fullfile( subj_mridir, 'aparc+aseg-in-rawavg.nii.gz');
                tmp = mfilename( 'fullpath');
                tmp = fileparts( tmp);
                lut_file = fullfile( tmp, 'Freesurfer_LUT.txt');
                
                if exist( file, 'file') && exist( lut_file, 'file') && ~isempty( app.userdata.srcdata.t1)                
                    
                    app.userdata.atlas.fs_aseg.mri = MRIread( file);
                    app.userdata.atlas.fs_aseg.mri.vol = permute( app.userdata.atlas.fs_aseg.mri.vol, [2, 1, 3]);
                    % freesurfer lookup table
                    app.userdata.atlas.fs_aseg.lut = '';
                    
                    lut = importdata( lut_file);
                    lut_num = cellfun( @(x) num2str( x), lut.textdata(:, 1), 'un', 0);
                    
                    if ~all( size( app.userdata.srcdata.t1.vol) == size( app.userdata.atlas.fs_aseg.mri.vol))
                        app.StatusTextArea.Text = 'Individual anatomical image and Freesurfer atlas are not matched.';
                        app.StatusTextArea.FontColor = 'b';
                        drawnow;
                        
                    else
                        fs_atlas_result = cell( length( app.userdata.elec.label), 2);
                        
                        nb_labels = length( app.userdata.elec.label);                        
                        for elec_idx = 1 : nb_labels                         
                            app.StatusTextArea.Text = sprintf( 'Atlas query using Freesurfer %.0f%%', ...
                                100 * elec_idx/nb_labels);
                            app.StatusTextArea.FontColor = 'b';
                            drawnow;
                            
                            if app.userdata.proj.status( elec_idx) == 1
                                % projected electrodes
                                stdcoord = app.userdata.proj.coord2t1( elec_idx, :)';
                                proj_used = 'yes';
                                
                            else
                                stdcoord = app.userdata.reg.coord2t1( elec_idx, :)';
                                proj_used = 'no';
                            end
                            
                            imgcoord = round( app.userdata.srcdata.t1.vox2ras1 \ [stdcoord; 1]);
                            
                            img_dims = size( app.userdata.srcdata.t1.vol);
                            [xx, yy, zz] = ndgrid( 1:img_dims(1), 1:img_dims(2), 1:img_dims(3));
                            
                            resol = app.userdata.srcdata.t1.volres( [2 1 3]);
                            ss = sqrt( (resol(1) *(xx - imgcoord(1))).^2 ...
                                + (resol(2) *( yy - imgcoord(2))).^2 ...
                                + (resol(3)*(zz - imgcoord(3))).^2) <= app.userdata.atlas.radius + 2*eps;
                            
                            lut_val = app.userdata.atlas.fs_aseg.mri.vol( ss == 1);
                            [lut_val, ~, lut_val_ind] = unique( lut_val(:));
                            lut_val = cat( 1, app.userdata.atlas.fs_aseg.mri.vol( imgcoord(1), imgcoord(2), imgcoord(3)), lut_val);
                            
                            label_lookup = cell( length( lut_val), 1);
                            for k = 1 : length( lut_val)
                                loc = find( cell2mat( cellfun( @(x) str2num(x) == lut_val( k), lut_num, 'un', 0)));
                                
                                prefix = '';
                                if k > 1
                                    N = sum( lut_val_ind == (k-1)) / length( lut_val_ind);
                                    N = num2str( round( N * 10000)/100);
                                    prefix = [N, '%: '];
                                end
                                
                                if ~isempty( loc)
                                    label_lookup{ k, 1} = [prefix, lut.textdata{ loc, 2}];
                                else
                                    label_lookup{ k, 1} = [prefix, 'Not found'];
                                end
                            end
                            
                            %label_lookup{ k + 1, 1} = [['Radius: ', ...
                            %    num2str( app.userdata.atlas.radius), ' mm; '],...
                            %    [num2str( length( lut_val_ind)), ' Voxels']];
                            label_lookup{ k + 1, 1} = sprintf( 'Radius: %.g mm. %d voxels',...
                                app.userdata.atlas.radius, length( lut_val_ind));                            
                            
                            fs_atlas_result{ elec_idx, 1} = label_lookup;
                            fs_atlas_result{ elec_idx, 2} = proj_used;
                        end % all electrodes
 
                        app.StatusTextArea.Text = 'Freesurfer atlas query completes.';
                        drawnow;
                    end
                end
            end % Freesurfer atlas lookup
            
            app.userdata.atlas.query.fsaseg = fs_atlas_result;
            
            if isempty( fs_atlas_result)
                is_fs_performed = 'no';
            end
            
            % Harvard-Oxford atlas
            is_mni_performed = 'yes';
            if ~isempty( app.userdata.reg.coord2std)                
                app.StatusTextArea.Text = 'Atlas query using FSL ...';
                app.StatusTextArea.FontColor = 'b';
                drawnow;
                
                app.userdata.atlas.query.fsl = {};
                coord = app.userdata.reg.coord2std;
                loc = find( app.userdata.proj.status == 1);
                coord( loc, :) = app.userdata.proj.coord2std( loc, :);
                
                coord_label = AtlasQuery_HarvardOxford( coord);
                app.StatusTextArea.Text = 'Atlas query using FSL     Done.';
                drawnow;                
                
                app.userdata.atlas.query.fsl( :, 2) = repmat( {'no'}, size( coord, 1), 1);
                
                if isempty( coord_label)
                    is_mni_performed = 'no';
                    
                else
                    app.userdata.atlas.query.fsl( :, 1) = coord_label;
                    app.userdata.atlas.query.fsl( loc, 2) = repmat( {'yes'}, length( loc), 1);
                end
            end
            
            txt = '';
            if strcmpi( is_fs_performed, 'no')
                txt = 'Freesurfer atlas query failed.';
            end
            
            if strcmpi( is_mni_performed, 'no')
                if ~isempty( txt)
                    txt = 'Both Freesurfer and FSL atlas query failed.';
                else
                    txt = 'FSL atlas query failed.';
                end                
            end       
            
            if isempty( txt)               
                txt = 'Atlas query succeeded.';
            end
            
            app.StatusTextArea.Text = txt;
            app.StatusTextArea.FontColor = 'r';                
        end % function Eviewer_AtlasQuery
        
             
        function Eviewer_CloseFigs(app)
            % close all other figures
            figs2close = {app.userdata.surf_yoke_name,...
                app.userdata.mni_surf_yoke_name,...
                app.userdata.misc.t13d_figname,...
                app.userdata.misc.std3d_figname};
            
            for k = 1 : length( figs2close)
                f = findobj( 'name', figs2close{k});
                if ~isempty( f)
                    delete( f);
                end
            end  
            
            app.fighandle.fs_yoke = [];
            % handle to surface patch
            app.fighandle.fssurf.lh = [];
            app.fighandle.fssurf.rh = [];
            
            app.fighandle.fs_all_elec = [];
            app.fighandle.fs_cur_elec = [];
            app.fighandle.fs_proj_elc = [];
            
            app.fighandle.mnisurf_yoke = [];
            app.fighandle.mnisurf.lh = [];
            app.fighandle.mnisurf.rh = [];
            
            app.fighandle.mni_all_elec = [];
            app.fighandle.mni_cur_elec = [];
            app.fighandle.mni_proj_elec = [];
                        
            app.fighandle.t13d_fig = [];
            app.fighandle.std3d_fig = [];
            app.fighandle.fs_proj_elec = [];
            app.fighandle.std_proj_elec = [];
            
            % individual surface handles
            app.fighandle.fsannot = [];
            app.fighandle.fsannot.lh = [];
            app.fighandle.fsannot.rh = [];
            
            % Freesurfer MNI surface handles
            app.fighandle.mniannot = [];
            app.fighandle.mniannot.lh = [];
            app.fighandle.mniannot.rh = [];   
            
        end % function Eviewer_CloseFigs
    
        
        function to_quit = Eviewer_SaveData(app)
            to_quit = 'yes';
            if strcmpi( app.userdata.data_saved, 'no')...
                    && ~isempty( app.userdata.elec)
                
                answer = questdlg( sprintf( 'Results have not been saved.\nAre you sure to quit?'),...
                    'Exit', 'Yes', 'No', 'Cancel', 'No');
                if ~strcmpi( answer, 'yes')
                    to_quit = 'no';
                end
            end
        end
    
        
        function InPlot( app)   
            set( app.StatusTextArea, 'Text', 'Updating plots ...', 'Fontcolor', 'b');
            drawnow;
                            
            InitializeYoke( app);   
            
            try
                fns = fieldnames( app.userdata.srcdata.fssurf);
                for k = 1 : length( fns)
                    set( app.StatusTextArea, 'Fontcolor', 'b', ...
                        'Text',  'Plotting individual cortical surface ... please wait');
                    drawnow;
                    fname = fns{ k};
                    if k == 1
                        app.fighandle.fssurf.( fname) = SurfPlot( app.fighandle.fs_yoke, ...
                            app.userdata.srcdata.fssurf.( fname).vert, app.userdata.srcdata.fssurf.( fname).tri);
                    else
                        app.fighandle.fssurf.( fname) = SurfPlot( app.fighandle.fs_yoke,...
                            app.userdata.srcdata.fssurf.( fname).vert, app.userdata.srcdata.fssurf.( fname).tri, 'hold', 'on');
                    end

                    app.fighandle.fsannot.( fname) = SurfPlot( app.fighandle.fs_yoke,...
                        app.userdata.srcdata.fssurf.( fname).vert, app.userdata.srcdata.fssurf.( fname).tri,...
                        'fc', app.userdata.srcdata.fssurf.( fname).label_color/255, ...
                        'vert_alpha', app.userdata.srcdata.fssurf.( fname).label_alpha, 'hold', 'on');
                end

                %alpha( app.fighandle.fs_yoke, app.SurfaceOpacitySlider.Value/100);
                if ~isempty( app.fighandle.fssurf.( fname))
                    alpha( app.fighandle.fssurf.( fname), app.SurfaceOpacitySlider.Value/100);
                    %alpha( app.fighandle.fssurf.rh, app.SurfaceOpacitySlider.Value/100);
                end

            catch
                % failed to read surface
            end
                
            
            % MNI brain surface   
            set( app.StatusTextArea, 'Text', 'Plotting MNI brain surface ... please wait', 'Fontcolor', 'b');
            drawnow;
            
            InitializeMNISurfYoke( app);   
            
            % created using CreateSurf    
            if ~isempty( app.userdata.srcdata.mnisurf) && isfield( app.userdata.srcdata.mnisurf, 'vert')
                SurfPlot( app.fighandle.mnisurf_yoke, app.userdata.srcdata.mnisurf.vert, app.userdata.srcdata.mnisurf.tri);
                alpha( app.fighandle.mnisurf_yoke, app.SurfaceOpacitySlider.Value/100);

            elseif ~isempty( app.userdata.srcdata.mnisurf) && isfield( app.userdata.srcdata.mnisurf, 'lh')
                % freesurfer MNI surface
                for hemi = {'lh', 'rh'}
                    h = hemi{1};
                    if ~isfield( app.userdata.srcdata.mnisurf, h) || ~isfield( app.userdata.srcdata.mnisurf.( h), 'vert')
                        continue;
                    end

                    if strcmpi( h, 'lh')
                        app.fighandle.mnisurf.(h) = SurfPlot( app.fighandle.mnisurf_yoke,...
                            app.userdata.srcdata.mnisurf.( h).vert, app.userdata.srcdata.mnisurf.( h).tri);
                    else
                        app.fighandle.mnisurf.(h) = SurfPlot( app.fighandle.mnisurf_yoke,...
                            app.userdata.srcdata.mnisurf.( h).vert, app.userdata.srcdata.mnisurf.( h).tri, 'hold', 'on');
                    end

                    % paracellation
                    if ~isempty( app.userdata.srcdata.mniannot.(h))
                        [c, ~, ic] = unique( app.userdata.srcdata.mniannot.(h).label);
                        [~, locb] = ismember(c, app.userdata.srcdata.mniannot.(h).colortable.table( :, 5));
                        label_color = app.userdata.srcdata.mniannot.(h).colortable.table( locb, 1:3);
                        label_color = label_color( ic, :);

                        label_alpha = app.userdata.srcdata.mniannot.(h).colortable.table( :, 6);
                        label_alpha = label_alpha( ic);

                        app.fighandle.mniannot.(h) = SurfPlot( app.fighandle.mnisurf_yoke,...
                            app.userdata.srcdata.mnisurf.( h).vert, app.userdata.srcdata.mnisurf.( h).tri,...
                            'fc', label_color/255, 'vert_alpha', label_alpha, 'hold', 'on');
                    end
                end

                if ~isempty( app.fighandle.mnisurf.lh)
                    alpha( app.fighandle.mnisurf.lh, app.SurfaceOpacitySlider.Value/100);
                    alpha( app.fighandle.mnisurf.rh, app.SurfaceOpacitySlider.Value/100);
                end

            else
                % do nothing
            end
            
            UpdateYoke( app);           
            
            if ~isempty( app.ElecLabelListbox.Items) > 0
                app.ElecLabelListbox.Value = app.ElecLabelListbox.Items(1);
                ElecLabelListboxValueChanged( app, []);
            end
                                        
            set( app.StatusTextArea, 'Text', 'Ready.', 'FontColor', 'b');
            drawnow;
        end % Plot
        
        
        function UpdateT1Clim( app)
            h = findall( app.fighandle.t13d_fig, 'type', 'axes');
            for k = 1 : length( h)
                caxis( h(k), app.userdata.srcdata.t1_clim);
            end            
        end
        
        
        function UpdateStdClim( app)
            h = findall( app.fighandle.std3d_fig, 'type', 'axes');
            for k = 1 : length( h)
                caxis( h(k), app.userdata.srcdata.std_clim);
            end
        end        


        function s = OrientLabel(~, vox2ras0)
            s = cell( 1, 6);
            s(:) = {''};
            labels = { {'P', 'A'}, {'L', 'R'}, {'I', 'S'}};
            ars = vox2ras0( 1:3, [2, 1, 3]);
            [~, midx] = max( abs( ars), [], 2);
            for k = 1 : 3
                ind = 2*(k-1) + 1 : 2*k;
                if ars( k, midx(k)) > 0
                    s( ind) = labels{ midx(k)}( [1, 2]);
                else
                    s( ind) = labels{ midx(k)}( [2, 1]);
                end
            end
        end
    end % Methods    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize userdata structure
            Eviewer_InitializeUserdata( app);
            
            % close all dependent figures
            Eviewer_CloseFigs( app);    
            
            warning off MATLAB:subscripting:noSubscriptsSpecified
            
            app.TabGroup.Visible = 'off';
            
            app.StatusTextArea.FontSize = 14;
        end

        % Menu selected function: LoadMenu
        function LoadDataButtonPushed(app, event)
            [file, p] = uigetfile( fullfile( app.userdata.srcdir, '*.mat'));
            
            orig_file = fullfile( p, file);
            if isequal( file, 0)
                % file selection failure
                return;
            end
            
            Eviewer_InitializeUserdata( app);
            Eviewer_CloseFigs( app);
                        
            try
                file = fullfile( p, file);
                app.userdata.srcdir = p;
                elec = load( file);
                elec = elec.elec;
                app.userdata.elec = elec;
                app.userdata.filename = file;
                               
                if isfield( elec, 'reg')
                    app.userdata.reg = elec.reg;
                end
                
                if isfield( elec, 'proj')
                    app.userdata.proj = elec.proj;
                end
                
                if isfield( elec, 'atlas') && isstruct( elec.atlas) ...
                        && isequal( fieldnames( elec.atlas), fieldnames( app.userdata.atlas.query))
                    app.userdata.atlas.query = elec.atlas;
                end
                
                app.TabGroup.Visible = 'on';
                
                % set GUI options
                Eviewer_InitializeTransform( app);            
                
                
                % load previously saved brain and surface images
                if isfield( elec, 'srcdata')
                    app.userdata.srcdata = elec.srcdata;
          
                    % read t1 and standard brain
                    if ~isempty( app.userdata.srcdata.t1)  
                        app.T1IntensityLo.Value = app.userdata.srcdata.t1_clim(1);
                        app.T1IntensityHi.Value = app.userdata.srcdata.t1_clim(2);
                    end
    
                    % read MNI512 standard brain
                    if ~isempty( app.userdata.srcdata.std)    
                        app.MNIintensityLo.Value = app.userdata.srcdata.std_clim(1);
                        app.MNIIntensityHi.Value = app.userdata.srcdata.std_clim(2);
                    end
        
                    % app.userdata.srcdata.surfmri    
                    if ~isempty( app.userdata.srcdata.mniannot) && ~isempty( app.userdata.srcdata.mniannot.rh)
                        app.FSParcellationListBox.Items = app.userdata.srcdata.mniannot.rh.colortable.struct_names;
                    end
                end                
                
                ChangeVisibility( app, 'on');
                
                app.StatusTextArea.Text = ['Data loaded successfully. ' ...
                    'Press "Show plot" to visualize results'];
                app.StatusTextArea.FontColor = 'b';                
                
            catch
                %warndlg( 'Failed to load the electrode location data.', 'Unsupported file');
                app.StatusTextArea.Text = ['Failed to load ', orig_file];
                app.StatusTextArea.FontColor = 'r';
                
                ChangeVisibility( app, 'off');
                app.TabGroup.Visible = 'off';
            end           
         
        end

        % Value changed function: ElecLabelGroupListbox
        function ElecLabelGroupListboxValueChanged(app, event)
            if isfield( app.userdata, 'label_grp')
                value = app.ElecLabelGroupListbox.Value;
                loc = ismember( app.userdata.label_grp( :, 1), value);
                selected_label = app.userdata.label_grp( loc, 3);
                selected_label = cat( 1, selected_label{:});
                app.ElecLabelListbox.Value = selected_label;
                if iscell( value)
                    value = strjoin( value, ', ');
                end
                
                app.ElecLabelGroupInfo.Text = [value, ' ', num2str( length( selected_label)), ' labels'];
                ElecLabelListboxValueChanged(app);
            end
        end

        % Value changed function: ElecLabelListbox
        function ElecLabelListboxValueChanged(app, event)
            labels = app.ElecLabelListbox.Value;
            if isempty( labels)
                return;
            end
            
            grp = ECoG_GroupLabels( cellstr( labels));
            s = cell( size( grp, 1), 1);
            for k = 1 : size( grp, 1)
                loc = ismember( app.userdata.label_grp( :, 1), grp{ k, 1});
                s{ k, 1} = [grp{ k, 1}, ': ',...
                    num2str( length( grp{ k, 2})), '/',...
                    num2str( length( app.userdata.label_grp{ loc, 2}))];
            end
            s( end+1, 1) = {['Total: ', num2str( length( labels)), ' labels.']};
            app.SelectedLabelListbox.Items = s;
            
            % axial, sagittal and cronal views
            if length( labels) == 1
                elec_loc = find( ismember( app.userdata.elec.label, labels));
                % plot electrode on individual anatomical iamge
                if ~isempty( app.fighandle.t13d_fig)...
                        && isvalid(app.fighandle.t13d_fig)...
                        && ~isempty(app.userdata.srcdata.t1) ...
                        && ~isempty( app.userdata.reg.coord2t1)
                                                            
                    if size( app.userdata.proj.coord2t1, 1) >=  elec_loc ...
                            && app.userdata.proj.status( elec_loc) == 1
                        % use projected coordinates
                        stdcoord = app.userdata.proj.coord2t1( elec_loc, :)';                        
                    else
                        stdcoord = app.userdata.reg.coord2t1( elec_loc, :)';
                    end
                    % image -> voxel
                    imgcoord = round( app.userdata.srcdata.t1.vox2ras1 \ [stdcoord; 1]);
                    
                    set( 0, 'CurrentFigure', app.fighandle.t13d_fig);
                    clf( app.fighandle.t13d_fig);
                    
                    subplot( 222);
                    imagesc( gca, squeeze( app.userdata.srcdata.t1.vol( :, imgcoord(2), :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.t1.volres( [3, 2, 1]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    
                    caxis( gca, app.userdata.srcdata.t1_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    plot( gca, imgcoord( 1), imgcoord( 3),...
                        'ro', ...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);                    
                    axis off
                    
                    s = OrientLabel( app, app.userdata.srcdata.t1.vox2ras0);
                    sz = size( app.userdata.srcdata.t1.vol);
                    text( 0, sz(3)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(3)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);

                    subplot( 221);
                    imagesc( gca, squeeze( app.userdata.srcdata.t1.vol( imgcoord(1), :, :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.t1.volres( [3, 1, 2]),...
                        'YDir', 'normal', ...
                        'XTick', [], ...
                        'ytick', [], ...
                        'box', 'off');
                    caxis( gca, app.userdata.srcdata.t1_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    plot( gca, imgcoord( 2), imgcoord( 3),...
                        'ro', 'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    
                    axis off
                                        
                    subplot( 223);
                    imagesc( gca, squeeze( app.userdata.srcdata.t1.vol( :, :, imgcoord(3)))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.t1.volres( [1, 2, 3]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    caxis( gca, app.userdata.srcdata.t1_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    plot( gca, imgcoord( 1), imgcoord( 2),...
                        'ro', 'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    colormap( gray);
                    axis off   

                    text( 0, sz(2)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(2)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);
                end
                
                % plot electrode on standard MNI brain
                if ~isempty( app.fighandle.std3d_fig)...
                        && isvalid(app.fighandle.std3d_fig)...
                        && ~isempty( app.userdata.srcdata.std) ...
                        && ~isempty( app.userdata.reg.coord2std)
                                        
                    if size( app.userdata.proj.coord2std, 1) >= elec_loc ...
                            && app.userdata.proj.status( elec_loc) == 1
                        stdcoord = app.userdata.proj.coord2std( elec_loc, :)';
                    else
                        stdcoord = app.userdata.reg.coord2std( elec_loc, :)';
                    end
                    
                    imgcoord = round( app.userdata.srcdata.std.vox2ras1 \ [stdcoord; 1]);
                    
                    set( 0, 'CurrentFigure', app.fighandle.std3d_fig);
                    clf( app.fighandle.std3d_fig);
                    
                    subplot( 222);
                    imagesc( gca, squeeze( app.userdata.srcdata.std.vol( :, imgcoord(2), :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.std.volres( [3, 2, 1]),... 
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    caxis( gca, app.userdata.srcdata.std_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    plot( gca, imgcoord( 1), imgcoord( 3),...
                        'ro',...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    axis equal
                    axis off

                    s = OrientLabel( app, app.userdata.srcdata.std.vox2ras0);
                    sz = size( app.userdata.srcdata.std.vol);
                    text( 0, sz(3)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(3)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    
                    subplot( 221);
                    imagesc( gca, squeeze( app.userdata.srcdata.std.vol( imgcoord(1), :, :))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.std.volres( [3, 1, 2]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    caxis( gca, app.userdata.srcdata.std_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    plot( gca, imgcoord( 2), imgcoord( 3),...
                        'ro',...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    axis equal
                    axis off
                    
                    subplot( 223);
                    imagesc( gca, squeeze( app.userdata.srcdata.std.vol( :, :, imgcoord(3)))');
                    set( gca,...
                        'dataaspectratio', app.userdata.srcdata.std.volres( [1, 2, 3]),...
                        'YDir', 'normal',...
                        'XTick', [],...
                        'ytick', [], ...
                        'box', 'off');
                    
                    caxis( gca, app.userdata.srcdata.std_clim);
                    disableDefaultInteractivity( gca);
                    hold on;
                    plot( gca, imgcoord( 1), imgcoord( 2),...
                        'ro',...
                        'MarkerFaceColor', 'r',...
                        'MarkerEdgeColor', 'none',...
                        'MarkerSize', app.userdata.markersize_3d);
                    colormap( gray);
                    axis equal
                    axis off
                               
                    text( 0, sz(2)/2, s{1}, 'FontSize', 12, 'color', app.userdata.elec_color);
                    text( sz(1)+1, sz(2)/2, s{2}, 'FontSize', 12, 'color', app.userdata.elec_color);

                    subplot( 224);
                    cla( gca);
                    fontsize = 12;
                    fontcolor = 'w';
                    t = text( 0, 4.1, 'MNI coordinates (mm):');
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    t = text( 0.1, 3.5, sprintf( 'x = %.0f', stdcoord(1)));
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    t = text( 0.1, 3, sprintf( 'y = %.0f', stdcoord(2)));
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    t = text( 0.1, 2.5, sprintf( 'z = %.0f', stdcoord(3)));
                    t.FontSize = fontsize;
                    t.Color = fontcolor;
                    set( gca, 'box', 'off', 'YLim', [0, 5]);
                    axis off
                end
                
                % ATLAS
                atlas_str = {};
                if ~isempty( app.userdata.atlas.query.fsaseg)
                    atlas_str = cat( 1, atlas_str, ...
                        {'Freesurfer:'},...
                        app.userdata.atlas.query.fsaseg{ elec_loc, 1},...
                        ['Projected: ', upper( app.userdata.atlas.query.fsaseg{ elec_loc, 2})]);
                end
                
                if ~isempty( app.userdata.atlas.query.fsl)
                    atlas_str = cat( 1, atlas_str, ...
                        {''},...
                        {'FSL:'},...
                        app.userdata.atlas.query.fsl{ elec_loc, 1});
                end
                
                atlas_str( cellfun(@isempty, atlas_str)) = {' '};
                app.AtlasListbox.Items = atlas_str';
                if ~isempty( atlas_str)                    
                    app.AtlasListbox.Value = atlas_str{1};
                else
                    app.AtlasListbox.Value = {};
                end                
            end % if only one electrode is selected
            
            %% update yoke figures
            UpdateYoke( app);
        end

        % Value changed function: SurfaceOpacitySlider
        function SurfaceOpacitySliderValueChanged(app, event)
            value = app.SurfaceOpacitySlider.Value;
            app.OpacityEdit.Value = value / 100;            
            ChangeSurfOpacity(app);
        end

        % Value changed function: OpacityEdit
        function OpacityEditValueChanged(app, event)
            value = app.OpacityEdit.Value;
            if value >= 0 && value <= 1
                app.SurfaceOpacitySlider.Value = 100 * value;
                ChangeSurfOpacity(app);
            else
                app.OpacityEdit.Value = app.SurfaceOpacitySlider.Value / 100;
            end
        end

        % Close request function: EVIEWERUIFigure
        function EVIEWERUIFigureCloseRequest(app, event)
            to_quit = Eviewer_SaveData( app);
            if strcmpi( to_quit, 'no')
                return;
            end
            
            Eviewer_CloseFigs( app);            
            delete(app);            
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            Eviewer_AtlasQuery(app);
        end

        % Button pushed function: ShowAtlasButton
        function ShowAtlasButtonPushed(app, event)
            if ~isempty( app.userdata.atlas.query.fsaseg)
                labels = app.userdata.atlas.query.fsaseg( :, 1);
                labels = cellfun( @(x) x{1}, labels, 'UniformOutput', false);
                labels = unique( labels);
                app.AtlasGroupListbox.Items = labels;
            end
        end

        % Callback function
        function GridSizeEditFieldValueChanged2(app, event)
            val = str2num( app.GridSizeEditField.Value);
            if length( val) == 2 && all( abs(val - round( val))) < 2*eps
                app.userdata.proj.gridsize = val;
            else
                app.GridSizeEditField.Value = '';
            end
        end

        % Value changed function: AtlasGroupListbox
        function AtlasGroupListboxValueChanged(app, event)
            value = app.AtlasGroupListbox.Value;
            if isfield( app.userdata, 'label_grp')
                labels = app.userdata.atlas.query.fsaseg( :, 1);
                labels = cellfun( @(x) x{1}, labels, 'UniformOutput', false);
                
                loc = ismember( labels( :, 1), value);
                app.ElecLabelListbox.Value = app.userdata.elec.label( loc);
                ElecLabelListboxValueChanged( app);
            end
        end

        % Callback function
        function ApplyProjButtonPushed(app, event)
            [status] = Eviewer_ElecProj( app, event);
            if status == 0
                %app.StatusTextArea.Text = 'Updating atlas query results.';
                %app.StatusTextArea.FontColor = 'r';
                %Eviewer_AtlasQuery( app);                
                %app.StatusTextArea.Text = 'Atlas query results were updated.';
                %app.StatusTextArea.FontColor = 'b';

                app.StatusTextArea.Text = 'Please remember to update atlas query results.';
                app.StatusTextArea.FontColor = 'r';
                drawnow;
                %warndlg( 'Remember to update atlas query results.', 'Coordinates corrected');
            end
        end

        % Callback function
        function PreviewProjButtonPushed(app, event)
            Eviewer_ElecProj( app, event);
        end

        % Callback function
        function ClearProjButtonPushed(app, event)
            answer = questdlg( 'Are you sure to clear projection?',...
                'Warning', 'Yes', 'No', 'Cancel', 'No');
            if strcmpi( answer, 'yes')
                app.userdata.proj = [];
                app.userdata.proj.coord = [];
                app.userdata.proj.coord2t1 = [];
                app.userdata.proj.coord2std = [];
                app.userdata.proj.coord2surf = [];
                app.userdata.proj.label = {};
                app.userdata.proj.status = [];
                app.userdata.proj.hist.label = {};
                app.userdata.proj.hist.gridsize = [];
                app.userdata.proj.hist.method = {};     
                
                app.StatusTextArea.Text = 'Please remember to update atlas query results.';
                app.StatusTextArea.FontColor = 'r';
                % warndlg( 'Remember to update Atlas Query results.');
            end
        end

        % Menu selected function: SaveMenu
        function SaveResultButtonPushed(app, event)
            if ~isempty( app.userdata.elec)
                elec = app.userdata.elec;
                
                % keep all transformation information
                elec.reg = app.userdata.reg;                
                elec.proj = app.userdata.proj;                
                elec.atlas = app.userdata.atlas.query;
                elec.srcdata = app.userdata.srcdata;
                                
                uisave( 'elec', fullfile( app.userdata.srcdir, 'eplace_reg'));
                app.userdata.data_saved = 'yes';   
                
            else
                app.StatusTextArea.Text = 'Nothing to save.';
                app.StatusTextArea.FontColor = 'r';
            end
            
        end

        % Value changed function: FSsurfOpacitySlider
        function FSsurfOpacitySliderValueChanged(app, event)
            set( app.StatusTextArea, 'Text', 'Updating annotations ...', 'FontColor', 'b');
            drawnow;
            
            value = app.FSsurfOpacitySlider.Value / 100;
            for annot = {'fsannot', 'mniannot'}
                a = annot{1};
                tmp = app.userdata.srcdata.(a).lh;
                if isfield( tmp, 'colortable') && isfield( tmp, 'FaceVertexAlphaData')
                    app.fighandle.(a).lh.FaceVertexAlphaData = zeros( length( tmp.lh.label), 1);
                    
                    if app.FSsurfLhCheckBox.Value == 1
                        % struct name index
                        loc = ismember( tmp.colortable.struct_names, app.FSParcellationListBox.Value);
                        % vertex index
                        vert_ind = ismember( tmp.lh.label, tmp.lh.colortable.table( loc, 5));
                        app.fighandle.srcdata.(a).lh.FaceAlpha = 'interp';
                        app.fighandle.srcdata.(a).lh.FaceVertexAlphaData( vert_ind) = value * ones( sum( vert_ind), 1);
                    end
                end
                
                tmp = app.userdata.srcdata.(a).rh;
                if isfield( tmp, 'colortable') && isfield( tmp, 'FaceVertexAlphaData')
                    app.fighandle.(a).rh.FaceVertexAlphaData = zeros( length( tmp.rh.label), 1);
                    
                    if app.FSsurfRhCheckBox.Value == 1
                        % struct name index
                        loc = ismember( tmp.rh.colortable.struct_names, app.FSParcellationListBox.Value);
                        % vertex index
                        vert_ind = ismember( tmp.rh.label, tmp.rh.colortable.table( loc, 5));
                        app.fighandle.srcdata.(a).rh.FaceAlpha = 'interp';
                        app.fighandle.srcdata.(a).rh.FaceVertexAlphaData( vert_ind) = value * ones( sum( vert_ind), 1);
                    end
                end
            end
               
            set( app.StatusTextArea, 'Text', 'Ready.', 'FontColor', 'b');
            drawnow;
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            help_doc = {'Step 1. Load data.',...
                'Step 2. Setup transformation matrix.'};
            
            app.StatusTextArea.Text = help_doc;
            app.StatusTextArea.FontColor = 'k';
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            to_quit = Eviewer_SaveData( app);
            if strcmpi( to_quit, 'no')
                return;
            end
            
            Eviewer_CloseFigs( app);
            delete( app);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            % close all dependent figures
            Eviewer_CloseFigs( app);    
            
            % initlize userdata structure
            Eviewer_InitializeUserdata( app);  
            
            % initlize userdata structure objects
            Eviewer_InitializeTransform( app);
            
            app.StatusTextArea.Text = '';
            app.FSParcellationListBox.Items = {};
            
            ChangeVisibility( app, 'off');
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            
        end

        % Menu selected function: CurrentdatasetMenu
        function CurrentdatasetMenuSelected(app, event)
            if ~isempty( app.userdata.filename)
                app.StatusTextArea.Text = ['Dataset: ', app.userdata.filename];
            else
                app.StatusTextArea.Text = 'No dataset loaded';
            end
            app.StatusTextArea.FontColor = 'k';
        end

        % Menu selected function: enormMenu
        function enormMenuSelected(app, event)
            app.StatusTextArea.Text = 'This is a GUI for visulizing eplace results.';
            app.StatusTextArea.FontColor = 'k';
        end

        % Selection changed function: ElectrodestoshowButtonGroup
        function ElectrodestoshowButtonGroupSelectionChanged(app, event)
            selectedButton = app.ElectrodestoshowButtonGroup.SelectedObject;
            
            if strcmpi( selectedButton.Text, 'Show all electrodes')
                app.userdata.show_all_elec = 'yes';
                app.userdata.show_cur_elec = 'yes';
            elseif strcmpi( selectedButton.Text, 'Show selected electrodes only')
                app.userdata.show_all_elec = 'no';
                app.userdata.show_cur_elec = 'yes';
            else
                app.userdata.show_all_elec = 'yes';
                app.userdata.show_cur_elec = 'no';
            end
            UpdateYoke( app);
        end

        % Value changed function: ShowprojectedelectrodesCheckBox
        function ShowprojectedelectrodesCheckBoxValueChanged(app, event)
            value = app.ShowprojectedelectrodesCheckBox.Value;
            if value == 1
                app.userdata.show_proj = 'yes';
            else
                app.userdata.show_proj = 'no';
            end
            UpdateYoke( app);
        end

        % Button pushed function: ChangeelectrodescolorButton
        function ChangeelectrodescolorButtonPushed(app, event)
            c = uisetcolor;
            if length( c) == 1
                % do nothing
            else
                app.userdata.elec_color = c;
                UpdateYoke( app);
            end
        end

        % Button pushed function: ChangeselectedelectrodescolorButton
        function ChangeselectedelectrodescolorButtonPushed(app, event)
            c = uisetcolor;
            if length( c) == 1
                % do nothing
            else
                app.userdata.cur_elec_color = c;
                UpdateYoke( app);
            end
        end

        % Button pushed function: ChangeprojectedelectrodescolorButton
        function ChangeprojectedelectrodescolorButtonPushed(app, event)
            c = uisetcolor;
            if length( c) == 1
                % do nothing
            else
                app.userdata.proj_elec_color = c;
                UpdateYoke( app);
            end
        end

        % Value changed function: ElectrodessizeEditField
        function ElectrodessizeEditFieldValueChanged(app, event)
            value = app.ElectrodessizeEditField.Value;
            
            app.userdata.elec_markersize = value;
            UpdateYoke( app);
        end

        % Value changed function: SelectedelectrodesizeEditField
        function SelectedelectrodesizeEditFieldValueChanged(app, event)
            value = app.SelectedelectrodesizeEditField.Value;
            
            app.userdata.cur_elec_markersize = value;
            UpdateYoke( app);
        end

        % Value changed function: ProjectedelectrodesizeEditField
        function ProjectedelectrodesizeEditFieldValueChanged(app, event)
            value = app.ProjectedelectrodesizeEditField.Value;
            
            app.userdata.proj_elec_markersize = value;
            UpdateYoke( app);
        end

        % Value changed function: T1IntensityLo
        function T1IntensityLoValueChanged(app, event)
            app.userdata.srcdata.t1_clim(1) = app.T1IntensityLo.Value;
            UpdateT1Clim( app);
        end

        % Value changed function: T1IntensityHi
        function T1IntensityHiValueChanged(app, event)
            app.userdata.srcdata.t1_clim(2) = app.T1IntensityHi.Value;
            UpdateT1Clim( app);
        end

        % Value changed function: MNIintensityLo
        function MNIintensityLoValueChanged(app, event)
            app.userdata.srcdata.std_clim(1) = app.MNIintensityLo.Value;
            UpdateStdClim( app);
        end

        % Value changed function: MNIIntensityHi
        function MNIIntensityHiValueChanged(app, event)
            app.userdata.srcdata.std_clim(2) = app.MNIIntensityHi.Value;
            UpdateStdClim( app);
        end

        % Button pushed function: DefaultT1Clim
        function DefaultT1ClimButtonPushed(app, event)
            if ~isempty( app.userdata.default_t1_clim)
                app.T1IntensityLo.Value = app.userdata.default_t1_clim(1);
                app.T1IntensityHi.Value = app.userdata.default_t1_clim(2);
                app.userdata.srcdata.t1_clim = app.userdata.default_t1_clim;
                UpdateT1Clim( app);
            end
        end

        % Button pushed function: DefaultStdClim
        function DefaultStdClimButtonPushed(app, event)
            if ~isempty( app.userdata.default_std_clim)
                app.MNIintensityLo.Value = app.userdata.default_std_clim(1);
                app.MNIIntensityHi.Value = app.userdata.default_std_clim(2);
                app.userdata.srcdata.std_clim = app.userdata.default_std_clim;
                UpdateStdClim( app);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            InPlot( app);
        end

        % Value changed function: FSParcellationListBox
        function FSParcellationListBoxValueChanged(app, event)
            FSsurfOpacitySliderValueChanged( app, []);
        end

        % Value changed function: FSsurfLhCheckBox
        function FSsurfLhCheckBoxValueChanged(app, event)
            FSsurfOpacitySliderValueChanged( app, []);            
        end

        % Value changed function: FSsurfRhCheckBox
        function FSsurfRhCheckBoxValueChanged(app, event)
            FSsurfOpacitySliderValueChanged( app, []);              
        end

        % Button pushed function: FigurebBkColorButton
        function FigurebBkColorButtonPushed(app, event)
            c = uisetcolor( app.userdata.bkcolor);
            if length( c) == 1
                % do nothing
            else
                app.userdata.bkcolor = c;
                UpdateYoke( app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EVIEWERUIFigure = uifigure('Visible', 'off');
            app.EVIEWERUIFigure.Name = 'EVIEWER';
            app.EVIEWERUIFigure.CloseRequestFcn = createCallbackFcn(app, @EVIEWERUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.EVIEWERUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadMenu.Tooltip = {'Load eplace data structure.'};
            app.LoadMenu.Accelerator = 'o';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Tooltip = {'Close current dataset.'};
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Tooltip = {'Quit this program.'};
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Accelerator = 'q';
            app.QuitMenu.Text = 'Quit';
            app.AboutMenu = uimenu(app.EVIEWERUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.CurrentdatasetMenu = uimenu(app.AboutMenu);
            app.CurrentdatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentdatasetMenuSelected, true);
            app.CurrentdatasetMenu.Tooltip = {'About the current dataset.'};
            app.CurrentdatasetMenu.Text = 'Current dataset';
            app.enormMenu = uimenu(app.AboutMenu);
            app.enormMenu.MenuSelectedFcn = createCallbackFcn(app, @enormMenuSelected, true);
            app.enormMenu.Tooltip = {'About eviewer.'};
            app.enormMenu.Text = 'enorm';
            app.HelpMenu = uimenu(app.EVIEWERUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Help.'};
            app.HelpMenu.Text = 'Help';
            app.StatusTextArea = uilabel(app.EVIEWERUIFigure);
            app.StatusTextArea.Text = 'Status';
            app.TabGroup = uitabgroup(app.EVIEWERUIFigure);
            app.ProjectionTab = uitab(app.TabGroup);
            app.ProjectionTab.Title = 'Projection';
            app.SurfaceOptionsPanel = uipanel(app.ProjectionTab);
            app.SurfaceOptionsPanel.Title = 'Surface Options';
            app.FreesurferParcellationListBoxLabel = uilabel(app.SurfaceOptionsPanel);
            app.FreesurferParcellationListBoxLabel.Text = 'Freesurfer Parcellation';
            app.FSParcellationListBox = uilistbox(app.SurfaceOptionsPanel);
            app.FSParcellationListBox.Items = {};
            app.FSParcellationListBox.Multiselect = 'on';
            app.FSParcellationListBox.ValueChangedFcn = createCallbackFcn(app, @FSParcellationListBoxValueChanged, true);
            app.FSParcellationListBox.Value = {};
            app.FSsurfOpacitySlider = uislider(app.SurfaceOptionsPanel);
            app.FSsurfOpacitySlider.MajorTicks = [];
            app.FSsurfOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @FSsurfOpacitySliderValueChanged, true);
            app.FSsurfOpacitySlider.MinorTicks = [];
            app.FSsurfLhCheckBox = uicheckbox(app.SurfaceOptionsPanel);
            app.FSsurfLhCheckBox.ValueChangedFcn = createCallbackFcn(app, @FSsurfLhCheckBoxValueChanged, true);
            app.FSsurfLhCheckBox.Text = 'lh';
            app.FSsurfLhCheckBox.Value = true;
            app.FSsurfRhCheckBox = uicheckbox(app.SurfaceOptionsPanel);
            app.FSsurfRhCheckBox.ValueChangedFcn = createCallbackFcn(app, @FSsurfRhCheckBoxValueChanged, true);
            app.FSsurfRhCheckBox.Text = 'rh';
            app.FSsurfRhCheckBox.Value = true;
            app.OpacityLabel = uilabel(app.SurfaceOptionsPanel);
            app.OpacityLabel.Text = 'Opacity';
            app.OpacityEdit = uieditfield(app.ProjectionTab, 'numeric');
            app.OpacityEdit.ValueChangedFcn = createCallbackFcn(app, @OpacityEditValueChanged, true);
            app.OpacityEdit.Value = 1;
            app.SurfaceOpacitySliderLabel = uilabel(app.ProjectionTab);
            app.SurfaceOpacitySliderLabel.Text = 'Surface Opacity';
            app.SurfaceOpacitySlider = uislider(app.ProjectionTab);
            app.SurfaceOpacitySlider.MajorTicks = [];
            app.SurfaceOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @SurfaceOpacitySliderValueChanged, true);
            app.SurfaceOpacitySlider.MinorTicks = [];
            app.SurfaceOpacitySlider.Value = 100;
            app.AtlasOptionsPanel = uipanel(app.ProjectionTab);
            app.AtlasOptionsPanel.Title = 'Atlas Options';
            app.AtlasGroupListbox = uilistbox(app.AtlasOptionsPanel);
            app.AtlasGroupListbox.Items = {'Item 1'};
            app.AtlasGroupListbox.ValueChangedFcn = createCallbackFcn(app, @AtlasGroupListboxValueChanged, true);
            app.QueryButton = uibutton(app.AtlasOptionsPanel, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Tooltip = {'Atlas query using Freesurfer''s segmentation and FSL''s Harvard-Oxford atlases.'};
            app.QueryButton.Text = 'Query';
            app.ShowAtlasButton = uibutton(app.AtlasOptionsPanel, 'push');
            app.ShowAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAtlasButtonPushed, true);
            app.ShowAtlasButton.Text = 'Seletec Atlas';
            app.ElecLabelListbox = uilistbox(app.ProjectionTab);
            app.ElecLabelListbox.Items = {};
            app.ElecLabelListbox.Multiselect = 'on';
            app.ElecLabelListbox.ValueChangedFcn = createCallbackFcn(app, @ElecLabelListboxValueChanged, true);
            app.ElecLabelListbox.Value = {};
            app.ChannelsLabel = uilabel(app.ProjectionTab);
            app.ChannelsLabel.Text = 'Channels';
            app.ElecLabelGroupInfo = uilabel(app.ProjectionTab);
            app.ElecLabelGroupInfo.Text = {'Label'; ''};
            app.ChanelgroupLabel = uilabel(app.ProjectionTab);
            app.ChanelgroupLabel.Text = 'Chanel group';
            app.ElecLabelGroupListbox = uilistbox(app.ProjectionTab);
            app.ElecLabelGroupListbox.Items = {};
            app.ElecLabelGroupListbox.Multiselect = 'on';
            app.ElecLabelGroupListbox.ValueChangedFcn = createCallbackFcn(app, @ElecLabelGroupListboxValueChanged, true);
            app.ElecLabelGroupListbox.Value = {};
            app.SelectedchannelsLabel = uilabel(app.ProjectionTab);
            app.SelectedchannelsLabel.Text = 'Selected channels';
            app.SelectedLabelListbox = uilistbox(app.ProjectionTab);
            app.SelectedLabelListbox.Items = {};
            app.SelectedLabelListbox.Value = {};
            app.AtlasofselectedchannelPanel = uipanel(app.ProjectionTab);
            app.AtlasofselectedchannelPanel.Title = 'Atlas of selected channel';
            app.AtlasListbox = uilistbox(app.AtlasofselectedchannelPanel);
            app.AtlasListbox.Items = {'Item 1'};
            app.AtlasListbox.Multiselect = 'on';
            app.AtlasListbox.Value = {'Item 1'};
            app.PlotButton = uibutton(app.ProjectionTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Show plots';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DisplayoptionsPanel = uipanel(app.SettingsTab);
            app.DisplayoptionsPanel.Title = 'Display options';
            app.ElectrodestoshowButtonGroup = uibuttongroup(app.DisplayoptionsPanel);
            app.ElectrodestoshowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ElectrodestoshowButtonGroupSelectionChanged, true);
            app.ElectrodestoshowButtonGroup.Title = 'Electrodes to show';
            app.ShowallelectrodesButton = uiradiobutton(app.ElectrodestoshowButtonGroup);
            app.ShowallelectrodesButton.Text = 'Show all electrodes';
            app.ShowallelectrodesButton.Value = true;
            app.ShowselectedelectrodesonlyButton = uiradiobutton(app.ElectrodestoshowButtonGroup);
            app.ShowselectedelectrodesonlyButton.Text = 'Show selected electrodes only';
            app.ShowallwithouthighlightingselectedelectrodeButton = uiradiobutton(app.ElectrodestoshowButtonGroup);
            app.ShowallwithouthighlightingselectedelectrodeButton.Text = 'Show all without highlighting selected electrode';
            app.ShowprojectedelectrodesCheckBox = uicheckbox(app.DisplayoptionsPanel);
            app.ShowprojectedelectrodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowprojectedelectrodesCheckBoxValueChanged, true);
            app.ShowprojectedelectrodesCheckBox.Text = 'Show projected electrodes';
            app.ChangeelectrodescolorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.ChangeelectrodescolorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeelectrodescolorButtonPushed, true);
            app.ChangeelectrodescolorButton.Text = 'Change electrodes color';
            app.ChangeselectedelectrodescolorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.ChangeselectedelectrodescolorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeselectedelectrodescolorButtonPushed, true);
            app.ChangeselectedelectrodescolorButton.Text = 'Change selected electrodes color';
            app.ChangeprojectedelectrodescolorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.ChangeprojectedelectrodescolorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeprojectedelectrodescolorButtonPushed, true);
            app.ChangeprojectedelectrodescolorButton.Text = 'Change projected electrodes color';
            app.ElectrodessizeEditFieldLabel = uilabel(app.DisplayoptionsPanel);
            app.ElectrodessizeEditFieldLabel.Text = 'Electrodes size';
            app.ElectrodessizeEditField = uieditfield(app.DisplayoptionsPanel, 'numeric');
            app.ElectrodessizeEditField.Limits = [1 Inf];
            app.ElectrodessizeEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodessizeEditFieldValueChanged, true);
            app.ElectrodessizeEditField.Value = 1;
            app.SelectedelectrodesizeEditFieldLabel = uilabel(app.DisplayoptionsPanel);
            app.SelectedelectrodesizeEditFieldLabel.Text = 'Selected electrode size';
            app.SelectedelectrodesizeEditField = uieditfield(app.DisplayoptionsPanel, 'numeric');
            app.SelectedelectrodesizeEditField.Limits = [1 Inf];
            app.SelectedelectrodesizeEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedelectrodesizeEditFieldValueChanged, true);
            app.SelectedelectrodesizeEditField.Value = 1;
            app.ProjectedelectrodesizeEditFieldLabel = uilabel(app.DisplayoptionsPanel);
            app.ProjectedelectrodesizeEditFieldLabel.Text = 'Projected electrode size';
            app.ProjectedelectrodesizeEditField = uieditfield(app.DisplayoptionsPanel, 'numeric');
            app.ProjectedelectrodesizeEditField.Limits = [1 Inf];
            app.ProjectedelectrodesizeEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectedelectrodesizeEditFieldValueChanged, true);
            app.ProjectedelectrodesizeEditField.Value = 1;
            app.T1brainintensityPanel = uipanel(app.DisplayoptionsPanel);
            app.T1brainintensityPanel.Title = 'T1 brain intensity';
            app.LowLabel = uilabel(app.T1brainintensityPanel);
            app.LowLabel.Text = 'Low';
            app.T1IntensityLo = uieditfield(app.T1brainintensityPanel, 'numeric');
            app.T1IntensityLo.ValueChangedFcn = createCallbackFcn(app, @T1IntensityLoValueChanged, true);
            app.T1IntensityHi = uieditfield(app.T1brainintensityPanel, 'numeric');
            app.T1IntensityHi.ValueChangedFcn = createCallbackFcn(app, @T1IntensityHiValueChanged, true);
            app.DefaultT1Clim = uibutton(app.T1brainintensityPanel, 'push');
            app.DefaultT1Clim.ButtonPushedFcn = createCallbackFcn(app, @DefaultT1ClimButtonPushed, true);
            app.DefaultT1Clim.Text = 'Reset';
            app.HighLabel = uilabel(app.T1brainintensityPanel);
            app.HighLabel.Text = 'High';
            app.MNIbrainintensityPanel = uipanel(app.DisplayoptionsPanel);
            app.MNIbrainintensityPanel.Title = 'MNI brain intensity';
            app.LowLabel_2 = uilabel(app.MNIbrainintensityPanel);
            app.LowLabel_2.Text = 'Low';
            app.MNIintensityLo = uieditfield(app.MNIbrainintensityPanel, 'numeric');
            app.MNIintensityLo.ValueChangedFcn = createCallbackFcn(app, @MNIintensityLoValueChanged, true);
            app.MNIIntensityHi = uieditfield(app.MNIbrainintensityPanel, 'numeric');
            app.MNIIntensityHi.ValueChangedFcn = createCallbackFcn(app, @MNIIntensityHiValueChanged, true);
            app.HighLabel_2 = uilabel(app.MNIbrainintensityPanel);
            app.HighLabel_2.Text = 'High';
            app.DefaultStdClim = uibutton(app.MNIbrainintensityPanel, 'push');
            app.DefaultStdClim.ButtonPushedFcn = createCallbackFcn(app, @DefaultStdClimButtonPushed, true);
            app.DefaultStdClim.Text = 'Reset';
            app.FigurebBkColorButton = uibutton(app.DisplayoptionsPanel, 'push');
            app.FigurebBkColorButton.ButtonPushedFcn = createCallbackFcn(app, @FigurebBkColorButtonPushed, true);
            app.FigurebBkColorButton.Tooltip = {'Replot required.'};
            app.FigurebBkColorButton.Text = 'Figure background color';
            % Show the figure after all components are created
            app.EVIEWERUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eviewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EVIEWERUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EVIEWERUIFigure)
        end
    end
end